classdef App1_v0 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        EEGSProc                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        Tab                             matlab.ui.container.Tab
        Panel                           matlab.ui.container.Panel
        Button                          matlab.ui.control.Button
        Label3                          matlab.ui.control.Label
        Button2                         matlab.ui.control.Button
        Label4                          matlab.ui.control.Label
        Button3                         matlab.ui.control.Button
        Label5                          matlab.ui.control.Label
        Button4                         matlab.ui.control.Button
        Label6                          matlab.ui.control.Label
        Button5                         matlab.ui.control.Button
        Label7                          matlab.ui.control.Label
        Label8                          matlab.ui.control.Label
        Button6                         matlab.ui.control.Button
        Button7                         matlab.ui.control.Button
        Label12                         matlab.ui.control.Label
        Panel2                          matlab.ui.container.Panel
        LabelDropDown2                  matlab.ui.control.Label
        DropDown2                       matlab.ui.control.DropDown
        LabelDropDown3                  matlab.ui.control.Label
        DropDown3                       matlab.ui.control.DropDown
        LabelDropDown4                  matlab.ui.control.Label
        DropDown4                       matlab.ui.control.DropDown
        Label9                          matlab.ui.control.Label
        Label2                          matlab.ui.control.Label
        NumericEditField3               matlab.ui.control.NumericEditField
        Label10                         matlab.ui.control.Label
        LabelNumericEditField           matlab.ui.control.Label
        NumericEditField                matlab.ui.control.NumericEditField
        Label11                         matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        NumericEditField2               matlab.ui.control.NumericEditField
        UIAxes                          matlab.ui.control.UIAxes
        Panel3                          matlab.ui.container.Panel
        TextArea                        matlab.ui.control.TextArea
        Tab2                            matlab.ui.container.Tab
        Panel4                          matlab.ui.container.Panel
        Button11                        matlab.ui.control.Button
        Label16                         matlab.ui.control.Label
        Button12                        matlab.ui.control.Button
        Label17                         matlab.ui.control.Label
        Label18                         matlab.ui.control.Label
        Button13                        matlab.ui.control.Button
        ExportLabel                     matlab.ui.control.Label
        Button13_2                      matlab.ui.control.Button
        TabGroup2                       matlab.ui.container.TabGroup
        Tab5                            matlab.ui.container.Tab
        Label20                         matlab.ui.control.Label
        DropDown5                       matlab.ui.control.DropDown
        Label21                         matlab.ui.control.Label
        DropDown6                       matlab.ui.control.DropDown
        Label22                         matlab.ui.control.Label
        DropDown7                       matlab.ui.control.DropDown
        Tab6                            matlab.ui.container.Tab
        Panel8                          matlab.ui.container.Panel
        Label31                         matlab.ui.control.Label
        Label32                         matlab.ui.control.Label
        Label33                         matlab.ui.control.Label
        NumericEditField10              matlab.ui.control.NumericEditField
        Label34                         matlab.ui.control.Label
        Label35                         matlab.ui.control.Label
        NumericEditField11              matlab.ui.control.NumericEditField
        Panel7                          matlab.ui.container.Panel
        Label27                         matlab.ui.control.Label
        Label28                         matlab.ui.control.Label
        LabelNumericEditField7          matlab.ui.control.Label
        NumericEditField7               matlab.ui.control.NumericEditField
        Label29                         matlab.ui.control.Label
        Label26                         matlab.ui.control.Label
        NumericEditField8               matlab.ui.control.NumericEditField
        TabGroup3                       matlab.ui.container.TabGroup
        Tab7                            matlab.ui.container.Tab
        Panel_7                         matlab.ui.container.Panel
        Label23                         matlab.ui.control.Label
        LabelNumericEditField4          matlab.ui.control.Label
        NumericEditField4               matlab.ui.control.NumericEditField
        Label24                         matlab.ui.control.Label
        LabelNumericEditField5          matlab.ui.control.Label
        NumericEditField5               matlab.ui.control.NumericEditField
        Label25                         matlab.ui.control.Label
        NumericEditField6               matlab.ui.control.NumericEditField
        Panel_8                         matlab.ui.container.Panel
        CheckBox2                       matlab.ui.control.CheckBox
        CheckBox3                       matlab.ui.control.CheckBox
        CheckBox4                       matlab.ui.control.CheckBox
        Panel_9                         matlab.ui.container.Panel
        CheckBox                        matlab.ui.control.CheckBox
        Tab8                            matlab.ui.container.Tab
        Panel_10                        matlab.ui.container.Panel
        LabelNumericEditField12         matlab.ui.control.Label
        NumericEditField12              matlab.ui.control.NumericEditField
        LabelEditField                  matlab.ui.control.Label
        EditField                       matlab.ui.control.EditField
        LabelDropDown8                  matlab.ui.control.Label
        DropDown8                       matlab.ui.control.DropDown
        Panel_11                        matlab.ui.container.Panel
        CheckBox7                       matlab.ui.control.CheckBox
        CheckBox8                       matlab.ui.control.CheckBox
        CheckBox6                       matlab.ui.control.CheckBox
        Panel_12                        matlab.ui.container.Panel
        LabelListBox                    matlab.ui.control.Label
        ListBox                         matlab.ui.control.ListBox
        Panel5                          matlab.ui.container.Panel
        UIAxes2                         matlab.ui.control.UIAxes
        Panel6                          matlab.ui.container.Panel
        UIAxes3                         matlab.ui.control.UIAxes
        UIAxes4                         matlab.ui.control.UIAxes
        Tab3                            matlab.ui.container.Tab
        Panel9                          matlab.ui.container.Panel
        Button14                        matlab.ui.control.Button
        ExportLabel_2                   matlab.ui.control.Label
        Button15                        matlab.ui.control.Button
        Label37                         matlab.ui.control.Label
        Label38                         matlab.ui.control.Label
        Button16                        matlab.ui.control.Button
        TabGroup4                       matlab.ui.container.TabGroup
        Tab9                            matlab.ui.container.Tab
        Label39                         matlab.ui.control.Label
        DropDown9                       matlab.ui.control.DropDown
        Label40                         matlab.ui.control.Label
        DropDown10                      matlab.ui.control.DropDown
        TabGroup5                       matlab.ui.container.TabGroup
        Tab11                           matlab.ui.container.Tab
        LabelDropDown11                 matlab.ui.control.Label
        DropDown11                      matlab.ui.control.DropDown
        Panel_3                         matlab.ui.container.Panel
        Label27_2                       matlab.ui.control.Label
        Label28_2                       matlab.ui.control.Label
        NumericEditField7_2Label        matlab.ui.control.Label
        NumericEditField7_2             matlab.ui.control.NumericEditField
        Label29_2                       matlab.ui.control.Label
        NumericEditField8_2Label        matlab.ui.control.Label
        NumericEditField8_2             matlab.ui.control.NumericEditField
        Panel_2                         matlab.ui.container.Panel
        LabelListBox3                   matlab.ui.control.Label
        ListBox3                        matlab.ui.control.ListBox
        CheckBox_3                      matlab.ui.control.CheckBox
        ListBox2                        matlab.ui.control.ListBox
        LabelListBox2                   matlab.ui.control.Label
        CheckBox_2                      matlab.ui.control.CheckBox
        Panel12                         matlab.ui.container.Panel
        UIAxes5                         matlab.ui.control.UIAxes
        Tab4                            matlab.ui.container.Tab
        Panel9_2                        matlab.ui.container.Panel
        Button14_2                      matlab.ui.control.Button
        AnalyzeLabel                    matlab.ui.control.Label
        Button15_2                      matlab.ui.control.Button
        ExploreLabel                    matlab.ui.control.Label
        Button16_2Label                 matlab.ui.control.Label
        Button16_2                      matlab.ui.control.Button
        Button7_2                       matlab.ui.control.Button
        Button7_2Label                  matlab.ui.control.Label
        Button7_3                       matlab.ui.control.Button
        TransformLabel_2                matlab.ui.control.Label
        TabGroup4_2                     matlab.ui.container.TabGroup
        Tab9_2                          matlab.ui.container.Tab
        ApproachLabel                   matlab.ui.control.Label
        DropDown9_2                     matlab.ui.control.DropDown
        ResutlsLabel                    matlab.ui.control.Label
        DropDown10_2                    matlab.ui.control.DropDown
        TabGroup6                       matlab.ui.container.TabGroup
        VariablesTab                    matlab.ui.container.Tab
        UITable                         matlab.ui.control.Table
        TestofNormalityTab              matlab.ui.container.Tab
        LillieforsTestPanel             matlab.ui.container.Panel
        UITable2                        matlab.ui.control.Table
        ShapiroWilkShapiroFranciaPanel  matlab.ui.container.Panel
        UITable2_2                      matlab.ui.control.Table
        DescriptivesPanel               matlab.ui.container.Panel
        UITable3                        matlab.ui.control.Table
        TestofHomogeneityofVariancesTab  matlab.ui.container.Tab
        LeveneTestPanel                 matlab.ui.container.Panel
        UITable4                        matlab.ui.control.Table
        ParametricTestResultsTab        matlab.ui.container.Tab
        ANOVAPanel                      matlab.ui.container.Panel
        UITable5                        matlab.ui.control.Table
        MultipleComparasionsGamesHowellPanel  matlab.ui.container.Panel
        UITable5_2                      matlab.ui.control.Table
        MultipleComparasionsTukeyPanel  matlab.ui.container.Panel
        UITable5_3                      matlab.ui.control.Table
        NonparametricTestResultsTab     matlab.ui.container.Tab
        KruskalWallisPanel              matlab.ui.container.Panel
        UITable5_4                      matlab.ui.control.Table
        MultipleComparasionsDunnBonferroniApproachPanel  matlab.ui.container.Panel
        UITable5_5                      matlab.ui.control.Table
        WilcoxonRankSumPanel            matlab.ui.container.Panel
        UITable6                        matlab.ui.control.Table
        TabGroup7                       matlab.ui.container.TabGroup
        DescriptiveStatisticsTab        matlab.ui.container.Tab
        Panel_4                         matlab.ui.container.Panel
        log10xCheckBox                  matlab.ui.control.CheckBox
        sqrtxCheckBox                   matlab.ui.control.CheckBox
        powx2CheckBox                   matlab.ui.control.CheckBox
        xCheckBox                       matlab.ui.control.CheckBox
        TransformLabel_3                matlab.ui.control.Label
        Panel_15                        matlab.ui.container.Panel
        SignificanceLevelLabel          matlab.ui.control.Label
        SignificanceLevelEditField      matlab.ui.control.NumericEditField
        ParametricTestsTab              matlab.ui.container.Tab
        Panel_5                         matlab.ui.container.Panel
        tTestCheckBox                   matlab.ui.control.CheckBox
        ANOVAOneWayCheckBox             matlab.ui.control.CheckBox
        RegressionAnalysisCheckBox      matlab.ui.control.CheckBox
        Panel_13                        matlab.ui.container.Panel
        TukeyCheckBox                   matlab.ui.control.CheckBox
        GamesHowellCheckBox             matlab.ui.control.CheckBox
        PostHocLabel                    matlab.ui.control.Label
        Button_2                        matlab.ui.control.Button
        Button_3                        matlab.ui.control.Button
        Button_4                        matlab.ui.control.Button
        Button_5                        matlab.ui.control.Button
        Panel_16                        matlab.ui.container.Panel
        SignificanceLevelEditField_2Label  matlab.ui.control.Label
        SignificanceLevelEditField_2    matlab.ui.control.NumericEditField
        NonparametricTestsTab           matlab.ui.container.Tab
        Panel_6                         matlab.ui.container.Panel
        FriedmanCheckBox                matlab.ui.control.CheckBox
        KruskalWallisCheckBox           matlab.ui.control.CheckBox
        WilcoxonRankSumCheckBox         matlab.ui.control.CheckBox
        Panel_17                        matlab.ui.container.Panel
        SignificanceLevelEditField_3Label  matlab.ui.control.Label
        SignificanceLevelEditField_3    matlab.ui.control.NumericEditField
        Button_6                        matlab.ui.control.Button
        Button_7                        matlab.ui.control.Button
    end

    
    properties (Access = public)
        
        dropdown2;
        dropdown3;
        matpath;
        mats_p;
        mats_f;
        
        errorflag;
        graphflag;
        prcs;
        in_prcs;
        run2_performed = 0;
        f % wait bar - in process
        grpvalue
        sbjvalue
        
        grp_file_name;
        sbj_file_name;
        btn_state =  0;
        
        max_recc;
        min_recc;
        cn_num;
        group_num;
        sub_num;
        samp_rate;
        
        Fs;
        swt_length;
        rd;
        thr_met;
        declevchg;
        
        butterfilt;
        
        bpf_signal;
        decom_lev;
        mot_wave;
        denoised_sig;
        
        reg_idx;
        ch_id;
        
        hilbert_sig;
        hilbert_regs;
        arg_r;
        
        y_hII;
        y_hII_mean;
        y_hI;
        
        xunit3_5;
        yunit3_5;
        
        xunit4_5;
        yunit4_5;
        
        GFS_m;
        GF;
        %         grp_idx;
        %         subj_idx
        %         ch_idx
        trns = 0;
        trns_btn = 0;
        
    end
    
    methods (Access = public)
        
        function process(app)
            app.in_prcs = 1;
            switch app.btn_state
                case 1
                    msg = 'Loading your data...';
                case 2
                    msg = 'Editing epochs...';
                case 3
                    msg = 'Reverting to orginal records...';
                case 4
                    msg = 'Reverting back the changes...';
                case 5
                    msg = 'Calculating Fourier Transform...';
                case 6
                    msg = 'Denoising data set...';
                case 6.1
                    msg = 'Exporting data set...';
                case 6.2
                    msg = 'Exporting analysis results...';
                case 7
                    msg = 'Calculating Hilbert Transform...';
                case 8
                    msg = 'Evaluating: Local Analysis I...';
                case 9
                    msg = 'Evaluating: Local Analysis II...';
                case 10
                    msg = 'Evaluating: Global Analysis...';
            end
            
            if app.prcs == 0
                app.f = waitbar(app.prcs,msg,'Name','Please wait...');
            else
                waitbar(app.prcs,app.f,msg);
            end
            
            if app.prcs == 1
                close(app.f)
                app.errorflag = 0;
                app.in_prcs = 0;
            end
        end
        
        function startupFcn2(app)
            clear app.matpath app.mats app.UIAxes app.DropDown2 ...
                app.DropDown3 app.dropdown2 app.dropdown3;
            
            app.UIAxes.cla;
            xlabel(app.UIAxes, 'X');
            ylabel(app.UIAxes, 'Y');
            title(app.UIAxes, 'Title');
            
            app.NumericEditField.Value = 0;
            app.NumericEditField2.Value = 180;
            app.NumericEditField3.Value = 0;
            
            app.DropDown2.Items = {};
            app.DropDown3.Items = {};
            app.DropDown4.Items = {};
            app.TextArea.Value = {' '};
            
            set(app.DropDown2,'Enable','off');
            set(app.DropDown3,'Enable','off');
            set(app.DropDown4,'Enable','off');
            
            set(app.Button2,'Enable','off');
            set(app.Button3,'Enable','off');
            set(app.Button4,'Enable','off');
            set(app.Button5,'Enable','off');
            set(app.Button6,'Enable','off');
            set(app.Button7,'Enable','off');
            
            set(app.NumericEditField2,'Enable','off');
            set(app.NumericEditField3,'Enable','off');
            set(app.NumericEditField,'Enable','off');
            set(app.TextArea,'Enable','off');
            
            set(app.Button11,'Enable','off');
            set(app.Button12,'Enable','off');
            set(app.DropDown5,'Enable','off');
            set(app.DropDown6,'Enable','off');
            set(app.DropDown7,'Enable','off');
            set(app.DropDown8,'Enable','off');
            set(app.NumericEditField4,'Enable','off');
            set(app.NumericEditField5,'Enable','off');
            set(app.NumericEditField6,'Enable','off');
            set(app.NumericEditField7,'Enable','off');
            set(app.NumericEditField8,'Enable','off');
            set(app.EditField,'Enable','off');
            set(app.NumericEditField10,'Enable','off');
            set(app.NumericEditField11,'Enable','off');
            set(app.NumericEditField12,'Enable','off');
            set(app.ListBox,'Enable','off');
            set(app.CheckBox2,'Enable','off');
            set(app.CheckBox3,'Enable','off');
            set(app.CheckBox4,'Enable','off');
            set(app.CheckBox7,'Enable','off');
            set(app.CheckBox8,'Enable','off');
            
            app.errorflag = 0;
            app.graphflag = 0;
        end
        
        function tab2enable(app)
            set(app.Button11,'Enable','on');
            set(app.DropDown5,'Enable','on');
            set(app.DropDown6,'Enable','on');
            set(app.DropDown7,'Enable','on');
            set(app.DropDown8,'Enable','on');
            set(app.NumericEditField4,'Enable','on');
            set(app.NumericEditField5,'Enable','on');
            set(app.NumericEditField6,'Enable','on');
            set(app.NumericEditField7,'Enable','on');
            set(app.NumericEditField8,'Enable','on');
            set(app.EditField,'Enable','on');
            set(app.NumericEditField10,'Enable','on');
            set(app.NumericEditField11,'Enable','on');
            set(app.NumericEditField12,'Enable','on');
            set(app.ListBox,'Enable','on');
            set(app.CheckBox2,'Enable','on');
            set(app.CheckBox3,'Enable','on');
            set(app.CheckBox4,'Enable','on');
            set(app.CheckBox7,'Enable','on');
            set(app.CheckBox8,'Enable','on');
        end
        
        function tab3enable(app)
            set(app.Button14,'Enable','on');
            set(app.Button15,'Enable','on');
            set(app.DropDown9,'Enable','on');
            set(app.DropDown10,'Enable','on');
            set(app.DropDown11,'Enable','on');
            set(app.ListBox2,'Enable','on');
        end
        
        function tab4enable(app)
            set(app.Button14_2,'Enable','on');
            set(app.Button15_2,'Enable','on');
            set(app.Button7_2,'Enable','on');
            set(app.Button7_3,'Enable','on');
            set(app.DropDown9_2,'Enable','on');
            set(app.DropDown10_2,'Enable','on');
            set(app.SignificanceLevelEditField,'Enable','on');
            set(app.log10xCheckBox,'Enable','on');
            set(app.sqrtxCheckBox,'Enable','on');
            set(app.powx2CheckBox,'Enable','on');
            set(app.xCheckBox,'Enable','on');
            set(app.SignificanceLevelEditField_2,'Enable','on');
            set(app.ANOVAOneWayCheckBox,'Enable','on');
            set(app.TukeyCheckBox,'Enable','on');
            set(app.GamesHowellCheckBox,'Enable','on');
            set(app.SignificanceLevelEditField_3,'Enable','on');
            set(app.KruskalWallisCheckBox,'Enable','on');
            set(app.WilcoxonRankSumCheckBox,'Enable','on');
        end
        
        function btw_ch_reg(app)
            reg1 = {'ch5','ch9'}; reg2 = {'ch2','ch3'}; reg3 = {' ',' '}; reg4 = {'ch14','ch15'}; reg5 = {'ch8','ch12'};
            regs = [reg1; reg2; reg3; reg4; reg5];
            if strcmp(app.DropDown11.Value,'Local Analysis I') || strcmp(app.DropDown11.Value,'Local Analysis II')
                
                [~,m] = size(app.ListBox2.Items);
                [~,n] = size(app.ListBox2.Value);
                listbox3(:,:).items = {};
                reg_id = 0;
                for i = 1:m
                    for j = 1:n
                        if strcmp(app.ListBox2.Items(i), app.ListBox2.Value(j))
                            if i > 2
                                k = i+1;
                            else
                                k = i;
                            end
                            reg_id = [reg_id k];
                        end
                    end
                end
                reg_id(1) = [];
                app.reg_idx = reg_id;
                ii = nchoosek(n,2);
                i1 = 1;
                i2 = 1;
                i3 = 2;
                if strcmp(app.DropDown11.Value,'Local Analysis II')
                    for i = 1:ii
                        for k = 1:2
                            for l = 1:2
                                %                                 display(reg_id(i2))
                                %                                 display(reg_id(i3))
                                %                                 display(reg_id(i3))
                                listbox3(i1,:).items = [regs{reg_id(i2),k},'-',regs{reg_id(i3),l}];
                                i1 = i1+1;
                            end
                        end
                        if i3 < length(reg_id)
                            i3 = i3+1;
                        else
                            reg_id(1) = [];
                            i2 = 1;
                            i3 = 2;
                        end
                    end
                    app.ListBox3.Items = {listbox3(:,:).items};
                    %                     display({listbox3(:,:).items})
                    
                    app.ch_id = 0;
                    [~,m] = size(app.ListBox3.Items);
                    [~,n] = size(app.ListBox3.Value);
                    for i = 1:m
                        for j = 1:n
                            if strcmp(app.ListBox3.Items(i), app.ListBox3.Value(j))
                                app.ch_id = [app.ch_id i];
                            end
                        end
                    end
                    app.ch_id(1) = [];
                    %                     display(app.ch_id);
                    %                     app.ListBox3.Items = {'Region 1' 'Region 2' 'Region 4' 'Region 5'};
                    %                 else
                end
                %             elseif strcmp(app.DropDown12.Value,'Local Analysis II')
                %                 app.DropDown8.Items = {'Region:1-2' 'Region:1-4' 'Region:1-5' ...
                %                     'Region:2-4' 'Region:2-5' 'Region:4-5' 'All Regions'};
                % %             elseif strcmp(app.DropDown12.Value,'Global Analysis')
            end
            if strcmp(app.DropDown11.Value,'Local Analysis I')
                set(app.CheckBox_2,'Enable','off');
                set(app.CheckBox_3,'Enable','off');
                set(app.ListBox2,'Enable','on');
                set(app.ListBox3,'Enable','off');
                app.Panel_3.Visible = 'off';
                app.Panel_2.Visible = 'on';
                localanalysisI_map(app);
            elseif strcmp(app.DropDown11.Value,'Local Analysis II')
                set(app.CheckBox_2,'Enable','on');
                set(app.CheckBox_3,'Enable','on');
                set(app.ListBox2,'Enable','on');
                set(app.ListBox3,'Enable','on');
                app.Panel_3.Visible = 'off';
                app.Panel_2.Visible = 'on';
                localanalysisII_map(app);
            else
                set(app.CheckBox_2,'Enable','off');
                set(app.CheckBox_3,'Enable','off');
                set(app.ListBox2,'Enable','off');
                set(app.ListBox3,'Enable','off');
                colorbar(app.UIAxes5,'off');
                app.Panel_2.Visible = 'off';
                app.Panel_3.Visible = 'on';
                globalanalysis_map(app);
            end
        end


        function [x,y] = circle(a,b,r)
            d = 0.1;
            th = 0:d:360-d;
            x = (r * cosd(th))+a;
            y = (r * sind(th))+b;
        end
        
        function [x,y] = fillcircle(a,b,r)
            ad = 0.1;
            rdis = 0.01;
            th = 0:ad:360-ad;
            rh = 0:rdis:r;
            x = zeros(length(rh),length(th));
            y = zeros(length(rh),length(th));
            for i = 1:length(rh)
                x(i,:) = (rh(i) * cosd(th))+a;
                y(i,:) = (rh(i) * sind(th))+b;
            end
            
        end
        
        function [R,G,B] = rgb_val(app, syn_idx)
            C = (syn_idx*255)*255;
            
            B = mod(C,255);
            G = ((C-B)/255);
            G = mod(G,255);
            R = ((C-B)/255^2) - G/255;
            
            R = R/255;
            G = G/255;
            B = B/255;
        end
        
        function [Ax1,Ay1,Ax2,Ay2] = regconnectp(x1,y1,x2,y2,r)
            A = x1+x2;
            B = y1+y2;
            
            Ax = find(A == min(A));
            By = find(B == min(B));
            
            ri = sqrt(((x2(:,By)-x1(:,By))^2)+(y2(:,Ax)-y1(:,Ax))^2);
            
            a = (r/ri)*(x2(:,By)-x1(:,By));
            b = (r/ri)*(y2(:,Ax)-y1(:,Ax));
            
            Ax1 = x1(:,By)+a;
            Ay1 = y1(:,Ax)+b;
            Ax2 = x2(:,By)-a;
            Ay2 = y2(:,Ax)-b;
        end
        
        function brain_map(app)
            r =1;
            [xunit,yunit] = circle(0,0,r);
            plot(app.UIAxes5,xunit, yunit,'k');
            colormap(app.UIAxes5,winter);
            c = colorbar(app.UIAxes5);
            c.Label.String = 'Syncronization Index';
            app.UIAxes5.FontSize = 10;
            %             colorbar(app.Panel13);
            xlim(app.UIAxes5,[-r-r*0.2 r+r*0.2]);
            ylim(app.UIAxes5,[-r-r*0.2 r+r*0.2]);
            text(app.UIAxes5,0,r+r*0.1,'Nasion','Color','black','HorizontalAlignment' , 'center');
            text(app.UIAxes5,0,-r-r*0.1,'Inion','Color','black','HorizontalAlignment' , 'center');
            text(app.UIAxes5,-r-r*0.1,0,'Left','Color','black','HorizontalAlignment' , 'center','Rotation',90);
            text(app.UIAxes5,r+r*0.1,0,'Right','Color','black','HorizontalAlignment' , 'center','Rotation',90);
            
            ths = [135,45];
            b = [-r/2,r/2];
            xs = -r/2:0.01:r/2;
            k = 1;
            d = 0.1;
            th = 0:d:360-d;
            xu = linspace(-1,1,length(th));
            xp = xu;
            yu = -xu;
            yp = xu;
            for i = 1:2
                for j = 1:2
                    ys(k,:) = tand(ths(j)).*xs + b(i);
                    ys(k,(ys(k,:) < -r/2)) = NaN;
                    ys(k,(ys(k,:) > r/2)) = NaN;
                    hold(app.UIAxes5,'on');
                    plot(app.UIAxes5, xs, ys(k,:),'k');
                    k = k+1;
                end
            end
            
            %             xu = (r-(r/2-(r/2*tand(th)))) .* cosd(th);
            %             yu = -(r-(r/2-(r/2*cotd(th)))) .* sind(th);
            %             xp = (r-(r/2-(r/2*tand(th)))) .* cosd(th);
            %             yp = (r-(r/2-(r/2*cotd(th)))) .* sind(th);
            
            for i = 1:length(yu)
                if (yu(1,i) < 0.25) && (yu(1,i) > -0.25)
                    yu(1,i) = NaN;
                    yp(1,i) = NaN;
                end
                if (yu(1,i) > 0.7071) || (yu(1,i) < -0.7071)
                    yu(1,i) = NaN;
                    yp(1,i) = NaN;
                end
            end
            plot(app.UIAxes5,xu, yu,'k');
            hold(app.UIAxes5,'on');
            plot(app.UIAxes5,xp, yp,'k');
            
            r =0.8;
            [xunit1,yunit1] = circle(0,0,r);
            hold(app.UIAxes5,'on');
            plot(app.UIAxes5,xunit1, yunit1,'--k');
            
            r =0.4;
            [xunit2,yunit2] = circle(0,0,r);
            hold(app.UIAxes5,'on');
            plot(app.UIAxes5,xunit2, yunit2,'--k');
            
            %%% for regions %%%
            r =0.6;
            [xunit1_5,yunit1_5] = circle(0,0,r);
            %%%
            
            %%% region & chanell labels %%%
            elc_lab_f10p = {'Fp1' 'F7' 'T3' 'T5' 'O1' 'O2' 'T6' 'T4' 'F8' 'Fp2'};
            elc_lab_f20p = {'C4' 'C3' 'F4' 'P3' 'F3' 'P4'};
            %             elc_lab_zero = {'Fpz' 'Fz' 'Cz' 'Pz' 'Oz'};
            regions = {'R1' 'R2' 'R3' 'R4' 'R5'};
            channels = {'c1' 'c2' 'c3' 'c4' 'ch5' 'ch6' 'ch7' 'ch8' 'ch9' ...
                'c10' 'c11' 'c12' 'c13' 'c14' 'ch15' 'ch16'};
            %%%
            
            s10p = 108;
            s20p = [1 54 126];
            k = 1;
            rgc = 90;
            sc = 126;
            mc = sc;
            %place regions
            for i = 1:5
                if i < 5
                    if i == 3
                        k = 5;
                    elseif i == 4
                        k = 4;
                    end
                    [app.xunit3_5(k,:),app.yunit3_5(k,:)] = circle(xunit1_5(rgc*3600/360),yunit1_5(rgc*3600/360),0.07);
                    hold(app.UIAxes5,'on');
                    plot(app.UIAxes5,app.xunit3_5(k,:),app.yunit3_5(k,:),'m')
                    text(app.UIAxes5,xunit1_5(rgc*3600/360),yunit1_5(rgc*3600/360),regions(k),'Color','magenta','HorizontalAlignment' , 'center');
                    rgc = rgc + 90;
                    k = k+1;
                else
                    k = 3;
                    [app.xunit3_5(k,:),app.yunit3_5(k,:)] = circle(0,0,0.07);
                    hold(app.UIAxes5,'on');
                    %                             plot(xunit3_5, yunit3_5,'b');
                    plot(app.UIAxes5,app.xunit3_5(k,:),app.yunit3_5(k,:),'m')
                    text(app.UIAxes5,0,0,regions(k),'Color','magenta','HorizontalAlignment' , 'center');
                end
            end
            k = 1;
            %place channels for 10-20% system / 16 ch
            for i = 1:16
                if i <= 10
                    [xunit4,yunit4] = circle(xunit1(s10p*3600/360),yunit1(s10p*3600/360),0.06);
                    hold(app.UIAxes5,'on');
                    plot(app.UIAxes5,xunit4, yunit4,'k');
                    text(app.UIAxes5,xunit1(s10p*3600/360),yunit1(s10p*3600/360),elc_lab_f10p(i),'Color','black','HorizontalAlignment' , 'center');
                    mid10_20p(i,:) = [xunit1(s10p*3600/360),yunit1(s10p*3600/360)];
                    if mod(s10p,360) == 0
                        s10p = 0;
                    end
                    s10p = s10p + 36;
                    
                else
                    [xunit4,yunit4] = circle(xunit2(s20p(k)*3600/360),yunit2(s20p(k)*3600/360),0.06);
                    hold(app.UIAxes5,'on');
                    plot(app.UIAxes5,xunit4, yunit4,'k');
                    text(app.UIAxes5,xunit2(s20p(k)*3600/360),yunit2(s20p(k)*3600/360),elc_lab_f20p(i-10),'Color','black','HorizontalAlignment' , 'center');
                    s20p(k) = s20p(k) + 180;
                    if mod(i,2) == 0
                        k = k+1;
                    end
                    mid10_20p(i,:) = [xunit2(mc*3600/360),yunit2(mc*3600/360)];
                    if mod(mc,360) == 0
                        mc = 0;
                    end
                    if mod(i-10,3) == 0
                        mc = mc + 72;
                    else
                        mc = mc+54;
                    end
                end
                %     if i <= 4
                % %         [xunit4,yunit4] = circle(xunit1(sc*3600/360),yunit1(sc*3600/360),0.06);
                % %         hold on;
                % %         fill(xunit4,yunit4,'k')
                % %         text(xunit1(sc*3600/360),yunit1(sc*3600/360),channels(i),'FontSize',8,'Color','white','HorizontalAlignment' , 'center');
                % %         sc = sc + 36;
                %         [xunit4,yunit4] = circle((mid10_20p(i,1)+mid10_20p(i+1,1))/2,(mid10_20p(i,2)+mid10_20p(i+1,2))/2,0.06);
                %         hold on;
                %         fill(xunit4,yunit4,'k')
                %         text((mid10_20p(i,1)+mid10_20p(i+1,1))/2,(mid10_20p(i,2)+mid10_20p(i+1,2))/2,channels(i),'FontSize',8,'Color','white','HorizontalAlignment' , 'center');
                %
                % %     elseif i <= 8
                %
                %     end
            end
            a = -4;
            b = 6;
            for i = 1:16
                if i <= 4
                    [app.xunit4_5(i,:),app.yunit4_5(i,:)] = circle((mid10_20p(i,1)+mid10_20p(i+1,1))/2,(mid10_20p(i,2)+mid10_20p(i+1,2))/2,0.06);
                    hold(app.UIAxes5,'on');
                    plot(app.UIAxes5,app.xunit4_5(i,:),app.yunit4_5(i,:),'r')
                    text(app.UIAxes5,(mid10_20p(i,1)+mid10_20p(i+1,1))/2,(mid10_20p(i,2)+mid10_20p(i+1,2))/2,channels(i), ...
                        'FontSize',8,'Color','red','HorizontalAlignment','center');
                    
                elseif i <= 8
                    if mod(i,6) == 0
                        a = 5;
                        b = a+1;
                    elseif mod(i,8) == 0
                        b = b-9;
                    end
                    [app.xunit4_5(i,:),app.yunit4_5(i,:)] = circle((mid10_20p(i+a,1)+mid10_20p(i+b,1))/2,(mid10_20p(i+a,2)+mid10_20p(i+b,2))/2,0.06);
                    hold(app.UIAxes5,'on');
                    plot(app.UIAxes5,app.xunit4_5(i,:),app.yunit4_5(i,:),'r')
                    text(app.UIAxes5,(mid10_20p(i+a,1)+mid10_20p(i+b,1))/2,(mid10_20p(i+a,2)+mid10_20p(i+b,2))/2,channels(i), ...
                        'FontSize',8,'Color','red','HorizontalAlignment','center');
                elseif i <= 12
                    if mod(i,9) == 0
                        a = 1;
                        b = 7;
                    elseif mod(i,10) == 0 || mod(i,11) == 0
                        a = b-1;
                        b = b-2;
                    elseif mod(i,12) == 0
                        a = b-1;
                        b = -b-3;
                    end
                    [app.xunit4_5(i,:),app.yunit4_5(i,:)] = circle((mid10_20p(i+a,1)+mid10_20p(i+b,1))/2,(mid10_20p(i+a,2)+mid10_20p(i+b,2))/2,0.06);
                    hold(app.UIAxes5,'on');
                    plot(app.UIAxes5,app.xunit4_5(i,:),app.yunit4_5(i,:),'r')
                    text(app.UIAxes5,(mid10_20p(i+a,1)+mid10_20p(i+b,1))/2,(mid10_20p(i+a,2)+mid10_20p(i+b,2))/2,channels(i), ...
                        'FontSize',8,'Color','red','HorizontalAlignment','center');
                else
                    if mod(i,13) == 0
                        a = -3;
                        b = -4;
                    else
                        a = a-2;
                        b = b-2;
                    end
                    [app.xunit4_5(i,:),app.yunit4_5(i,:)] = circle((mid10_20p(i+a,1)+mid10_20p(i+b,1))/2,(mid10_20p(i+a,2)+mid10_20p(i+b,2))/2,0.06);
                    hold(app.UIAxes5,'on');
                    plot(app.UIAxes5,app.xunit4_5(i,:),app.yunit4_5(i,:),'r')
                    text(app.UIAxes5,(mid10_20p(i+a,1)+mid10_20p(i+b,1))/2,(mid10_20p(i+a,2)+mid10_20p(i+b,2))/2,channels(i), ...
                        'FontSize',8,'Color','red','HorizontalAlignment','center');
                end
            end
        end
        
        function graph(app)
            if app.TabGroup.SelectedTab == app.Tab
                grp_idx = strfind({app.dropdown2(:).name}, app.DropDown2.Value);
                grp_idx = find(~cellfun(@isempty,grp_idx));
                subj_idx = strfind({app.dropdown3(grp_idx,:).name}, app.DropDown3.Value);
                subj_idx = find(~cellfun(@isempty,subj_idx));
                ch_idx = strfind(app.DropDown4.Items, app.DropDown4.Value);
                ch_idx = find(~cellfun(@isempty,ch_idx));
                
                [~,smp] = size(app.mats_p(grp_idx,subj_idx).nkdata.eeg);
                t = 1:smp;
                app.Fs = app.mats_p(grp_idx,subj_idx).nkdata.sampHz;
                st = app.NumericEditField.Value;
                et = app.NumericEditField2.Value;
                sr = app.mats_p(grp_idx,subj_idx).nkdata.sampHz;
                app.NumericEditField3.Value = length(t)/sr;
                
                if st >= et
                    errordlg('Start Time must be smaller than End Time!','Time Error','modal')
                    app.errorflag = 1;
                else
                    app.errorflag = 0;
                    if et > app.NumericEditField3.Value
                        errordlg('End Time must be smaller than Recording Len.!','Time Error','modal')
                        app.errorflag = 1;
                    else
                        app.errorflag = 0;
                        app.UIAxes.cla;
                        
                        t = st:(1/app.Fs):et-(1/app.Fs);
                        plot(app.UIAxes, t, app.mats_p(grp_idx,subj_idx).nkdata.eeg(ch_idx,(st*sr)+1:et*sr));
                        xlabel(app.UIAxes, 'Time (.sec)');
                        ylabel(app.UIAxes, 'Amplitude (mV)');
                        title(app.UIAxes, ['Raw EEG -',' ',app.DropDown3.Value,'/',app.DropDown4.Value], 'Interpreter', 'none');
                        ylimmax = max(app.mats_p(grp_idx,subj_idx).nkdata.eeg(ch_idx,(st*sr)+1:et*sr));
                        ylimmin = min(app.mats_p(grp_idx,subj_idx).nkdata.eeg(ch_idx,(st*sr)+1:et*sr));
                        xlim(app.UIAxes, [st, et-(1/app.Fs)])
                        yl1 = floor(ylimmin-abs(ylimmin*0.1)-0.5);
                        yl2 = ceil(ylimmax+abs(ylimmax*0.1)+0.5);
                        %                         display(ylimmin)
                        %                         display(yl2)
                        ylim(app.UIAxes, [yl1, yl2])
                    end
                end
            elseif app.TabGroup.SelectedTab == app.Tab2 && app.graphflag == 1
                app.errorflag = 0;
                app.UIAxes2.cla;
                grp_idx = strfind(app.DropDown5.Items, app.DropDown5.Value);
                grp_idx = find(~cellfun(@isempty,grp_idx));
                subj_idx = strfind(app.DropDown6.Items, app.DropDown6.Value);
                subj_idx = find(~cellfun(@isempty,subj_idx));
                ch_idx = strfind(app.DropDown7.Items, app.DropDown7.Value);
                ch_idx = find(~cellfun(@isempty,ch_idx));
                
                [~,smp] = size(app.mats_p(grp_idx,subj_idx).nkdata.eeg);
                app.Fs = app.mats_p(grp_idx,subj_idx).nkdata.sampHz;
                
                ulf_p = app.NumericEditField7.Value;
                llf_p = app.NumericEditField8.Value;
                bw_p = ulf_p - llf_p;
                cf_p = (ulf_p + llf_p)/2;
                x_p = (smp/2) + (cf_p*smp/app.Fs);
                y_p = (bw_p*smp/app.Fs);
                
                ulf = app.Fs/2;
                llf = -app.Fs/2;
                bw = ulf - llf;
                cf = (ulf + llf)/2;
                x = (smp/2) + (cf*smp/app.Fs);
                y = (bw*smp/app.Fs);
                
                frq = linspace(((-smp/2)+(x-y/2))*app.Fs/smp,((-smp/2)+(x+y/2))*app.Fs/smp,length((x-y/2)+1:(x+y/2)));
                frq_p = linspace(((-smp/2)+(x_p-y_p/2))*app.Fs/smp,((-smp/2)+(x_p+y_p/2))*app.Fs/smp,length((x_p-y_p/2)+1:(x_p+y_p/2)));
                
                app.errorflag = 0;
                if app.TabGroup3.SelectedTab == app.Tab7
                    if app.CheckBox2.Value == 1
                        
                        cut_on = app.NumericEditField4.Value;
                        cut_off = app.NumericEditField5.Value;
                        bw_order = app.NumericEditField6.Value;
                        Wc(1,1) = cut_on/(app.Fs/2);
                        Wc(1,2) = cut_off/(app.Fs/2); % define the cutoff frequency rads/sample
                        
                        N = length(frq); % number of points around the unit circle
                        [z, p, k] = butter (bw_order, Wc); sos = zp2sos (z, p, k); app.butterfilt = freqz(sos,N,'whole');
                        %                             app.butterfilt = freqz(b,a,N,'whole'); % evaluate transfer function at
                        app.butterfilt = fftshift(app.butterfilt((x-y/2)+1:(x+y/2),1));
                        AA = app.mats_f(grp_idx,subj_idx).nkdata.eeg(ch_idx,(x-y/2)+1:(x+y/2)).*(app.butterfilt)';
                        
                        %                         display(length(abs(app.butterfilt((x_p-y_p/2)+1:(x_p+y_p/2),1))))
                        %                         display((x_p-y_p/2))
                        if app.CheckBox3.Value == 1
                            hold(app.UIAxes2,'on');
                            plot(app.UIAxes2, frq_p, 20*log10((abs(app.butterfilt((x_p-y_p/2)+1:(x_p+y_p/2),1)))),'r');
                            plot(app.UIAxes2, frq_p, 20*log10(abs(app.mats_f(grp_idx,subj_idx).nkdata.eeg(ch_idx,(x_p-y_p/2)+1:(x_p+y_p/2)))),'b');
                            plot(app.UIAxes2, frq_p, 20*log10(abs(AA(1,(x_p-y_p/2)+1:(x_p+y_p/2)))),'g');
                            
                            xlabel(app.UIAxes2, 'Frequency (.sec)');
                            ylabel(app.UIAxes2, 'Amplitude (dB)');
                            title(app.UIAxes2, ['Frequency Spectrum of  -',' ',app.DropDown6.Value,'/',app.DropDown7.Value], 'Interpreter', 'none');
                            ylimmax1 = max(20*log10(abs((app.butterfilt((x_p-y_p/2)+1:(x_p+y_p/2),1)))));
                            ylimmin1 = min(20*log10(abs((app.butterfilt((x_p-y_p/2)+1:(x_p+y_p/2),1)))));
                            ylimmax2 = max(20*log10(abs(app.mats_f(grp_idx,subj_idx).nkdata.eeg(ch_idx,(x_p-y_p/2)+1:(x_p+y_p/2)))));
                            ylimmin2 = min(20*log10(abs(app.mats_f(grp_idx,subj_idx).nkdata.eeg(ch_idx,(x_p-y_p/2)+1:(x_p+y_p/2)))));
                            ylimmax3 = max(20*log10(abs(AA(1,(x_p-y_p/2)+1:(x_p+y_p/2)))));
                            ylimmin3 = min(20*log10(abs(AA(1,(x_p-y_p/2)+1:(x_p+y_p/2)))));
                            ylimmaxf = max(ylimmax1,ylimmax2); ylimmaxf = max(ylimmaxf,ylimmax3);
                            ylimminf = min(ylimmin1,ylimmin2); ylimminf = min(ylimminf,ylimmin3);
                            xlim(app.UIAxes2, [((-smp/2)+(x_p-y_p/2)+1)*(app.Fs/smp) ((-smp/2)+(x_p+y_p/2))*(app.Fs/smp)])
                            if isnan(ylimminf) || isinf(ylimminf)
                                ylimminf = -ylimmaxf;
                            end
                            yl1 = ((ylimminf-abs(ylimminf*0.1))-0.1);
                            yl2 = ((ylimmaxf+abs(ylimmaxf*0.1))+0.1);
                            ylim(app.UIAxes2, [yl1 yl2]);
                            %                             text(app.UIAxes2, ((-smp/2)+(x_p-y_p/2)+1)*(app.Fs/smp),ylimmaxf-ylimmaxf*0.1,'Filter','Color','red');
                            %                             text(app.UIAxes2, ((-smp/2)+(x_p-y_p/2)+1)*(app.Fs/smp),ylimmaxf-ylimmaxf*0.1),'Raw EEG','Color','blue');
                            %                             text(app.UIAxes2, ((-smp/2)+(x_p-y_p/2)+1)*(app.Fs/smp),ylimmaxf-ylimmaxf*0.1-10,'Filtered EEG','Color','green');
                            legend(app.UIAxes2,'Filter','Raw EEG','Filtered EEG');
                        elseif app.CheckBox4.Value == 1
                            hold(app.UIAxes2,'on');
                            plot(app.UIAxes2, frq_p, abs(app.butterfilt((x_p-y_p/2)+1:(x_p+y_p/2),1))/max(abs(app.butterfilt((x_p-y_p/2)+1:(x_p+y_p/2),1))),'r');
                            plot(app.UIAxes2, frq_p, abs(app.mats_f(grp_idx,subj_idx).nkdata.eeg(ch_idx,(x_p-y_p/2)+1:(x_p+y_p/2)))/max(abs(app.mats_f(grp_idx,subj_idx).nkdata.eeg(ch_idx,(x_p-y_p/2)+1:(x_p+y_p/2)))),'b');
                            plot(app.UIAxes2, frq_p, abs(AA(1,(x_p-y_p/2)+1:(x_p+y_p/2)))/max(abs(AA(1,(x_p-y_p/2)+1:(x_p+y_p/2)))),'g');
                            
                            xlabel(app.UIAxes2, 'Frequency (Hz)');
                            ylabel(app.UIAxes2, '|Amplitude|');
                            title(app.UIAxes2, ['Frequency Spectrum of -',' ',app.DropDown6.Value,'/',app.DropDown7.Value], 'Interpreter', 'none');
                            ylimmax1 = max(abs(app.butterfilt((x_p-y_p/2)+1:(x_p+y_p/2),1))/max(abs(app.butterfilt((x_p-y_p/2)+1:(x_p+y_p/2),1))));
                            ylimmin1 = min(abs(app.butterfilt((x_p-y_p/2)+1:(x_p+y_p/2),1))/max(abs(app.butterfilt((x_p-y_p/2)+1:(x_p+y_p/2),1))));
                            ylimmax2 = max(abs(app.mats_f(grp_idx,subj_idx).nkdata.eeg(ch_idx,(x_p-y_p/2)+1:(x_p+y_p/2)))/max(abs(app.mats_f(grp_idx,subj_idx).nkdata.eeg(ch_idx,(x_p-y_p/2)+1:(x_p+y_p/2)))));
                            ylimmin2 = min(abs(app.mats_f(grp_idx,subj_idx).nkdata.eeg(ch_idx,(x_p-y_p/2)+1:(x_p+y_p/2)))/max(abs(app.mats_f(grp_idx,subj_idx).nkdata.eeg(ch_idx,(x_p-y_p/2)+1:(x_p+y_p/2)))));
                            ylimmax3 = max((abs(AA(1,(x_p-y_p/2)+1:(x_p+y_p/2))))/max(abs(AA(1,(x_p-y_p/2)+1:(x_p+y_p/2)))));
                            ylimmin3 = min((abs(AA(1,(x_p-y_p/2)+1:(x_p+y_p/2))))/max(abs(AA(1,(x_p-y_p/2)+1:(x_p+y_p/2)))));
                            ylimmaxf = max(ylimmax1,ylimmax2); ylimmaxf = max(ylimmaxf,ylimmax3);
                            ylimminf = min(ylimmin1,ylimmin2); ylimminf = min(ylimminf,ylimmin3);
                            xlim(app.UIAxes2, [((-smp/2)+(x_p-y_p/2)+1)*(app.Fs/smp) ((-smp/2)+(x_p+y_p/2))*(app.Fs/smp)]);
                            if isnan(ylimminf) || isinf(ylimminf)
                                ylimminf = -ylimmaxf;
                            end
                            yl1 = ((ylimminf-abs(ylimminf*0.1))-0.1);
                            yl2 = ((ylimmaxf+abs(ylimmaxf*0.1))+0.1);
                            ylim(app.UIAxes2, [yl1 yl2]);
                            %                             text(app.UIAxes2, ((-smp/2)+(x_p-y_p/2)+1)*(app.Fs/smp),ylimmaxf-ylimmaxf*0.1,'Filter','Color','red');
                            %                             text(app.UIAxes2, ((-smp/2)+(x_p-y_p/2)+1)*(app.Fs/smp),ylimmaxf-ylimmaxf*0.25,'Raw EEG','Color','blue');
                            %                             text(app.UIAxes2, ((-smp/2)+(x_p-y_p/2)+1)*(app.Fs/smp),ylimmaxf-ylimmaxf*0.4,'Filtered EEG','Color','green');
                            legend(app.UIAxes2,'Filter','Raw EEG','Filtered EEG');
                        end
                    else
                        plot(app.UIAxes2, frq_p, abs(app.mats_f(grp_idx,subj_idx).nkdata.eeg(ch_idx,(x_p-y_p/2)+1:(x_p+y_p/2))),'b');
                        legend(app.UIAxes2,'off');
                        xlabel(app.UIAxes2, 'Frequency (Hz)');
                        ylabel(app.UIAxes2, '|Amplitude|');
                        title(app.UIAxes2, ['Raw EEG Freq. Spect. -',' ',app.DropDown6.Value,'/',app.DropDown7.Value], 'Interpreter', 'none');
                        ylimmaxf = max(abs(app.mats_f(grp_idx,subj_idx).nkdata.eeg(ch_idx,(x_p-y_p/2)+1:(x_p+y_p/2))));
                        ylimminf = min(abs(app.mats_f(grp_idx,subj_idx).nkdata.eeg(ch_idx,(x_p-y_p/2)+1:(x_p+y_p/2))));
                        xlim(app.UIAxes2, [((-smp/2)+(x_p-y_p/2)+1)*(app.Fs/smp) ((-smp/2)+(x_p+y_p/2))*(app.Fs/smp)]);
                        yl1 = ((ylimminf-abs(ylimminf*0.1))-0.1);
                        yl2 = ((ylimmaxf+abs(ylimmaxf*0.1))+0.1);
                        ylim(app.UIAxes2, [yl1 yl2]);
                        %                 xlim(app.UIAxes, [st et-0.002])
                    end
                elseif app.TabGroup3.SelectedTab == app.Tab8
                    app.UIAxes3.cla;
                    app.UIAxes4.cla;
                    app.decom_lev = app.NumericEditField12.Value;
                    app.mot_wave = app.EditField.Value;
                    
                    st = app.NumericEditField.Value;
                    et = app.NumericEditField2.Value;
                    %                     display(app.CheckBox)
                    if app.CheckBox.Value == 1
                        AA = [];
                        
                        %                         cut_on = app.NumericEditField4.Value;
                        %                         cut_off = app.NumericEditField5.Value;
                        %                         bw_order = app.NumericEditField6.Value;
                        %                         Wc(1,1) = cut_on/(app.Fs/2);
                        %                         Wc(1,2) = cut_off/(app.Fs/2); % define the cutoff frequency rads/sample
                        
                        %                         N = length(frq); % number of points around the unit circle
                        %                         [z, p, k] = butter (bw_order, Wc); sos = zp2sos (z, p, k); app.butterfilt = freqz(sos,N,'whole');
                        %                             app.butterfilt = freqz(b,a,N,'whole'); % evaluate transfer function at
                        AA = app.mats_f(grp_idx,subj_idx).nkdata.eeg(ch_idx,(x-y/2)+1:(x+y/2)) .* ((app.butterfilt((x-y/2)+1:(x+y/2),1)))';
                        orj_sig = double(ifft(ifftshift(AA)));
                    else
                        orj_sig = double(app.mats_p(grp_idx,subj_idx).nkdata.eeg(ch_idx,(st*app.Fs)+1:et*app.Fs));
                    end
                    
                    csl = mod(length(orj_sig)/2^app.decom_lev,2);
                    
                    if csl ~= 0
                        pad = ((int32(length(orj_sig)/2^app.decom_lev)+1)*2^app.decom_lev) - (length(orj_sig));
                        padded_sig = wextend('1','zpd',orj_sig,pad,'r');
                    end
                    
                    [swa,swd] = swt(padded_sig,app.decom_lev,app.mot_wave);
                    app.swt_length = length(padded_sig);
                    %                     [~,smp]=size(padded_sig);
                    
                    ii = length(app.rd);
                    [~,kk] = size(swd);
                    r_swd = zeros(ii,kk);
                    k = 1;
                    rmv_dtl = 0;
                    for i = 1:ii
                        if ~isnan(app.rd(i))
                            r_swd(k,:) = swd(app.rd(i),:);
                            k = k+1;
                            rmv_dtl = 1;
                        end
                    end
                    if rmv_dtl ~= 1
                        r_swd = swd;
                    end
                    denoised = iswt(swa,r_swd,app.mot_wave);
                    
                    if app.CheckBox8.Value == 1 || app.CheckBox7.Value == 1
                        mediandev = mad(denoised,1);
                        thr = (mediandev/0.6745)*sqrt((2*log10(length(denoised)))/(length(denoised)));
                        denoised = wthresh(denoised, app.thr_met ,thr);
                    end
                    
                    st = app.NumericEditField11.Value;
                    et = app.NumericEditField10.Value;
                    
                    t = st:(1/app.Fs):et-(1/app.Fs);
                    
                    if ~strcmp(app.DropDown8.Value,'Orj. vs Denoised') && ~strcmp(app.DropDown8.Value,'Residual') && ~strcmp(app.DropDown8.Value,'Orj. vs Denoised (FFT)')
                        app.Panel7.Visible = 'off';
                        app.Panel8.Visible = 'on';
                        rd_c = regexp(app.DropDown8.Value, '\d+', 'match');
                        rd_b = char(rd_c);
                        lvl = str2double(rd_b);
                        
                        plot(app.UIAxes3, t, swa(lvl,(st*app.Fs)+1:et*app.Fs));
                        xlabel(app.UIAxes3, 'Time (.sec)');
                        ylabel(app.UIAxes3, 'Coefficients');
                        title(app.UIAxes3, ['Approximation @ -',' ',app.DropDown8.Value], 'Interpreter', 'none');
                        ylimmax = max(swa(lvl,(st*app.Fs)+1:et*app.Fs));
                        ylimmin = min(swa(lvl,(st*app.Fs)+1:et*app.Fs));
                        xlim(app.UIAxes3, [st et-(1/app.Fs)])
                        yl1 = floor(ylimmin-abs(ylimmin*0.1)-0.1);
                        yl2 = ceil(ylimmax+abs(ylimmax*0.1)+0.1);
                        ylim(app.UIAxes3, [yl1 yl2])
                        
                        plot(app.UIAxes4, t, swd(lvl,(st*app.Fs)+1:et*app.Fs));
                        xlabel(app.UIAxes4, 'Time (.sec)');
                        ylabel(app.UIAxes4, 'Coefficients');
                        title(app.UIAxes4, ['Details @ -',' ',app.DropDown8.Value], 'Interpreter', 'none');
                        ylimmax = max(swd(lvl,(st*app.Fs)+1:et*app.Fs));
                        ylimmin = min(swd(lvl,(st*app.Fs)+1:et*app.Fs));
                        xlim(app.UIAxes4, [st et-(1/app.Fs)])
                        yl1 = floor(ylimmin-abs(ylimmin*0.1)-0.1);
                        yl2 = ceil(ylimmax+abs(ylimmax*0.1)+0.1);
                        ylim(app.UIAxes4, [yl1 yl2])
                        
                    elseif strcmp(app.DropDown8.Value,'Orj. vs Denoised')
                        app.Panel7.Visible = 'off';
                        app.Panel8.Visible = 'on';
                        plot(app.UIAxes3, t, padded_sig(1,(st*app.Fs)+1:et*app.Fs));
                        xlabel(app.UIAxes3, 'Time (.sec)');
                        ylabel(app.UIAxes3, 'Apmlitude (mV)');
                        title(app.UIAxes3, 'Orj. Signal', 'Interpreter', 'none');
                        ylimmax = max(padded_sig(1,(st*app.Fs)+1:et*app.Fs));
                        ylimmin = min(padded_sig(1,(st*app.Fs)+1:et*app.Fs));
                        xlim(app.UIAxes3, [st et-(1/app.Fs)])
                        yl1 = floor(ylimmin-abs(ylimmin*0.1)-0.1);
                        yl2 = ceil(ylimmax+abs(ylimmax*0.1)+0.1);
                        ylim(app.UIAxes3, [yl1 yl2])
                        
                        plot(app.UIAxes4, t, denoised(1,(st*app.Fs)+1:et*app.Fs));
                        xlabel(app.UIAxes4, 'Time (.sec)');
                        ylabel(app.UIAxes4, 'Apmlitude (mV)');
                        title(app.UIAxes4, 'Denoised Signal', 'Interpreter', 'none');
                        ylimmax = max(denoised(1,(st*app.Fs)+1:et*app.Fs));
                        ylimmin = min(denoised(1,(st*app.Fs)+1:et*app.Fs));
                        xlim(app.UIAxes4, [st et-(1/app.Fs)])
                        yl1 = floor(ylimmin-abs(ylimmin*0.1)-0.1);
                        yl2 = ceil(ylimmax+abs(ylimmax*0.1)+0.1);
                        ylim(app.UIAxes4, [yl1 yl2])
                        
                    elseif strcmp(app.DropDown8.Value,'Orj. vs Denoised (FFT)')
                        app.Panel7.Visible = 'on';
                        app.Panel8.Visible = 'off';
                        
                        st = 0;
                        et = length(padded_sig)/app.Fs;
                        padded_sig_f = fftshift(fft(padded_sig(1,(st*app.Fs)+1:et*app.Fs)));
                        plot(app.UIAxes3, frq_p, abs(padded_sig_f(1,(x_p-y_p/2)+1:(x_p+y_p/2))));
                        xlabel(app.UIAxes3, 'Frequency (Hz)');
                        ylabel(app.UIAxes3, '|Apmlitude|');
                        title(app.UIAxes3, 'Orj. Signal Freq. Spect.', 'Interpreter', 'none');
                        ylimmax = max(abs(padded_sig_f(1,(x_p-y_p/2)+1:(x_p+y_p/2))));
                        ylimmin = min(abs(padded_sig_f(1,(x_p-y_p/2)+1:(x_p+y_p/2))));
                        xlim(app.UIAxes3, [((-smp/2)+(x_p-y_p/2)+1)*(app.Fs/smp) ((-smp/2)+(x_p+y_p/2))*(app.Fs/smp)])
                        yl1 = floor(ylimmin-abs(ylimmin*0.1)-0.1);
                        yl2 = ceil(ylimmax+abs(ylimmax*0.1)+0.1);
                        ylim(app.UIAxes3, [yl1 yl2])
                        
                        denoised_f = fftshift(fft(denoised(1,(st*app.Fs)+1:et*app.Fs)));
                        plot(app.UIAxes4, frq_p, abs(denoised_f(1,(x_p-y_p/2)+1:(x_p+y_p/2))));
                        xlabel(app.UIAxes4, 'Frequency (Hz)');
                        ylabel(app.UIAxes4, '|Apmlitude|');
                        title(app.UIAxes4, 'Denoised Signal Freq. Spect.', 'Interpreter', 'none');
                        ylimmax = max(abs(denoised_f(1,(x_p-y_p/2)+1:(x_p+y_p/2))));
                        ylimmin = min(abs(denoised_f(1,(x_p-y_p/2)+1:(x_p+y_p/2))));
                        xlim(app.UIAxes4, [((-smp/2)+(x_p-y_p/2)+1)*(app.Fs/smp) ((-smp/2)+(x_p+y_p/2))*(app.Fs/smp)])
                        yl1 = floor(ylimmin-abs(ylimmin*0.1)-0.1);
                        yl2 = ceil(ylimmax+abs(ylimmax*0.1)+0.1);
                        ylim(app.UIAxes4, [yl1 yl2])
                        
                    elseif strcmp(app.DropDown8.Value,'Residual')
                        app.Panel7.Visible = 'off';
                        app.Panel8.Visible = 'off';
                        res = padded_sig - denoised;
                        res_f = fftshift(fft(res));
                        plot(app.UIAxes3, t, res(1,(st*app.Fs)+1:et*app.Fs));
                        xlabel(app.UIAxes3, 'Time (.sec)');
                        ylabel(app.UIAxes3, 'Amplitude (mV)');
                        title(app.UIAxes3, 'Residual',  'Interpreter', 'none');
                        ylimmax = max(res(1,(st*app.Fs)+1:et*app.Fs));
                        ylimmin = min(res(1,(st*app.Fs)+1:et*app.Fs));
                        xlim(app.UIAxes3, [st et-(1/app.Fs)])
                        yl1 = floor(ylimmin-abs(ylimmin*0.1)-0.1);
                        yl2 = ceil(ylimmax+abs(ylimmax*0.1)+0.1);
                        ylim(app.UIAxes3, [yl1 yl2])
                        
                        plot(app.UIAxes4, frq, abs(res_f(1,(x-y/2)+1:(x+y/2))));
                        xlabel(app.UIAxes4, 'Frequency (Hz)');
                        ylabel(app.UIAxes4, '|Amplitude|');
                        title(app.UIAxes4, 'Residual Freq. Spect.', 'Interpreter', 'none');
                        ylimmax = max(abs(res_f(1,(x-y/2)+1:(x+y/2))));
                        ylimmin = min(abs(res_f(1,(x-y/2)+1:(x+y/2))));
                        xlim(app.UIAxes4, [0 ((-smp/2)+(x+y/2))*(app.Fs/smp)])
                        yl1 = floor(ylimmin-abs(ylimmin*0.1)-0.1);
                        yl2 = ceil(ylimmax+abs(ylimmax*0.1)+0.1);
                        ylim(app.UIAxes4, [yl1 yl2])
                    end
                end
            end
        end
        
        function update_info(app)
            app.TextArea.Value = ['Total: ' app.group_num ' groups ' '; ' app.sub_num ' subjects for each'];
            app.TextArea.Value = [app.TextArea.Value; 'Max. recording length: ' num2str(app.max_recc) '.sec'];
            app.TextArea.Value = [app.TextArea.Value; 'Min. recording length: ' num2str(app.min_recc) '.sec'];
            app.TextArea.Value = [app.TextArea.Value; 'Sample rate: ' app.samp_rate 'Hz'];
            app.TextArea.Value = [app.TextArea.Value; 'Channel number: ' app.cn_num  ];
        end
        
        function frq_spect(app)
            app.prcs = 0;
            p = 0;
            app.btn_state = 5;
            app.process;
            [i,j] = size(app.matpath);
            ch = app.mats_p(1,1).nkdata.nchannels;
            
            app.mats_f = [];
            
            
            for ii = 1:i
                for jj = 1:j
                    for cc = 1:ch
                        A = double(app.mats_p(ii,jj).nkdata.eeg(cc,:));
                        app.mats_f(ii,jj).nkdata.eeg(cc,:) = fftshift(fft(A));
                        %                         app.mats_f(ii,jj).nkdata.eeg(cc,:) = abs(B);
                        p =p+1;
                        app.prcs = p/(i*j*ch);
                        app.process;
                    end
                end
            end
            msgbox('Calculation completed', 'Success','help','modal');
            graph(app);
            
        end
        
        %         function hilbert_transform(app)
        %             app.prcs = 0;
        %             p = 0;
        %             app.btn_state = 7;
        %             app.process;
        %             [i,j] = size(app.matpath);
        %             ch = app.mats_p(1,1).nkdata.nchannels;
        %
        %             for ii = 1:i
        %                 for jj = 1:j
        %                     for cc = 1:ch
        %                         app.hilbert_sig(ii,jj).nkdata.eeg(cc,:) = hilbert(app.denoised_sig(ii,jj).nkdata.eeg(cc,:));
        %                         p =p+1;
        %                         app.prcs = p/(i*j*ch);
        %                         app.process;
        %                     end
        %                 end
        %             end
        %
        %         end
        %
        %         function arg(app)
        %             app.prcs = 0;
        %             p = 0;
        %             app.btn_state = 8;
        %             app.process;
        %             [i,j] = size(app.matpath);
        %             ch = app.mats_p(1,1).nkdata.nchannels;
        %
        %             for ii = 1:i
        %                 for jj = 1:j
        %                     for cc = 1:ch
        %                         Z = app.denoised_sig(ii,jj).nkdata.eeg(cc,:) + 1i*app.hilbert_sig(ii,jj).nkdata.eeg(cc,:);
        %                         arg(ii,jj).nkdata.eeg(cc,:) = angle(Z);
        %                         p =p+1;
        %                         app.prcs = p/(i*j*ch);
        %                         app.process;
        %                     end
        %                 end
        %             end
        %
        %         end
        
        function localanalysisI(app)
            app.prcs = 0;
            p = 0;
            app.btn_state = 8;
            app.process;
            [i,j] = size(app.matpath);
            %             ch = app.mats_p(1,1).nkdata.nchannels;
            app.arg_r = [];
            
            n = length(app.denoised_sig(1,1).nkdata.eeg(1,:));
            reg3_mean = zeros(1,n);
            
            reg_id = [1 2 4 5];
            n = length(reg_id);
            ii1 = nchoosek(n,2);
            
            for ii = 1:i
                for jj = 1:j
                    reg1_mean = (app.denoised_sig(ii,jj).nkdata.eeg(5,:) + app.denoised_sig(ii,jj).nkdata.eeg(9,:))/2;
                    reg2_mean = (app.denoised_sig(ii,jj).nkdata.eeg(2,:) + app.denoised_sig(ii,jj).nkdata.eeg(3,:))/2;
                    
                    reg4_mean = (app.denoised_sig(ii,jj).nkdata.eeg(14,:) + app.denoised_sig(ii,jj).nkdata.eeg(15,:))/2;
                    reg5_mean = (app.denoised_sig(ii,jj).nkdata.eeg(8,:) + app.denoised_sig(ii,jj).nkdata.eeg(12,:))/2;
                    regmeans = [reg1_mean;reg2_mean;reg3_mean;reg4_mean;reg5_mean];
                    for rr = 1:5
                        app.hilbert_regs = hilbert(regmeans(rr,:));
                        
                        Z = regmeans(rr,:) + 1i*app.hilbert_regs;
                        app.arg_r(ii,jj).regs(rr,:) = angle(Z);
                        
                        p =p+1;
                        app.prcs = p/(i*j*(5+ii1));
                        app.process;
                    end
                    i2 = 1;
                    i3 = 2;
                    reg_id = [1 2 4 5];
                    for i1=1:ii1
                        N = length(app.arg_r(ii,jj).regs(reg_id(i2),:));
                        syn_time_mean = ...
                            sum(exp(1i*(app.arg_r(ii,jj).regs(reg_id(i2),:) - app.arg_r(ii,jj).regs(reg_id(i3),:))))/N;
                        app.y_hI(ii,jj).idx(1,i1) = abs(syn_time_mean);
                        if i3 < length(reg_id)
                            i3 = i3+1;
                        elseif i3 >= length(reg_id)
                            reg_id(1) = [];
                            i2 = 1;
                            i3 = 2;
                        end
                        p =p+1;
                        app.prcs = p/(i*j*(5+ii1));
                        app.process;
                    end                    
                end
            end
            %             btw_ch_reg(app); % --> localanalysisI_map(app) || localanalysisII_map(app);
        end
        
        function localanalysisI_map(app)
            grp_idx = strfind(app.DropDown9.Items, app.DropDown9.Value);
            grp_idx = find(~cellfun(@isempty,grp_idx));
            subj_idx = strfind(app.DropDown10.Items, app.DropDown10.Value);
            subj_idx = find(~cellfun(@isempty,subj_idx));
            
            [~,n] = size(app.ListBox2.Value);
            ii = nchoosek(n,2);
            i2 = 1;
            i3 = 2;
            
            for i=1:ii
                %                                 display(app.reg_idx(i2));
                %                                 display(app.reg_idx(i3));
                %                 display(size(app.arg_r(grp_idx,subj_idx).regs(:,:)));
                
                %                 display(y_h);
                if app.reg_idx(i2) == 1
                    if app.reg_idx(i3) == 2
                        i1 = 1;
                    elseif app.reg_idx(i3) == 4
                        i1 = 2;
                    else
                        i1 = 3;
                    end
                elseif app.reg_idx(i2) == 2
                    if app.reg_idx(i3) == 4
                        i1 = 4;
                    else
                        i1 = 5;
                    end
                else
                    i1 = 6;
                end
                a = app.y_hI(grp_idx,subj_idx).idx(1,i1);
                %                 [R,G,B] = rgb_val(app, a);
                R = 0;
                G = a;
                B = 1-a;
                %                                 display(a);
                %                                 display(G);
                %                                 display(B);
                x1 = app.xunit3_5(app.reg_idx(i2),:);
                x2 = app.xunit3_5(app.reg_idx(i3),:);
                y1 = app.yunit3_5(app.reg_idx(i2),:);
                y2 = app.yunit3_5(app.reg_idx(i3),:);
                r = 0.07;
                [Ax1,Ay1,Ax2,Ay2] = regconnectp(x1,y1,x2,y2,r);
                hold(app.UIAxes5,'on');
                plot(app.UIAxes5,[Ax1 Ax2],[Ay1 Ay2],'LineWidth',2,'Color' , [R,G,B])
                xlabel(app.UIAxes5, 'Spatial Coordinat (X)');
                ylabel(app.UIAxes5, 'Spatial Coordinat (Y)');
                title(app.UIAxes5, ['Syncronization Map (Local Analysis I) -',' ',app.DropDown9.Value,'/',app.DropDown10.Value], 'Interpreter', 'none');
                
                if i3 < length(app.reg_idx)
                    i3 = i3+1;
                elseif i3 >= length(app.reg_idx)
                    app.reg_idx(1) = [];
                    i2 = 1;
                    i3 = 2;
                end
            end
        end
        
        function localanalysisII(app)
            app.prcs = 0;
            p = 0;
            app.btn_state = 9;
            app.process;
            reg1 = {'ch5','ch9'}; reg2 = {'ch2','ch3'}; reg3 = {' ',' '}; reg4 = {'ch14','ch15'}; reg5 = {'ch8','ch12'};
            regs = [reg1; reg2; reg3; reg4; reg5];
            reg_id = [1 2 4 5];
            n = length(reg_id);
            [i,j] = size(app.matpath);
            ii1 = nchoosek(n,2);
            %             i1 = 1;
            i1_1 = 1;
            
            for ii = 1:i
                for jj = 1:j
                    reg_id = [1 2 4 5];
                    i2 = 1;
                    i3 = 2;
                    for iii = 1:ii1
                        for k = 1:2
                            for l = 1:2
                                %                                 display(reg_id(i2))
                                %                                 display(reg_id(i3))
                                %                                 display(reg_id(i3))
                                
                                ch_idx1_str = regexp(regs{reg_id(i2),k}, '\d+', 'match');
                                ch_idx2_str = regexp(regs{reg_id(i3),l}, '\d+', 'match');
                                ch_idx1_chr = char(ch_idx1_str);
                                ch_idx2_chr = char(ch_idx2_str);
                                ch_idx1_dbl = str2double(ch_idx1_chr);
                                ch_idx2_dbl = str2double(ch_idx2_chr);
                                
                                hilbert_regs1 = hilbert(app.denoised_sig(ii,jj).nkdata.eeg(ch_idx1_dbl,:));
                                Z1 = app.denoised_sig(ii,jj).nkdata.eeg(ch_idx1_dbl,:) + 1i*hilbert_regs1;
                                arg_r1 = angle(Z1);
                                
                                hilbert_regs2 = hilbert(app.denoised_sig(ii,jj).nkdata.eeg(ch_idx2_dbl,:));
                                Z2 = app.denoised_sig(ii,jj).nkdata.eeg(ch_idx2_dbl,:) + 1i*hilbert_regs2;
                                arg_r2 = angle(Z2);
                                
                                N = length(arg_r1);
                                syn_time_mean = sum(exp(1i*(arg_r1 - arg_r2)))/N;
                                
                                if i1_1 > 4
                                    i1_1 = 1;
                                end
                                app.y_hII(ii,jj).idx(iii,i1_1) = abs(syn_time_mean);
                                
                                i1_1 = i1_1+1;
                                %                                 i1 = i1+1;
                                
                                p =p+1;
                                app.prcs = p/(i*j*ii1*2*2);
                                app.process;
                            end
                        end
                        app.y_hII_mean(ii,jj).idx(iii) = sum(app.y_hII(ii,jj).idx(iii,:))/4;
                        if i3 < length(reg_id)
                            i3 = i3+1;
                        else
                            reg_id(1) = [];
                            i2 = 1;
                            i3 = 2;
                        end
                    end
                end                
            end
            %             btw_ch_reg(app); % --> localanalysisI_map(app) || localanalysisII_map(app);
        end
        
        function localanalysisII_map(app)
            grp_idx = strfind(app.DropDown9.Items, app.DropDown9.Value);
            grp_idx = find(~cellfun(@isempty,grp_idx));
            subj_idx = strfind(app.DropDown10.Items, app.DropDown10.Value);
            subj_idx = find(~cellfun(@isempty,subj_idx));
            
            reg1 = {'ch5','ch9'}; reg2 = {'ch2','ch3'}; reg3 = {' ',' '}; reg4 = {'ch14','ch15'}; reg5 = {'ch8','ch12'};
            regs = [reg1; reg2; reg3; reg4; reg5];
            
            [~,n] = size(app.ListBox2.Value);
            [~,nn] = size(app.ListBox3.Value);
            ii = nchoosek(n,2);
            %             nn = ii*4;
            i2 = 1;
            i3 = 2;
            
            for i=1:ii
                %                                 display(app.reg_idx(i2));
                %                                 display(app.reg_idx(i3));
                %                 display(size(app.arg_r(grp_idx,subj_idx).regs(:,:)));
                if app.reg_idx(i2) == 1
                    if app.reg_idx(i3) == 2
                        i1 = 1;
                    elseif app.reg_idx(i3) == 4
                        i1 = 2;
                    else
                        i1 = 3;
                    end
                elseif app.reg_idx(i2) == 2
                    if app.reg_idx(i3) == 4
                        i1 = 4;
                    else
                        i1 = 5;
                    end
                else
                    i1 = 6;
                end
                a = app.y_hII_mean(grp_idx,subj_idx).idx(i1);
                %                 [R,G,B] = rgb_val(app, a);
                R = 0;
                G = a;
                B = 1-a;
                %                 display(R);
                %                 display(G);
                %                 display(B);
                x1 = app.xunit3_5(app.reg_idx(i2),:);
                x2 = app.xunit3_5(app.reg_idx(i3),:);
                y1 = app.yunit3_5(app.reg_idx(i2),:);
                y2 = app.yunit3_5(app.reg_idx(i3),:);
                r = 0.07;
                [Ax1,Ay1,Ax2,Ay2] = regconnectp(x1,y1,x2,y2,r);
                
                if app.CheckBox_2.Value == 1
                    hold(app.UIAxes5,'on');
                    plot(app.UIAxes5,[Ax1 Ax2],[Ay1 Ay2],'LineWidth',2,'Color' , [R,G,B])
                    xlabel(app.UIAxes5, 'Spatial Coordinat (X)');
                    ylabel(app.UIAxes5, 'Spatial Coordinat (Y)');
                    title(app.UIAxes5, ['Syncronization Map (Local Analysis II) -',' ',app.DropDown9.Value,'/',app.DropDown10.Value], 'Interpreter', 'none');
                    
                end
                
                for kk = 1:nn
                    if app.ch_id(kk) <= i*4 && app.ch_id(kk) > (i-1)*4
                        i1_1 = mod(app.ch_id(kk),4);
                        if i1_1 == 0
                            i1_1 = 4;
                        end
                        if i1_1 <= 2
                            k = 1;
                            l = 1;
                            if i1_1 == 2
                                l = 2;
                            end
                        else
                            k = 2;
                            l = 1;
                            if i1_1 == 4
                                l = 2;
                            end
                        end
                        
                        a = app.y_hII(grp_idx,subj_idx).idx(i1,i1_1);
                        %                         [R,G,B] = rgb_val(app, a);
                        R = 0;
                        G = a;
                        B = 1-a;
                        
                        ch_idx1_str = regexp(regs{app.reg_idx(i2),k}, '\d+', 'match');
                        ch_idx2_str = regexp(regs{app.reg_idx(i3),l}, '\d+', 'match');
                        ch_idx1_chr = char(ch_idx1_str);
                        ch_idx2_chr = char(ch_idx2_str);
                        ch_idx1_dbl = str2double(ch_idx1_chr);
                        ch_idx2_dbl = str2double(ch_idx2_chr);
                        
                        x1 = app.xunit4_5(ch_idx1_dbl,:);
                        x2 = app.xunit4_5(ch_idx2_dbl,:);
                        y1 = app.yunit4_5(ch_idx1_dbl,:);
                        y2 = app.yunit4_5(ch_idx2_dbl,:);
                        r = 0.06;
                        [Ax1,Ay1,Ax2,Ay2] = regconnectp(x1,y1,x2,y2,r);
                        hold(app.UIAxes5,'on');
                        %                         colormap(app.UIAxes5,winter);
                        %                         c = colorbar(app.UIAxes5);
                        %                         c.Label.String = 'Syncronization Index';
                        %                         app.UIAxes5.FontSize = 10;
                        plot(app.UIAxes5,[Ax1 Ax2],[Ay1 Ay2],'LineWidth',2,'Color' , [R,G,B])
                        xlabel(app.UIAxes5, 'Spatial Coordinat (X)');
                        ylabel(app.UIAxes5, 'Spatial Coordinat (Y)');
                        title(app.UIAxes5, ['Syncronization Map (Local Analysis II) -',' ',app.DropDown9.Value,'/',app.DropDown10.Value], 'Interpreter', 'none');
                        %                         display(R); display(G); display(B); display(a);
                    end
                end
                
                if i3 < length(app.reg_idx)
                    i3 = i3+1;
                elseif i3 >= length(app.reg_idx)
                    app.reg_idx(1) = [];
                    i2 = 1;
                    i3 = 2;
                end                               
            end
        end
        
        function globalanalysis(app)
            app.prcs = 0;
            p = 0;
            app.btn_state = 10;
            app.process;
            [i,j] = size(app.matpath);
            ch = app.mats_p(1,1).nkdata.nchannels;
            N = length(app.denoised_sig(1,1).nkdata.eeg(1,:));
            %             display(N);
            %             display(app.denoised_sig(1,1).nkdata.eeg(1,:));
            %             sr = 500;
            %             N = floor(N/sr);
            mats_ff = [];
            
            for ii = 1:i
                for jj = 1:j
                    for cc = 1:ch-1
                        A = double(app.denoised_sig(ii,jj).nkdata.eeg(cc,:));
                        mats_ff(ii,jj).nkdata.eeg(cc,:) = (fft(A));
                        %                         mats_ff(ii,jj).nkdata.eeg(cc,:) = fftshift(fft(A));
                        XRe(cc,:) = real(mats_ff(ii,jj).nkdata.eeg(cc,:));
                        XIm(cc,:) = imag(mats_ff(ii,jj).nkdata.eeg(cc,:));
                        
                        p =p+1;
                        app.prcs = p/(i*j*((ch-1)+floor(N/2)));
                        app.process;
                    end
                    for iii = 1:floor(N/2)
                        C = cov(XRe(:,iii),XIm(:,iii));
                        CC = eig(C);
                        app.GF(ii,jj).GFS(iii) = abs(CC(1)-CC(2))/(CC(1)+CC(2));
%                         app.GF(ii,jj).GFS(iii) = abs(CC(1)-CC(2));
                        p =p+1;
                        app.prcs = p/(i*j*((ch-1)+floor(N/2)));
                        app.process;
                    end
                    app.GFS_m(ii,jj) = sum(app.GF(ii,jj).GFS(:))/floor(N/2);
                end
            end
            %             display(CCC);
            
            msgbox('Analysises completed', 'Success','help','modal');
        end
        
        function globalanalysis_map(app)
            grp_idx = strfind(app.DropDown9.Items, app.DropDown9.Value);
            grp_idx = find(~cellfun(@isempty,grp_idx));
            subj_idx = strfind(app.DropDown10.Items, app.DropDown10.Value);
            subj_idx = find(~cellfun(@isempty,subj_idx));
            
            N = length(app.denoised_sig(1,1).nkdata.eeg(1,:));
            smp = N;
            %             ulf = app.Fs/2;
            %             llf = -app.Fs/2;
            %             bw = ulf - llf;
            %             cf = (ulf + llf)/2;
            %             x = (smp/2) + (cf*smp/app.Fs);
            %             y = (bw*smp/app.Fs);
            
            ulf_p = app.NumericEditField7_2.Value;
            llf_p = app.NumericEditField8_2.Value;
            bw_p = ulf_p - llf_p;
            cf_p = (ulf_p + llf_p)/2;
            %             x_p = (smp/2) + (cf_p*smp/app.Fs/2);
            x_p = (cf_p*smp/app.Fs);
            y_p = (bw_p*smp/app.Fs);
            
            %             frq = ((-smp/2)+(x-y/2)+1:(-smp/2)+(x+y/2))*(app.Fs / smp);
            %             frq = (0:(-smp/2)+(x+y/2)-1)*(app.Fs/smp);
            frq_p = (((x_p-y_p/2)):(x_p+y_p/2)-1)*(app.Fs/smp);
            %             display(x_p-y_p/2)
            %             display(x_p+y_p/2)
            GFS_p(grp_idx,subj_idx) = sum(app.GF(grp_idx,subj_idx).GFS(1,(x_p-y_p/2)+1:(x_p+y_p/2)))/length(frq_p);
            GFSval = num2str(GFS_p(grp_idx,subj_idx));
            %             hold(app.UIAxes5,'on');
            plot(app.UIAxes5, frq_p, app.GF(grp_idx,subj_idx).GFS(1,(x_p-y_p/2)+1:(x_p+y_p/2)),'b');
            xlabel(app.UIAxes5, 'Frequency (Hz)');
            ylabel(app.UIAxes5, 'GFS Index');
            title(app.UIAxes5, ['GFS Graph -',' ',app.DropDown9.Value,'/',app.DropDown10.Value], 'Interpreter', 'none');
            ylimmax = max(app.GF(grp_idx,subj_idx).GFS(1,(x_p-y_p/2)+1:(x_p+y_p/2)));
            ylimmin = min(app.GF(grp_idx,subj_idx).GFS(1,(x_p-y_p/2)+1:(x_p+y_p/2)));
            %             text(app.UIAxes5, ((x_p-y_p/2))*(app.Fs/smp)+0.01,ylimmax+abs(ylimmax*0.1),['Mean GFS =', GFSval],'Color','red');
            legend(app.UIAxes5, ['Mean GFS =', GFSval])
            xlim(app.UIAxes5, [((x_p-y_p/2))*(app.Fs/smp) ((x_p+y_p/2)-1)*(app.Fs/smp)])
            yl1 = (ylimmin-abs(ylimmin*0.1)-0.1);
            yl2 = (ylimmax+abs(ylimmax*0.1)+0.1);
            ylim(app.UIAxes5, [yl1 yl2])
        end
        
        function sync_var_tab(app)
            grppath = app.grp_file_name;
            grppath_j = app.sbj_file_name;
            % You can also use dir to get the list of the subfolders
            [ii,~] = size(grppath);
            [~,j] = size(grppath_j);
            approach = {'LocalAnalysisI.xls' 'LocalAnalysisII.xls' 'GlobalAnalysis.xls'};
            app.UITable.ColumnName = {};
            [~,i] = size(approach);
            %                         [~,i1] = size(approach);
            %             xlswrite(fullfile(selpath,'l.xls'),A);
            %             app.y_hI(ii,jj).idx(1,i1) = abs(syn_time_mean);
            if strcmp(app.DropDown9_2.Value,'Local Analysis I') || strcmp(app.DropDown9_2.Value,'Local Analysis II')
                if strcmp(app.DropDown9_2.Value,'Local Analysis I')
                    ap = 1;
                    GDATA = [];
                    GDATA = app.y_hI;
                elseif strcmp(app.DropDown9_2.Value,'Local Analysis II')
                    ap = 2;
                    GDATA = [];
                    GDATA = app.y_hII_mean;
                end
                A1 =  [];
                A1n = [string('Sbj. No') string('R1-R2') string('R1-R4') string('R1-R5') string('R2-R4') string('R2-R5') string('R4-R5')];


                [~,i1] = size(A1n);
                for h = 1:i
                    app.DropDown10_2.Items(h) = {grppath(h+2).name}; % # rename 1st sheet
                end
                for h = 2:i1
                    app.DropDown10_2.Items(h+2) = {A1n{1,h}}; % # rename 1st sheet
                end
                
                var_idx = strfind(app.DropDown10_2.Items, app.DropDown10_2.Value);
                var_idx = find(~cellfun(@isempty,var_idx));
                
                if var_idx <= 3
                    h = var_idx;
                    %                     app.UITable.Position = [1 2 527 267];
                    app.UITable.ColumnName = {A1n(1); A1n(2); A1n(3); A1n(4); A1n(5); A1n(6); A1n(7)};
                    for k = 3:j
                        A1 = [A1; (k-2) GDATA(h,k-2).idx(1,:)];
                    end
                    app.UITable.Data = A1;
                else
                    h = var_idx -2;
                    A2 =  [];
                    A2n =  [string('Sbj. No') string(grppath(3).name) string(grppath(4).name) string(grppath(5).name)];
                    %                     app.UITable.Position = [1 2 306 267];
                    app.UITable.ColumnName = {A2n(1); A2n(2); A2n(3); A2n(4)};
                    for k = 3:j
                        B = GDATA(1,k-2).idx(1,h-1);
                        for kk = 4:ii
                            B = [B, GDATA(kk-2,k-2).idx(1,h-1)];
                        end
                        A2 = [A2; (k-2) B];
                    end
                    app.UITable.Data = A2;
                end
            else
                ap = 3;
                GDATA = [];
                GDATA =  (app.GFS_m(:,:))';
                A2 =  [];
                A2n =  [string('Sbj. No') string(grppath(3).name) string(grppath(4).name) string(grppath(5).name)];
                %                 app.UITable.Position = [1 2 306 267];
                app.UITable.ColumnName = {A2n(1); A2n(2); A2n(3); A2n(4)};
                for k = 3:j
                    A2 = [A2; (k-2) GDATA(k-2,:)];
                end
                app.UITable.Data = A2;
            end
        end
        
        function [H, pValue, W, skw, krt] = swtest(app, x, alpha)
            %SWTEST Shapiro-Wilk parametric hypothesis test of composite normality
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%% cite as
            %Ahmed BenSaïda (2020). Shapiro-Wilk and Shapiro-Francia normality tests. (https://www.mathworks.com
            %/matlabcentral/fileexchange/13964-
            %shapiro-wilk-and-shapiro-francia-normality-tests), MATLAB Central File Exchange. Retrieved June 14, 2020.
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%% cite as
            %   [H, pValue, SWstatistic] = SWTEST(X, ALPHA) performs the
            %   Shapiro-Wilk test to determine if the null hypothesis of
            %   composite normality is a reasonable assumption regarding the
            %   population distribution of a random sample X. The desired significance
            %   level, ALPHA, is an optional scalar input (default = 0.05).
            %
            %   The Shapiro-Wilk and Shapiro-Francia null hypothesis is:
            %   "X is normal with unspecified mean and variance."
            %
            %   This is an omnibus test, and is generally considered relatively
            %   powerful against a variety of alternatives.
            %   Shapiro-Wilk test is better than the Shapiro-Francia test for
            %   Platykurtic sample. Conversely, Shapiro-Francia test is better than the
            %   Shapiro-Wilk test for Leptokurtic samples.
            %
            %   When the series 'X' is Leptokurtic, SWTEST performs the Shapiro-Francia
            %   test, else (series 'X' is Platykurtic) SWTEST performs the
            %   Shapiro-Wilk test.
            %
            %    [H, pValue, SWstatistic] = SWTEST(X, ALPHA)
            %
            % Inputs:
            %   X - a vector of deviates from an unknown distribution. The observation
            %     number must exceed 3 and less than 5000.
            %
            % Optional inputs:
            %   ALPHA - The significance level for the test (default = 0.05).
            %
            % Outputs:
            %  SWstatistic - The test statistic (non normalized).
            %
            %   pValue - is the p-value, or the probability of observing the given
            %     result by chance given that the null hypothesis is true. Small values
            %     of pValue cast doubt on the validity of the null hypothesis.
            %
            %     H = 0 => Do not reject the null hypothesis at significance level ALPHA.
            %     H = 1 => Reject the null hypothesis at significance level ALPHA.
            %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %                Copyright (c) 17 March 2009 by Ahmed Ben Saïda          %
            %                 Department of Finance, IHEC Sousse - Tunisia           %
            %                       Email: ahmedbensaida@yahoo.com                   %
            %                    $ Revision 3.0 $ Date: 18 Juin 2014 $               %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %
            % References:
            %
            % - Royston P. "Remark AS R94", Applied Statistics (1995), Vol. 44,
            %   No. 4, pp. 547-551.
            %   AS R94 -- calculates Shapiro-Wilk normality test and P-value
            %   for sample sizes 3 <= n <= 5000. Handles censored or uncensored data.
            %   Corrects AS 181, which was found to be inaccurate for n > 50.
            %   Subroutine can be found at: http://lib.stat.cmu.edu/apstat/R94
            %
            % - Royston P. "A pocket-calculator algorithm for the Shapiro-Francia test
            %   for non-normality: An application to medicine", Statistics in Medecine
            %   (1993a), Vol. 12, pp. 181-184.
            %
            % - Royston P. "A Toolkit for Testing Non-Normality in Complete and
            %   Censored Samples", Journal of the Royal Statistical Society Series D
            %   (1993b), Vol. 42, No. 1, pp. 37-43.
            %
            % - Royston P. "Approximating the Shapiro-Wilk W-test for non-normality",
            %   Statistics and Computing (1992), Vol. 2, pp. 117-119.
            %
            % - Royston P. "An Extension of Shapiro and Wilk's W Test for Normality
            %   to Large Samples", Journal of the Royal Statistical Society Series C
            %   (1982a), Vol. 31, No. 2, pp. 115-124.
            %
            %
            % Ensure the sample data is a VECTOR.
            %
            if numel(x) == length(x)
                x  =  x(:);               % Ensure a column vector.
            else
                error(' Input sample ''X'' must be a vector.');
            end
            %
            % Remove missing observations indicated by NaN's and check sample size.
            %
            x  =  x(~isnan(x));
            if length(x) < 3
                error(' Sample vector ''X'' must have at least 3 valid observations.');
            end
            if length(x) > 5000
                warning('Shapiro-Wilk test might be inaccurate due to large sample size ( > 5000).');
            end
            %
            % Ensure the significance level, ALPHA, is a
            % scalar, and set default if necessary.
            %
            if (nargin >= 2) && ~isempty(alpha)
                if ~isscalar(alpha)
                    error(' Significance level ''Alpha'' must be a scalar.');
                end
                if (alpha <= 0 || alpha >= 1)
                    error(' Significance level ''Alpha'' must be between 0 and 1.');
                end
            else
                alpha  =  0.05;
            end
            % First, calculate the a's for weights as a function of the m's
            % See Royston (1992, p. 117) and Royston (1993b, p. 38) for details
            % in the approximation.
            x       =   sort(x); % Sort the vector X in ascending order.
            n       =   length(x);
            mtilde  =   norminv(((1:n)' - 3/8) / (n + 1/4));
            weights =   zeros(n,1); % Preallocate the weights.
            krt = kurtosis(x);
            skw = skewness(x);
            if kurtosis(x) > 3
                
                % The Shapiro-Francia test is better for leptokurtic samples.
                
                weights =   1/sqrt(mtilde'*mtilde) * mtilde;
                %
                % The Shapiro-Francia statistic W' is calculated to avoid excessive
                % rounding errors for W' close to 1 (a potential problem in very
                % large samples).
                %
                W   =   (weights' * x)^2 / ((x - mean(x))' * (x - mean(x)));
                % Royston (1993a, p. 183):
                nu      =   log(n);
                u1      =   log(nu) - nu;
                u2      =   log(nu) + 2/nu;
                mu      =   -1.2725 + (1.0521 * u1);
                sigma   =   1.0308 - (0.26758 * u2);
                newSFstatistic  =   log(1 - W);
                %
                % Compute the normalized Shapiro-Francia statistic and its p-value.
                %
                NormalSFstatistic =   (newSFstatistic - mu) / sigma;
                
                % Computes the p-value, Royston (1993a, p. 183).
                pValue   =   1 - normcdf(NormalSFstatistic, 0, 1);
                
            else
                
                % The Shapiro-Wilk test is better for platykurtic samples.
                c    =   1/sqrt(mtilde'*mtilde) * mtilde;
                u    =   1/sqrt(n);
                % Royston (1992, p. 117) and Royston (1993b, p. 38):
                PolyCoef_1   =   [-2.706056 , 4.434685 , -2.071190 , -0.147981 , 0.221157 , c(n)];
                PolyCoef_2   =   [-3.582633 , 5.682633 , -1.752461 , -0.293762 , 0.042981 , c(n-1)];
                % Royston (1992, p. 118) and Royston (1993b, p. 40, Table 1)
                PolyCoef_3   =   [-0.0006714 , 0.0250540 , -0.39978 , 0.54400];
                PolyCoef_4   =   [-0.0020322 , 0.0627670 , -0.77857 , 1.38220];
                PolyCoef_5   =   [0.00389150 , -0.083751 , -0.31082 , -1.5861];
                PolyCoef_6   =   [0.00303020 , -0.082676 , -0.48030];
                PolyCoef_7   =   [0.459 , -2.273];
                weights(n)   =   polyval(PolyCoef_1 , u);
                weights(1)   =   -weights(n);
                
                if n > 5
                    weights(n-1) =   polyval(PolyCoef_2 , u);
                    weights(2)   =   -weights(n-1);
                    
                    count  =   3;
                    phi    =   (mtilde'*mtilde - 2 * mtilde(n)^2 - 2 * mtilde(n-1)^2) / ...
                        (1 - 2 * weights(n)^2 - 2 * weights(n-1)^2);
                else
                    count  =   2;
                    phi    =   (mtilde'*mtilde - 2 * mtilde(n)^2) / ...
                        (1 - 2 * weights(n)^2);
                end
                
                % Special attention when n = 3 (this is a special case).
                if n == 3
                    % Royston (1992, p. 117)
                    weights(1)  =   1/sqrt(2);
                    weights(n)  =   -weights(1);
                    phi = 1;
                end
                %
                % The vector 'WEIGHTS' obtained next corresponds to the same coefficients
                % listed by Shapiro-Wilk in their original test for small samples.
                %
                weights(count : n-count+1)  =  mtilde(count : n-count+1) / sqrt(phi);
                %
                % The Shapiro-Wilk statistic W is calculated to avoid excessive rounding
                % errors for W close to 1 (a potential problem in very large samples).
                %
                W   =   (weights' * x) ^2 / ((x - mean(x))' * (x - mean(x)));
                %
                % Calculate the normalized W and its significance level (exact for
                % n = 3). Royston (1992, p. 118) and Royston (1993b, p. 40, Table 1).
                %
                newn    =   log(n);
                if (n >= 4) && (n <= 11)
                    
                    mu      =   polyval(PolyCoef_3 , n);
                    sigma   =   exp(polyval(PolyCoef_4 , n));
                    gam     =   polyval(PolyCoef_7 , n);
                    
                    newSWstatistic  =   -log(gam-log(1-W));
                    
                elseif n > 11
                    
                    mu      =   polyval(PolyCoef_5 , newn);
                    sigma   =   exp(polyval(PolyCoef_6 , newn));
                    
                    newSWstatistic  =   log(1 - W);
                    
                elseif n == 3
                    mu      =   0;
                    sigma   =   1;
                    newSWstatistic  =   0;
                end
                %
                % Compute the normalized Shapiro-Wilk statistic and its p-value.
                %
                NormalSWstatistic   =   (newSWstatistic - mu) / sigma;
                
                % NormalSWstatistic is referred to the upper tail of N(0,1),
                % Royston (1992, p. 119).
                pValue       =   1 - normcdf(NormalSWstatistic, 0, 1);
                
                % Special attention when n = 3 (this is a special case).
                if n == 3
                    pValue  =   6/pi * (asin(sqrt(W)) - asin(sqrt(3/4)));
                    % Royston (1982a, p. 121)
                end
                
            end
            
            H  = (alpha >= pValue);
        end
        

        function [H, F, df1, df2, P] = levenetest(app, X, alpha)
            %Levene's Test for Homogeneity of Variances.
            %[In the Levene's test the data are transforming to yij = abs[xij - mean(xj)]
            %and uses the F distribution performing an one-way ANOVA using y as the
            %dependent variable (Brownlee, 1965; Miller, 1986)].
            %
            %   Syntax: function [Levenetest] = Levenetest(X,alfa)
            %
            %     Inputs:
            %          X - data matrix (Size of matrix must be n-by-2; data=column 1, sample=column 2).
            %       alpha - significance level (default = 0.05).
            %     Outputs:
            %          - Sample variances vector.
            %          - Whether or not the homoscedasticity was met.
            %
            %    Example: From the example 10.1 of Zar (1999, p.180), to test the Levene's
            %             homoscedasticity of data with a significance level = 0.05.
            %
            %                                 Diet
            %                   ---------------------------------
            %                       1       2       3       4
            %                   ---------------------------------
            %                     60.8    68.7   102.6    87.9
            %                     57.0    67.7   102.1    84.2
            %                     65.0    74.0   100.2    83.1
            %                     58.6    66.3    96.5    85.7
            %                     61.7    69.8            90.3
            %                   ---------------------------------
            %
            %           Data matrix must be:
            %            X=[60.8 1;57.0 1;65.0 1;58.6 1;61.7 1;68.7 2;67.7 2;74.0 2;66.3 2;69.8 2;
            %            102.6 3;102.1 3;100.2 3;96.5 3;87.9 4;84.2 4;83.1 4;85.7 4;90.3 4];
            %
            %     Calling on Matlab the function:
            %             Levenetest(X)
            %
            %       Answer is:
            %
            % The number of samples are: 4
            %
            % ----------------------------
            % Sample    Size      Variance
            % ----------------------------
            %   1        5         9.3920
            %   2        5         8.5650
            %   3        4         7.6567
            %   4        5         8.3880
            % ----------------------------
            %
            % Levene's Test for Equality of Variances F=0.0335, df1= 3, df2=15
            % Probability associated to the F statistic = 0.9914
            % The associated probability for the F test is larger than 0.05
            % So, the assumption of homoscedasticity was met.
            %
            %  Created by A. Trujillo-Ortiz and R. Hernandez-Walls
            %             Facultad de Ciencias Marinas
            %             Universidad Autonoma de Baja California
            %             Apdo. Postal 453
            %             Ensenada, Baja California
            %             Mexico.
            %             atrujo@uabc.mx
            %
            %  April 11, 2003.
            %
            %  To cite this file, this would be an appropriate format:
            %  Trujillo-Ortiz, A. and R. Hernandez-Walls. (2003). Levenetest: Levene's test for
            %    homogeneity of variances. A MATLAB file. [WWW document]. URL http://www.mathworks.com/
            %    matlabcentral/fileexchange/loadFile.do?objectId=3375&objectType=FILE
            %
            %  References:
            %
            %  Brownlee, K. A. (1965) Statistical Theory and Methodology in Science
            %           and Engineering. New York: John Wiley & Sons.
            %  Miller, R. G. Jr. (1986) Beyond ANOVA, Basics of Applied Statistics.
            %           New York: John Wiley & Sons.
            %  Zar, J. H. (1999), Biostatistical Analysis (2nd ed.).
            %           NJ: Prentice-Hall, Englewood Cliffs. p. 180.
            %
            if nargin < 2
                alpha = 0.05;
            end
            Y=X;
            k=max(Y(:,2));
            %             fprintf('The number of samples are:%2i\n\n', k);
            %Levene's Procedure.
            n=[];s2=[];Z=[];
            indice=Y(:,2);
            for i=1:k
                Ye=find(indice==i);
                eval(['Y' num2str(i) '=Y(Ye,1);']);
                eval(['mY' num2str(i) '=mean(Y(Ye,1));']);
                eval(['n' num2str(i) '=length(Y' num2str(i) ') ;']);
                eval(['s2' num2str(i) '=(std(Y' num2str(i) ').^2) ;']);
                eval(['Z' num2str(i) '= abs((Y' num2str(i) ') - mY' num2str(i) ');']);
                eval(['xn= n' num2str(i) ';']);
                eval(['xs2= s2' num2str(i) ';']);
                eval(['x= Z' num2str(i) ';']);
                n=[n;xn];s2=[s2;xs2];Z=[Z;x];
            end
            Y=[Z Y(:,2)];
            %             fprintf('-----------------------------\n');
            %             disp(' Sample    Size      Variance')
            %             fprintf('-----------------------------\n');
            %             for i=1:k
            %                 fprintf('   %d       %2i         %.4f\n',i,n(i),s2(i))
            %             end
            %             fprintf('-----------------------------\n');
            %             disp(' ')
            C=(sum(Y(:,1)))^2/length(Y(:,1)); %correction term.
            SST=sum(Y(:,1).^2)-C; %total sum of squares.
            dfT=length(Y(:,1))-1; %total degrees of freedom.
            indice=Y(:,2);
            for i=1:k
                Ye=find(indice==i);
                eval(['A' num2str(i) '=Y(Ye,1);']);
            end
            A=[];
            for i=1:k
                eval(['x =((sum(A' num2str(i) ').^2)/length(A' num2str(i) '));']);
                A=[A,x];
            end
            SSA=sum(A)-C; %sample sum of squares.
            dfA=k-1; %sample degrees of freedom.
            SSE=SST-SSA; %error sum of squares.
            dfE=dfT-dfA; %error degrees of freedom.
            MSA=SSA/dfA; %sample mean squares.
            MSE=SSE/dfE; %error mean squares.
            F=MSA/MSE; %F-statistic.
            v1=dfA;df1=v1;
            v2=dfE;df2=v2;
            P = 1 - fcdf(F,v1,v2);  %probability associated to the F-statistic.
            H  = (alpha >= P);
            %             fprintf('Levene''s Test for Equality of Variances F=%3.4f, df1=%2i, df2=%2i\n', F,df1,df2);
            %             fprintf('Probability associated to the F statistic = %3.4f\n', P);
            %             if P >= alpha;
            %                 fprintf('The associated probability for the F test is equal or larger than% 3.2f\n', alpha);
            %                 fprintf('So, the assumption of homoscedasticity was met.\n');
            %             else
            %                 fprintf('The associated probability for the F test is smaller than% 3.2f\n', alpha);
            %                 fprintf('So, the assumption of homoscedasticity was not met.\n');
            %             end
        end
        
        function [h, p, stats] = games_howell(app, data, group, alpha)
            %
            % GAMES_HOWELL: post-hoc Games-Howell test for one-way ANOVA.
            %
            % [h,p,stats] = games_howell(data,group,alpha) performs the Games-Howell test
            % for one-way ANOVA (analysis of variance). The Games-Howell test compares
            % all individual group means to each other in a pairwise fashion. It
            % accommodates groups with unequal sample sizes (with a recommended minimum
            % number of 6 observations in any given group) and variances.
            %
            % INPUTS:
            %
            % The 'data' input is a vector with individual observations, and the
            % 'group' input is a vector with labels that indicate to which group each
            % observation in 'data' belongs. 'group' can be a numerical, string or cell
            % array. Both inputs are required. The syntax is similar to that of ANOVA1
            % in the Statistics Toolbox. The 'alpha' input optionally lets the user
            % select the significance level for statistical testing; the default is
            % 0.05.
            %
            % OUTPUTS:
            %
            % The 'h' output is a numerical array that is set to 1 if the Games-Howell
            % test rejects the null hypothesis of no difference between group mean
            % pairs at the alpha significance level. The 'p' output is a numerical
            % array that returns the probability of observing a value as extreme as or
            % more extreme than the test statistic under the null hypothesis. 'h' and
            % 'p' are square matrices whose dimension reflects the number of different
            % in 'group'. Their diagonal is set to NaN. The 'stats' structure contains:
            %  - gnames, the labels for the different groups
            %  - md, the differences between pairs of groups
            %  - q, the test statistic for each pairwise comparison
            %  - df, the degrees of freedom for each pairwise comparison
            %
            % EXAMPLE:
            %
            % data=[1+2.*randn(9,1);1+randn(14,1);-1+randn(11,1);randn(7,1)];
            % group=[ones(9,1);2.*ones(14,1);3.*ones(11,1);4.*ones(7,1)];
            % [h,p,stats]=games_howell(data, group);
            %
            % REFERENCES:
            %
            % Games PA, Howell JF. Pairwise Multiple Comparison Procedures with Unequal
            % N's and/or Variances: A Monte Carlo Study. Journal of Educational and
            % Behavioral Statistics 1976;1:113. doi: 10.3102/10769986001002113
            %
            % I used the equations in the following web pages to prepare this function:
            % - David C. Howell's Advanced Statistical Methods:
            %   https://www.uvm.edu/~dhowell/gradstat/psych341/labs/Lab1/Multcomp.html
            % - Jon Starkweather's Introduction to Statistics for the Social Sciences:
            %   http://www.unt.edu/rss/class/Jon/ISSS_SC/Module009/isss_m91_onewayanova
            %   /node7.html
            %
            % I tested the output against GNU PSPP 0.8.3 for Windows
            % (http://www.gnu.org/www.gnu.org/software/pspp/)
            %
            % This function calls the function CDFTUKEY, by Peter Nagy.
            % http://www.mathworks.com/matlabcentral/fileexchange/37450
            %
            % See also ANOVA1, CDFTUKEY.
            % Author of this function: pierre.megevand@gmail.com
            % perform some checks on the inputs
            
            if nargin<2
                error('The ''data'' and ''group'' inputs need to be defined. Type ''help games_howell'' for more information.');
            end
            if nargin<3
                alpha=0.05; % default alpha level
            end
            if ~isvector(data)||~isvector(group)
                error('The ''data'' and ''group'' inputs need to be vectors. Type ''help games_howell'' for more information.');
            end
            if numel(data)~=numel(group)
                error('The number of elements in ''data'' and ''group'' need to be the same. Type ''help games_howell'' for more information.');
            end
            [~,gnamesidx]=unique(group);
            gnames=group(sort(gnamesidx));
            ng=numel(gnames);
            [~,gidx]=ismember(group,gnames);
            h=NaN(ng);
            p=NaN(ng);
            md=NaN(ng);
            q=NaN(ng);
            df=NaN(ng);
            for n1=1:ng
                for n2=1:ng
                    if n1~=n2
                        md(n1,n2) = mean(data(gidx==n1))-mean(data(gidx==n2));
                        [q(n1,n2),se(n1,n2)] = compq(app, data(gidx==n1),data(gidx==n2));
                        df(n1,n2)=compdf(app, data(gidx==n1),data(gidx==n2));
                        p(n1,n2)=1-cdfTukey(app, abs(q(n1,n2)), df(n1,n2), ng);
                        if p(n1,n2)<=alpha
                            h(n1,n2)=1;
                        else
                            h(n1,n2)=0;
                        end
                    end
                end
            end
            
            %             q = compq(app, g1, g2);
            %             df = compdf(app, g1, g2);
            % prepare stats structure
            stats=[];
            stats.gnames = gnames;
            stats.md = md;
            stats.q = q;
            stats.df = df;
            stats.se = se;
        end
        
        % compute test statistic
        function [q,se] = compq(app, g1, g2)
            se=sqrt((var(g1)./numel(g1)+var(g2)./numel(g2))./2);
            q=(mean(g1)-mean(g2))./se;
        end
        
        % compute degrees of freedom
        function df = compdf(app, g1, g2)
            df=((var(g1)./numel(g1)+var(g2)./numel(g2)).^2) ./ ...
                (((var(g1)./numel(g1)).^2)./(numel(g1)-1) + ...
                ((var(g2)./numel(g2)).^2)./(numel(g2)-1));
        end
        
        function prtrng = cdfTukey(app, q, v, r)
            % The function returns the cumulative distribution function of the
            % studentized range distribution used in Tukey's HSD test
            % q - observed value of Tukey's test
            % v - degrees of freedom (total number of elements minus number of groups
            % r - number of samples
            pcutj = 0.00003;
            pcutk = 0.0001;
            step = 0.1125; % 0.45 in the original Fortran file
            vmax = 120;
            cv1 = 0.193064705;
            cv2 = 0.293525326;
            cvmax = 0.39894228;
            cv = [0.318309886,-0.268132716e-2, 0.347222222e-2, 0.833333333e-1];
            jmin = 12; % 3 in the original Fortran file
            jmax = 60; % 15 in the original Fortran file
            kmin = 28; % 7 in the original Fortran file
            kmax = 60; % 15 in the original Fortran file
            vw = zeros(121); % 30 in the original Fortran file
            qw = zeros(121); % 30 in the original Fortran file
            % Minimum and maximum number of steps are controlled by
            % jmin, jmax, kmin and kmax.  Accuracy can be increased
            % by use of a finer grid - Increase sizes of arrays vw
            % and qw, and jmin, jmax, kmin, kmax and 1/step proportionally.
            prtrng = 0;
            if not(q<=0 || v<1 || r<2)
                
                g = step*r^-0.2;
                gmid = 0.5*log(r);
                r1 = r-1;
                c = log(r*g*cvmax);
                if v <= vmax
                    h = step*v^-0.2;
                    v2 = v*0.5;
                    switch v
                        case 1
                            c = cv1;
                        case 2
                            c = cv2;
                        otherwise
                            c = sqrt(v2)*cv(1)/(1+((cv(2)/v2+cv(3))/v2+cv(4))/v2);
                    end
                    c = log(c*r*g*h);
                end
                gstep = g;
                qw(1) = -1;
                qw(jmax+1) = -1;
                pk1 = 1;
                pk2 = 1;
                k = 1;
                while k <= kmax % loop 28
                    gstep = gstep-g;
                    notDone21 = true;
                    while notDone21 % loop 21
                        gstep = -gstep;
                        gk = gmid+gstep;
                        pk = 0;
                        if not(pk2 <= pcutk && k > kmin) % test 26
                            w0 = c-gk*gk*0.5;
                            pz = 1-normcdf(gk);
                            x = 1-normcdf(gk-q)-pz;
                            if x>0
                                pk = exp(w0+r1*log(x));
                            end
                            if not(v>vmax) % test 26 again
                                jump = -jmax;
                                notDone22 = true;
                                while notDone22 % loop 22
                                    jump = jump+jmax;
                                    j = 1;
                                    while j <= jmax % loop 24
                                        jj = j+jump;
                                        if not(qw(jj)>0) % test 23
                                            hj = h*j;
                                            if j>jmax
                                                qw(jj+1) = -1;
                                            end
                                            ehj = exp(hj);
                                            qw(jj) = q*ehj;
                                            vw(jj) = v*(hj+0.5-ehj*ehj*0.5);
                                        end % test 23
                                        pj = 0;
                                        x = 1-normcdf(gk-qw(jj))-pz;
                                        if x>0
                                            pj = exp(w0+vw(jj)+r1*log(x));
                                        end
                                        pk = pk+pj;
                                        if not(pj>pcutj) % test 24
                                            if jj>jmin || k>kmin % if conditions are met, make sure to exit loop 24
                                                j = jmax+1;
                                            end
                                        end
                                        j = j+1;
                                    end % loop 24
                                    h = -h;
                                    notDone22 = h<0;
                                end % loop 22
                            end
                        end
                        prtrng = prtrng+pk;
                        if k>kmin && pk<=pcutk && pk1<=pcutk % if the conditions are met, the program will step out of loop 28 and loop 21
                            k = kmax+1; % make sure to exit loop 28
                            notDone21 = false; % make sure to exit loop 21
                        else
                            pk2=pk1;
                            pk1=pk;
                            notDone21=gstep>0;
                        end
                    end % loop 21
                    k=k+1;
                end % loop 28
            end  %if not(q<=0 || ifault==1
        end
        
        function check_intervals1(app)
            if app.NumericEditField.Value >= app.NumericEditField2.Value || app.NumericEditField.Value < 0 || app.NumericEditField.Value >= app.NumericEditField3.Value
                errordlg('Time intervals not consistent!','Frequency Error','modal')
                app.errorflag = 1;
            else
                app.errorflag = 0;
            end
            
            if app.NumericEditField2.Value > app.NumericEditField3.Value || app.NumericEditField2.Value <= app.NumericEditField.Value
                errordlg('Time intervals not consistent!','Frequency Error','modal')
                app.errorflag = 1;
            elseif app.errorflag ~= 1
                app.errorflag = 0;
            end
        end
        
        function check_intervals2(app)
            if app.NumericEditField8.Value < (-app.Fs/2) || app.NumericEditField8.Value >= app.NumericEditField7.Value
                errordlg('Frequency intervals not consistent!','Frequency Error','modal')
                app.errorflag = 1;
            else
                app.errorflag = 0;
            end
            
            if app.NumericEditField7.Value > (app.Fs/2) || app.NumericEditField7.Value <= app.NumericEditField8.Value
                errordlg('Frequency intervals not consistent!','Frequency Error','modal')
                app.errorflag = 1;
            elseif app.errorflag ~= 1
                app.errorflag = 0;
            end
        end
        
        function check_intervals3(app)
            if app.NumericEditField11.Value > app.swt_length/((app.Fs)-1) || app.NumericEditField11.Value >= app.NumericEditField10.Value
                errordlg('Time intervals not consistent!','Frequency Error','modal')
                app.errorflag = 1;
            else
                app.errorflag = 0;
            end
            
            if app.NumericEditField10.Value > app.swt_length/(app.Fs) || app.NumericEditField10.Value <= app.NumericEditField11.Value
                errordlg('Time intervals not consistent!','Frequency Error','modal')
                app.errorflag = 1;
            elseif app.errorflag ~= 1
                app.errorflag = 0;
            end
        end
        
        function check_intervals4(app)
            if app.NumericEditField8_2.Value < 0 || app.NumericEditField8_2.Value >= app.NumericEditField7_2.Value
                errordlg('Frequency intervals not consistent!','Frequency Error','modal')
                app.errorflag = 1;
            else
                app.errorflag = 0;
            end
            
            if app.NumericEditField7_2.Value > (app.Fs/2) || app.NumericEditField7_2.Value <= app.NumericEditField8_2.Value
                errordlg('Frequency intervals not consistent!','Frequency Error','modal')
                app.errorflag = 1;
            elseif app.errorflag ~= 1
                app.errorflag = 0;
            end
        end
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.graphflag = 0;
            app.errorflag = 0;
            app.btn_state = -1;
            filtertabs(app);
            thr_meth(app);
            thr_mets(app);
            removedetails(app);
            brain_map(app);
            %             app.ListBox2.Value(1) = app.ListBox2.Items(1);
            %             app.ListBox2.Value(2) = app.ListBox2.Items(2);
            app.btn_state = 0;
            app.declevchg = 0;
            app.UIAxes.cla;
            app.DropDown2.Items ={};
            app.DropDown3.Items ={};
            app.DropDown4.Items ={};
            
            app.NumericEditField.Value = 0;
            app.NumericEditField2.Value = 180;
            app.NumericEditField3.Value = 0;
            app.TextArea.Value = {' '};
            
            set(app.DropDown2,'Enable','off');
            set(app.DropDown3,'Enable','off');
            set(app.DropDown4,'Enable','off');
            set(app.Button2,'Enable','off');
            set(app.Button3,'Enable','off');
            set(app.Button4,'Enable','off');
            set(app.Button5,'Enable','off');
            set(app.Button6,'Enable','off');
            set(app.Button7,'Enable','off');
            set(app.NumericEditField2,'Enable','off');
            set(app.NumericEditField3,'Enable','off');
            set(app.NumericEditField,'Enable','off');
            set(app.TextArea,'Enable','off');
            
            set(app.Button11,'Enable','off');
            set(app.Button12,'Enable','off');
            set(app.Button13_2,'Enable','off');
            set(app.DropDown5,'Enable','off');
            set(app.DropDown6,'Enable','off');
            set(app.DropDown7,'Enable','off');
            set(app.DropDown8,'Enable','off');
            set(app.NumericEditField4,'Enable','off');
            set(app.NumericEditField5,'Enable','off');
            set(app.NumericEditField6,'Enable','off');
            set(app.NumericEditField7,'Enable','off');
            set(app.NumericEditField8,'Enable','off');
            set(app.EditField,'Enable','off');
            set(app.NumericEditField10,'Enable','off');
            set(app.NumericEditField11,'Enable','off');
            set(app.NumericEditField12,'Enable','off');
            set(app.ListBox,'Enable','off');
            set(app.CheckBox2,'Enable','off');
            set(app.CheckBox3,'Enable','off');
            set(app.CheckBox4,'Enable','off');
            set(app.CheckBox7,'Enable','off');
            set(app.CheckBox8,'Enable','off');
            %set(app.TextArea,'Enable','off');
            
            set(app.Button14,'Enable','off');
            set(app.Button15,'Enable','off');
            set(app.DropDown9,'Enable','off');
            set(app.DropDown10,'Enable','off');
            set(app.DropDown11,'Enable','off');
            set(app.ListBox2,'Enable','off');
            set(app.ListBox3,'Enable','off');
            set(app.CheckBox_2,'Enable','off');
            set(app.CheckBox_3,'Enable','off');
            
            set(app.Button14_2,'Enable','off');
            set(app.Button15_2,'Enable','off');
            set(app.Button7_2,'Enable','off');
            set(app.Button7_3,'Enable','off');
            set(app.DropDown9_2,'Enable','off');
            set(app.DropDown10_2,'Enable','off');
            set(app.SignificanceLevelEditField,'Enable','off');
            set(app.log10xCheckBox,'Enable','off');
            set(app.sqrtxCheckBox,'Enable','off');
            set(app.powx2CheckBox,'Enable','off');
            set(app.xCheckBox,'Enable','off');
            set(app.SignificanceLevelEditField_2,'Enable','off');
            set(app.ANOVAOneWayCheckBox,'Enable','off');
            set(app.TukeyCheckBox,'Enable','off');
            set(app.GamesHowellCheckBox,'Enable','off');
            set(app.SignificanceLevelEditField_3,'Enable','off');
            set(app.KruskalWallisCheckBox,'Enable','off');
            set(app.WilcoxonRankSumCheckBox,'Enable','off');
            
            app.MultipleComparasionsTukeyPanel.Visible = 'on';
            app.MultipleComparasionsGamesHowellPanel.Visible = 'off';
            app.WilcoxonRankSumPanel.Visible = 'off';
            app.KruskalWallisPanel.Visible = 'on';
            app.MultipleComparasionsDunnBonferroniApproachPanel.Visible = 'on';
        end

        % Button pushed function: Button
        function browse(app, event)
            app.errorflag = 0;
            
            selpath = uigetdir;
            
            if selpath ~= 0
                app.startupFcn2;
                grppath = dir(selpath);
                app.grp_file_name = grppath;
                [i,~] = size(grppath);
                grppath_i = struct;
                
                for ii = 3:i
                    try
                        grppath_i(ii-2).name = [selpath,'\',grppath(ii).name];
                        grppath_j(ii-2,:) = dir(grppath_i(ii-2).name);
                        app.dropdown2(ii-2,1).name = grppath(ii).name;
                    catch
                        errordlg('Groups not consistent! Please create subfolders for each group or equalize subject numbers within the groups.','File Error','modal')
                        app.errorflag = 1;
                        break;
                    end
                end
                
                app.sbj_file_name = grppath_j;
                [i,j] = size(grppath_j);
                if j <= 1 && app.errorflag ~= 1
                    errordlg('No Group Detected! Please create subfolders for each group.','File Error','modal')
                    app.errorflag = 1;
                elseif app.errorflag ~= 1
                    set(app.Button2,'Enable','on');
                    for ii = 1:i
                        for jj = 3:j
                            app.dropdown3(ii,jj-2).name = grppath_j(ii,jj).name;
                            app.matpath(ii,jj-2).name = [grppath_i(ii).name,'\',grppath_j(ii,jj).name];
                        end
                    end
                end
            end
        end

        % Value changed function: ListBox
        function group(app, event)
            %             value = app.ListBox.Value;
            
        end

        % Button pushed function: Button2
        function load(app, event)
            app.prcs = 0;
            p = 0;
            app.max_recc = 0;
            app.min_recc = Inf;
            if app.btn_state == 0
                clear(app)
                app.btn_state = 1;
            elseif app.btn_state == 2 && app.errorflag == 1
                app.btn_state = 4;
                app.errorflag = 0;
                close(app.f)
                
            end
            
            %             app.btn_state = btn_state;
            [i,j] = size(app.matpath);
            
            app.process;
            try
                for ii = 1:i
                    for jj = 1:j
                        mats(ii,jj) = load(app.matpath(ii,jj).name);
                        [k(ii,jj),l(ii,jj)] = size(mats(ii,jj).nkdata.ch_names);
                        sfrq(ii,jj) = mats(ii,jj).nkdata.sampHz;
                        max_rec(ii,jj) = length(mats(ii,jj).nkdata.eeg(1,:));
                        
                        if jj >= 2 && app.errorflag ~= 1
                            kk = k(ii,jj) - k(ii,jj-1);
                            ll = l(ii,jj) - l(ii,jj-1);
                            ss = sfrq(ii,jj) - sfrq(ii,jj-1);
                            
                            if kk ~= 0 || ll ~= 0
                                errordlg('EEG channels not consistent!','File Error','modal')
                                app.errorflag = 1;
                                break;
                            end
                            if ss ~= 0
                                errordlg('Sample rates not consistent!','File Error','modal')
                                app.errorflag = 1;
                                break;
                            end
                            if max_rec(ii,jj) > max_rec(ii,jj-1) && app.max_recc < max_rec(ii,jj) && app.max_recc < max_rec(ii,jj-1)
                                app.max_recc = max_rec(ii,jj);
                            elseif app.max_recc < max_rec(ii,jj-1)
                                app.max_recc = max_rec(ii,jj-1);
                            end
                            if max_rec(ii,jj) < max_rec(ii,jj-1) && app.min_recc > max_rec(ii,jj) && app.min_recc > max_rec(ii,jj-1)
                                app.min_recc = max_rec(ii,jj);
                            elseif app.min_recc > max_rec(ii,jj-1)
                                app.min_recc = max_rec(ii,jj-1);
                            end
                        end
                        if app.errorflag == 1
                            break;
                        else
                            p =p+1;
                            app.prcs = p/(i*j);
                            app.process;
                        end
                    end
                end
            catch
                errordlg('Groups not consistent! Please create subfolders for each group or equalize subjects.','File Error','modal')
                app.errorflag = 1;
            end


            if app.errorflag ~= 1
                
                set(app.DropDown2,'Enable','on');
                set(app.DropDown3,'Enable','on');
                set(app.DropDown4,'Enable','on');
                set(app.Button2,'Enable','on');
                set(app.Button3,'Enable','on');
                set(app.Button4,'Enable','on');
                set(app.Button6,'Enable','on');
                set(app.NumericEditField2,'Enable','on');
                set(app.NumericEditField3,'Enable','on');
                set(app.NumericEditField,'Enable','on');
                set(app.TextArea,'Enable','on');
                
                app.max_recc = app.max_recc/sfrq(ii,jj);
                app.min_recc = app.min_recc/sfrq(ii,jj);
                app.cn_num = num2str(mats(ii,jj).nkdata.nchannels);
                app.group_num = num2str(ii);
                app.sub_num = num2str(jj);
                app.samp_rate = num2str(sfrq(ii,jj));
                
                update_info(app);
                
                for ii = 1:k(1,1)
                    dropdown4(ii,:).name = mats(1,1).nkdata.ch_names(ii,:);
                end
                
                app.DropDown2.Items = {app.dropdown2(:).name};
                %                 editsubj(app);
                [i,~] = size(app.dropdown2);
                for ii = 1:i
                    if strcmp(app.DropDown2.Value,app.dropdown2(ii,1).name)
                        app.DropDown3.Items = {app.dropdown3(ii,:).name};
                    end
                end
                %                 app.DropDown3.Items = {app.dropdown3(1,:).name};
                app.DropDown4.Items = {dropdown4(:).name};
                
                app.mats_p = mats;
                app.graph;
                
                if app.btn_state == 1
                    msgbox('Loading completed', 'Success','help','modal');
                elseif app.btn_state == 4
                    msgbox('Reverting completed', 'Success','help','modal');
                end
                
                app.btn_state = 0;
            else
                clear(app);
            end
            
            
        end

        % Button pushed function: Button3
        function clear(app, event)
            clear app.matpath  app.mats_p app.dropdown2 app.dropdown3 app.mats_f app.errorflag app.graphflag ...
                app.prcs app.in_prcs app.f app.grpvalue app.sbjvalue app.btn_state app.max_recc app.min_recc app.cn_num ...
                app.group_num app.sub_num app.samp_rate app.Fs app.swt_length app.rd app.thr_met app.declevchg app.butterfilt ...
                app.bpf_signal app.decom_lev app.mot_wave app.denoised_sig;
            
            app.UIAxes.cla;
            app.UIAxes2.cla;
            app.UIAxes3.cla;
            app.UIAxes4.cla;
            xlabel(app.UIAxes, 'X');
            ylabel(app.UIAxes, 'Y');
            title(app.UIAxes, 'Title');
            xlabel(app.UIAxes2, 'X');
            ylabel(app.UIAxes2, 'Y');
            title(app.UIAxes2, 'Title');
            xlabel(app.UIAxes3, 'X');
            ylabel(app.UIAxes3, 'Y');
            title(app.UIAxes3, 'Title');
            xlabel(app.UIAxes4, 'X');
            ylabel(app.UIAxes4, 'Y');
            title(app.UIAxes4, 'Title');
            
            app.NumericEditField.Value = 0;
            app.NumericEditField2.Value = 180;
            app.NumericEditField3.Value = 0;
            
            app.DropDown2.Items = {};
            app.DropDown3.Items = {};
            app.DropDown4.Items = {};
            app.DropDown5.Items = {};
            app.DropDown6.Items = {};
            app.DropDown7.Items = {};
            app.DropDown8.Items = {};
            app.TextArea.Value = {' '};
            
            set(app.DropDown2,'Enable','off');
            set(app.DropDown3,'Enable','off');
            set(app.DropDown4,'Enable','off');
            
            set(app.Button2,'Enable','off');
            set(app.Button3,'Enable','off');
            set(app.Button4,'Enable','off');
            set(app.Button5,'Enable','off');
            set(app.Button6,'Enable','off');
            set(app.Button7,'Enable','off');
            
            set(app.NumericEditField2,'Enable','off');
            set(app.NumericEditField3,'Enable','off');
            set(app.NumericEditField,'Enable','off');
            set(app.TextArea,'Enable','off');
            
            set(app.Button11,'Enable','off');
            set(app.Button12,'Enable','off');
            set(app.DropDown5,'Enable','off');
            set(app.DropDown6,'Enable','off');
            set(app.DropDown7,'Enable','off');
            set(app.DropDown8,'Enable','off');
            set(app.NumericEditField4,'Enable','off');
            set(app.NumericEditField5,'Enable','off');
            set(app.NumericEditField6,'Enable','off');
            set(app.NumericEditField7,'Enable','off');
            set(app.NumericEditField8,'Enable','off');
            set(app.EditField,'Enable','off');
            set(app.NumericEditField10,'Enable','off');
            set(app.NumericEditField11,'Enable','off');
            set(app.NumericEditField12,'Enable','off');
            set(app.ListBox,'Enable','off');
            set(app.CheckBox2,'Enable','off');
            set(app.CheckBox3,'Enable','off');
            set(app.CheckBox4,'Enable','off');
            set(app.CheckBox7,'Enable','off');
            set(app.CheckBox8,'Enable','off');
            
            if app.in_prcs ~= 0
                close(app.f)
            end
            
            app.errorflag = 0;
            app.graphflag = 0;
        end

        % Value changed function: DropDown2
        function editsubj(app, event)
            %             grpvalue = app.DropDown2.Value;
            
            [i,~] = size(app.dropdown2);
            for ii = 1:i
                if strcmp(app.DropDown2.Value,app.dropdown2(ii,1).name)
                    app.DropDown3.Items = {app.dropdown3(ii,:).name};
                end
            end
            
            app.graph;
        end

        % Value changed function: DropDown3
        function graphsubj(app, event)
            %             subjvalue = app.DropDown3.Value;
            app.graph;
            
        end

        % Value changed function: DropDown4
        function graphch(app, event)
            %             chvalue = app.DropDown4.Value;
            app.graph;
            
        end

        % Value changed function: NumericEditField
        function stchang(app, event)
            %             stvalue = app.NumericEditField.Value;
            app.NumericEditField.Value = ceil(app.NumericEditField.Value);
            %             app.graph;
            %             value = app.NumericEditField.Value;
            check_intervals1(app)
            if  app.errorflag == 0
                graph(app);
            end
            
        end

        % Value changed function: NumericEditField2
        function etchang(app, event)
            %             etvalue = app.NumericEditField2.Value;
            app.NumericEditField2.Value = ceil(app.NumericEditField2.Value);
            %             app.graph;
            %             value = app.NumericEditField2.Value;
            check_intervals1(app)
            if app.errorflag == 0
                graph(app);
            end
            
        end

        % Button pushed function: Button4
        function editepoch(app, event)
            st = app.NumericEditField.Value;
            et = app.NumericEditField2.Value;
            
            app.prcs = 0;
            p = 0;
            app.btn_state = 2;
            
            [i,j] = size(app.matpath);
            ch = app.mats_p(1,1).nkdata.nchannels;
            
            
            if st >= et || et > app.NumericEditField3.Value
                errordlg('Time intervals not consistent!','Time Error','modal')
                app.errorflag = 1;
            else
                app.process;
                app.errorflag = 0;
                for ii = 1:i
                    for jj = 1:j
                        for cc = 1:ch
                            sr = app.mats_p(ii,jj).nkdata.sampHz;
                            rl = length(app.mats_p(ii,jj).nkdata.eeg(cc,:));
                            cl1 = et*sr;
                            cl2 = st*sr;
                            if cl1 > rl || cl2 > rl
                                app.errorflag = 1;
                                break;
                            end
                            
                            app.mats_p(ii,jj).nkdata.eeg(cc,1:length(cl2+1:cl1)) = app.mats_p(ii,jj).nkdata.eeg(cc,cl2+1:cl1);
                            
                            p =p+1;
                            app.prcs = p/(i*j*ch);
                            app.process;
                        end
                        
                        if app.errorflag == 1
                            break;
                        end
                        if length(cl2+1:cl1) < rl
                            app.mats_p(ii,jj).nkdata.eeg(:,length(cl2+1:cl1)+1:rl) = [];
                        end
                        
                    end
                end
            end
            if app.errorflag ~= 1
                app.NumericEditField.Value = 0;
                [~,app.NumericEditField2.Value] = size(app.mats_p(ii,jj).nkdata.eeg(cc,:));
                app.NumericEditField2.Value = app.NumericEditField2.Value/sr;
                
                app.max_recc = length(app.mats_p(ii,jj).nkdata.eeg(cc,:))/sr;
                app.min_recc = app.max_recc;
                update_info(app);
                app.graph;
                
                set(app.Button7,'Enable','on');
                set(app.Button5,'Enable','on');
                msgbox('Editing completed', 'Success','help','modal');
                app.btn_state = 0;
            else
                errordlg('Epoch time not consistent!','Time Error','modal')
                load(app)
            end
        end

        % Button pushed function: Button7
        function undo(app, event)
            clear(app);
            app.btn_state = 4;
            load(app);
        end

        % Button pushed function: Button5
        function nexttab0(app, event)
            if app.errorflag == 0
                app.graphflag = 1;
                app.TabGroup.SelectedTab = app.Tab2;
                app.DropDown5.Items = app.DropDown2.Items;
                app.DropDown6.Items = app.DropDown3.Items;
                app.DropDown7.Items = app.DropDown4.Items;
                app.NumericEditField7.Value = app.Fs/2;
                app.NumericEditField8.Value = -app.Fs/2;
                app.ListBox.Value(1) = app.ListBox.Items(1);
                app.ListBox.Value(2) = app.ListBox.Items(2);
                
                frq_spect(app);
                app.TabGroup3.SelectedTab = app.Tab8;
                filtertabs(app);
                app.TabGroup3.SelectedTab = app.Tab7;
                filtertabs(app);
                tab2enable(app);
            else
                errordlg('Time intervals not consistent!','Frequency Error','modal')
            end
        end

        % Value changed function: DropDown5
        function tab2grp(app, event)
            %             value = app.DropDown5.Value;
            [i,~] = size(app.dropdown2);
            for ii = 1:i
                if strcmp(app.DropDown5.Value,app.dropdown2(ii,1).name)
                    app.DropDown6.Items = {app.dropdown3(ii,:).name};
                end
            end
            graph(app);
            
        end

        % Value changed function: DropDown6
        function tab2sbj(app, event)
            %             value = app.DropDown6.Value;
            
            graph(app);
        end

        % Value changed function: DropDown7
        function tab2ch(app, event)
            %             value = app.DropDown7.Value;
            graph(app);
        end

        % Value changed function: NumericEditField7
        function upperfreq_chg(app, event)
            app.NumericEditField7.Value = ceil(app.NumericEditField7.Value);
            %             value = app.NumericEditField7.Value;
            check_intervals2(app);
            if  app.errorflag == 0
                graph(app);
            end
        end

        % Value changed function: NumericEditField8
        function lowerfreq_chg(app, event)
            app.NumericEditField8.Value = ceil(app.NumericEditField8.Value);
            %             value = app.NumericEditField8.Value;
            check_intervals2(app);
            if  app.errorflag == 0
                graph(app);
            end
        end

        % Value changed function: CheckBox2
        function shw_bpf(app, event)
            value = app.CheckBox2.Value;
            if value ==0
                set(app.CheckBox3,'Enable','off');
                set(app.CheckBox4,'Enable','off');
            else
                set(app.CheckBox3,'Enable','on');
                set(app.CheckBox4,'Enable','on');
            end
            graph(app);
            %                             Wc = [100 150]/250; % define the cutoff frequency rads/sample
            %                 [b,a] = butter(6,Wc); % use the butter function to produce the
            %                 % filter coefficients
            %                 N = length(frq); % number of points around the unit circle
            %                 FF = ((-smp/2)+(x-y/2)+1:(-smp/2)+(x+y/2))* (Fs / smp); % the corresponding frequency axis
            %                 app.butterfilt = freqz(b,a,N,'whole'); % evaluate transfer function at
            %                 % N equally spaced points
            %                 % around the unit circle
            %                 figure(3); % plot the magnitude of app.butterfilt vs. FF
            %                 plot(FF,abs(fftshift(app.butterfilt)));
            %                 grid on;
            %                 xlabel('frequency (cycles/sample)');
            %                 ylabel('magnitude (dB)');
        end

        % Value changed function: CheckBox3
        function bp_filt_view(app, event)
            value = app.CheckBox3.Value;
            if value == 1
                app.CheckBox4.Value = 0;
            else
                app.CheckBox4.Value = 1;
            end
            graph(app);
        end

        % Value changed function: CheckBox4
        function bp_filt_view1(app, event)
            value = app.CheckBox4.Value;
            if value == 1
                app.CheckBox3.Value = 0;
            else
                app.CheckBox3.Value = 1;
            end
            graph(app);
        end

        % Value changed function: NumericEditField4
        function cuton_frq_chg(app, event)
            value = app.NumericEditField4.Value;
            if value <= 0 || value >= (app.Fs/2) || value >= app.NumericEditField5.Value
                errordlg('Cut On Freq. not consistent!','Frequency Error','modal')
                app.errorflag = 1;
            else
                app.errorflag = 0;
                graph(app);
            end
            
        end

        % Value changed function: NumericEditField5
        function cutoff_frq_chg(app, event)
            value = app.NumericEditField5.Value;
            if value <= 0 || value >= (app.Fs/2) || value <= app.NumericEditField4.Value
                errordlg('Cut Off Freq. not consistent!','Frequency Error','modal')
                app.errorflag = 1;
            else
                app.errorflag = 0;
                graph(app);
            end
        end

        % Value changed function: NumericEditField6
        function bp_order(app, event)
            %             value = app.NumericEditField6.Value;
            graph(app);
        end

        % Value changed function: NumericEditField10
        function uppertime_chg(app, event)
            app.NumericEditField10.Value = ceil(app.NumericEditField10.Value);
            %             value = app.NumericEditField10.Value;
            check_intervals3(app);
            if app.errorflag == 0
                graph(app);
            end
            
        end

        % Value changed function: NumericEditField11
        function lowertime_chg(app, event)
            app.NumericEditField11.Value = ceil(app.NumericEditField11.Value);
            %             value = app.NumericEditField11.Value;
            check_intervals3(app);
            if app.errorflag == 0
                graph(app);
            end
        end

        % Value changed function: DropDown8
        function graphswt_param(app, event)
            %             value = app.DropDown8.Value;
            thr_meth(app);
            thr_mets(app);
            removedetails(app);
            graph(app);
        end

        % Value changed function: ListBox
        function removedetails(app, event)
            value = app.ListBox.Value;
            app.rd = 0;
            
            [~,ii] = size(value);
            value_nr = app.ListBox.Items;
            for i = 1:ii
                value_nr = strrep (value_nr, value(i), {' '} );
            end
            
            [~,ii] = size(value_nr);
            for i = 1:ii
                if ~strcmp(value_nr(i),' ')
                    value_nr(i) = app.ListBox.Items(i);
                end
                rd_c(i,:) = regexp(value_nr(i), '\d+', 'match');
                rd_b = char(rd_c{i,:});
                app.rd(i) = str2double(rd_b);
            end
            
            if (app.btn_state ~= (-1))
                app.declevchg = 0;
                graph(app);
            end
        end

        % Value changed function: CheckBox8
        function thr_meth(app, event)
            value = app.CheckBox8.Value;
            if value == 1
                app.thr_met = 'h';
                app.CheckBox7.Value = 0;
            end
            
            if app.btn_state ~= (-1)
                graph(app);
            end
        end

        % Value changed function: CheckBox7
        function thr_mets(app, event)
            value = app.CheckBox7.Value;
            if value == 1
                app.thr_met = 's';
                app.CheckBox8.Value = 0;
            end
            
            if app.btn_state ~= (-1)
                graph(app);
            end
        end

        % Value changed function: EditField
        function motwave(app, event)
            value = app.EditField.Value;
            a = regexp(value,'db','match');
            b = regexp(value,'\d*','match');
            
            if ~isempty(a) && ~isempty(b)
                c = [a{1} b{1}];
                if strcmp(value,c)
                    graph(app);
                else
                    errordlg('Mother wavelet not appropriate!','Mother Wavelet Error','modal')
                end
            else
                errordlg('Mother wavelet not appropriate!','Mother Wavelet Error','modal')
            end
        end

        % Value changed function: NumericEditField12
        function declev(app, event)
            %             value = app.NumericEditField12.Value;
            app.declevchg = 1;
            filtertabs(app);
            removedetails(app);
        end

        % Button pushed function: Button11
        function filtersignals(app, event)
            app.prcs = 0;
            p = 0;
            app.btn_state = 6;
            app.process;
            [i,j] = size(app.matpath);
            ch = app.mats_p(1,1).nkdata.nchannels;
            
            %             if app.run2_performed == 1
            app.bpf_signal = [];
            app.denoised_sig = [];
            %                 orj_sig(:,:).nkdata.eeg(:,:) = [];
            %             end
            
            for ii = 1:i
                for jj = 1:j
                    for cc = 1:ch
                        if app.CheckBox.Value == 1
                            app.bpf_signal(ii,jj).nkdata.eeg(cc,:) = app.mats_f(ii,jj).nkdata.eeg(cc,:) .* (app.butterfilt)';
                            orj_sig(ii,jj).nkdata.eeg(cc,:) = double(ifft(ifftshift(app.bpf_signal(ii,jj).nkdata.eeg(cc,:))));
                        else
                            orj_sig(ii,jj).nkdata.eeg(cc,:) = double(app.mats_p(ii,jj).nkdata.eeg(cc,:));
                        end
                        
                        if app.CheckBox6.Value == 1
                            csl = mod(length(orj_sig(ii,jj).nkdata.eeg(cc,:))/2^app.decom_lev,2);
                            
                            if csl ~= 0
                                pad = ((int32(length(orj_sig(ii,jj).nkdata.eeg(cc,:))/2^app.decom_lev)+1)*2^app.decom_lev) - (length(orj_sig(ii,jj).nkdata.eeg(cc,:)));
                                padded_sig = wextend('1','zpd',orj_sig(ii,jj).nkdata.eeg(cc,:),pad,'r');
                            end
                            
                            [swa,swd] = swt(padded_sig,app.decom_lev,app.mot_wave);
                            app.swt_length = length(padded_sig);
                            %                     [~,smp]=size(padded_sig);
                            
                            iif = length(app.rd);
                            [~,kk] = size(swd);
                            r_swd = zeros(iif,kk);
                            k = 1;
                            rmv_dtl = 0;
                            for i_f = 1:iif
                                if ~isnan(app.rd(i_f))
                                    r_swd(k,:) = swd(app.rd(i_f),:);
                                    k = k+1;
                                    rmv_dtl = 1;
                                end
                            end
                            if rmv_dtl ~= 1
                                r_swd = swd;
                            end
                            app.denoised_sig(ii,jj).nkdata.eeg(cc,:) = iswt(swa,r_swd,app.mot_wave);
                            
                            if app.CheckBox8.Value == 1 || app.CheckBox7.Value == 1
                                mediandev = mad(app.denoised_sig(ii,jj).nkdata.eeg(cc,:),1);
                                thr = (mediandev/0.6745)*sqrt((2*log10(length(app.denoised_sig(ii,jj).nkdata.eeg(cc,:))))/(length(app.denoised_sig(ii,jj).nkdata.eeg(cc,:))));
                                app.denoised_sig(ii,jj).nkdata.eeg(cc,:) = wthresh(app.denoised_sig(ii,jj).nkdata.eeg(cc,:), app.thr_met ,thr);
                            end
                            
                        else
                            app.denoised_sig(ii,jj).nkdata.eeg(cc,:) = orj_sig(ii,jj).nkdata.eeg(cc,:);
                        end
                        p =p+1;
                        app.prcs = p/(i*j*ch);
                        app.process;
                    end
                end
            end
            msgbox('Denoising completed', 'Success','help','modal');
            set(app.Button12,'Enable','on');
            set(app.Button13_2,'Enable','on');
            graph(app);
            app.run2_performed = 1;
        end

        % Button pushed function: Button12
        function nexttab1(app, event)
            %             app.graphflag = 1;
            app.TabGroup.SelectedTab = app.Tab3;
            app.DropDown9.Items = app.DropDown5.Items;
            app.DropDown10.Items = app.DropDown6.Items;
            %             hilbert_transform(app);
            %             arg(app);
            app.ListBox2.Value(1) = app.ListBox2.Items(1);
            app.ListBox2.Value(2) = app.ListBox2.Items(2);
            app.NumericEditField7_2.Value = app.Fs/2;
            app.NumericEditField8_2.Value = 0;
            
            %             btw_ch_reg(app);
            localanalysisI(app);
            localanalysisII(app);
            globalanalysis(app);
            btw_ch_reg(app); % --> localanalysisI_map(app) || localanalysisII_map(app) || globalanalysis_map(app);
            tab3enable(app);
            
        end

        % Value changed function: ListBox2
        function rgn_chg(app, event)
            value = app.ListBox2.Value;
            [~,n] = size(value);
            if n < 2
                msgbox('Please select least 2 region', 'Hint','help','modal');
            else
                app.UIAxes5.cla;
                brain_map(app);
                btw_ch_reg(app);
            end
        end

        % Selection change function: TabGroup3
        function filtertabs(app, event)
            selectedTab = app.TabGroup3.SelectedTab;
            if selectedTab == app.Tab7
                app.Panel5.Visible = 'on';
                app.Panel7.Visible = 'on';
                app.Panel6.Visible = 'off';
                app.Panel8.Visible = 'off';
            elseif selectedTab == app.Tab8
                app.Panel6.Visible = 'on';
                app.Panel8.Visible = 'on';
                app.Panel5.Visible = 'off';
                app.Panel7.Visible = 'off';
                app.NumericEditField11.Value = app.NumericEditField.Value;
                app.NumericEditField10.Value = app.NumericEditField2.Value;
                for ii = 1:app.NumericEditField12.Value
                    dropdown8(ii,:).name = ['Level ' num2str(ii)];
                end
                dropdown8(app.NumericEditField12.Value+1,:).name = 'Orj. vs Denoised';
                dropdown8(app.NumericEditField12.Value+2,:).name = 'Orj. vs Denoised (FFT)';
                dropdown8(app.NumericEditField12.Value+3,:).name = 'Residual';
                app.DropDown8.Items = {dropdown8(:).name};
                app.ListBox.Items = {dropdown8(1:end-3,:).name};
            end
            if app.btn_state ~= (-1) && ~isequal(app.declevchg,1)
                graph(app);
            end
        end

        % Value changed function: ListBox3
        function ch_chg(app, event)
            
            app.UIAxes5.cla;
            brain_map(app);
            btw_ch_reg(app);
            
        end

        % Value changed function: DropDown11
        function approach_chg(app, event)
            %             value = app.DropDown11.Value;
            if strcmp(app.DropDown11.Value,'Global Analysis')
                app.UIAxes5.cla;
                %                 brain_map(app);
                btw_ch_reg(app);
            else
                app.UIAxes5.cla;
                brain_map(app);
                btw_ch_reg(app);
            end
        end

        % Value changed function: CheckBox_2
        function graph_regions(app, event)
            %             value = app.CheckBox_2.Value;
            app.UIAxes5.cla;
            brain_map(app);
            btw_ch_reg(app);
        end

        % Value changed function: CheckBox_3
        function selectall_ch(app, event)
            value = app.CheckBox_3.Value;
            if value == 1
                app.ListBox3.Value = app.ListBox3.Items;
            else
                app.ListBox3.Value = {};
            end
            app.UIAxes5.cla;
            brain_map(app);
            btw_ch_reg(app);
        end

        % Value changed function: DropDown9
        function tab3grp(app, event)
            %             value = app.DropDown9.Value;
            [i,~] = size(app.dropdown2);
            for ii = 1:i
                if strcmp(app.DropDown9.Value,app.dropdown2(ii,1).name)
                    app.DropDown10.Items = {app.dropdown3(ii,:).name};
                end
            end
            app.UIAxes5.cla;
            if ~strcmp(app.DropDown11.Value,'Global Analysis')
                brain_map(app);
                
            end
            btw_ch_reg(app);
        end

        % Value changed function: DropDown10
        function tab3sbj(app, event)
            %             value = app.DropDown10.Value;
            app.UIAxes5.cla;
            if ~strcmp(app.DropDown11.Value,'Global Analysis')
                brain_map(app);
                
            end
            btw_ch_reg(app);
        end

        % Value changed function: NumericEditField7_2
        function upperfrq_chg_2(app, event)
            %             value = app.NumericEditField7_2.Value;
            app.NumericEditField7_2.Value = ceil(app.NumericEditField7_2.Value);
            %             value = app.NumericEditField7_2.Value;
            check_intervals4(app);
            if app.errorflag == 0
                %                 lowerfrq_chg_2(app);
                app.errorflag = 0;
                app.UIAxes5.cla;
                btw_ch_reg(app);
            end
            
        end

        % Value changed function: NumericEditField8_2
        function lowerfrq_chg_2(app, event)
            %             value = app.NumericEditField8_2.Value;
            app.NumericEditField8_2.Value = ceil(app.NumericEditField8_2.Value);
            %             value = app.NumericEditField8_2.Value;
            check_intervals4(app);
            if app.errorflag == 0
                %                 upperfrq_chg_2(app);
                app.errorflag = 0;
                app.UIAxes5.cla;
                btw_ch_reg(app);
            end
            
        end

        % Button pushed function: Button13_2
        function export(app, event)
            selpath = uigetdir;
            if selpath ~= 0
                app.prcs = 0;
                p = 0;
                app.btn_state = 6.1;
                app.process;
                grppath = app.grp_file_name;
                grppath_j = app.sbj_file_name;
                % You can also use dir to get the list of the subfolders
                [i,~] = size(grppath);
                [~,j] = size(grppath_j);
                for h = 3:i
                    mkdir(fullfile(selpath,grppath(h).name));
                    for k = 3:j
                        % create the directory
                        grppath_j(h-2,k).name = ['filt_',grppath_j(h-2,k).name];
                        SAVE_MAT.nkdata.eeg = app.denoised_sig(h-2,k-2).nkdata.eeg(:,:);
                        SAVE_MAT.nkdata.nchannels = app.mats_p(h-2,k-2).nkdata.nchannels;
                        SAVE_MAT.nkdata.sampHz = app.mats_p(h-2,k-2).nkdata.sampHz;
                        SAVE_MAT.nkdata.ch_names = app.mats_p(h-2,k-2).nkdata.ch_names;
                        save(fullfile(selpath,grppath(h).name,grppath_j(h-2,k).name),'-struct','SAVE_MAT');
                        %                     save(fullfile(selpath,grppath(h).name,grppath_j(h-2,k).name));
                        p =p+1;
                        app.prcs = p/((i-2)*(j-2));
                        app.process;
                    end
                end
                msgbox('Data export completed', 'Success','help','modal');
            end
        end

        % Button pushed function: Button15
        function nexttab2(app, event)
            app.TabGroup.SelectedTab = app.Tab4;
            sync_var_tab(app);
            tab4enable(app);
        end

        % Button pushed function: Button14
        function export1(app, event)
            selpath = uigetdir;
            if selpath ~= 0
                app.prcs = 0;
                p = 0;
                app.btn_state = 6.2;
                app.process;
                
                grppath = app.grp_file_name;
                grppath_j = app.sbj_file_name;
                % You can also use dir to get the list of the subfolders
                [ii,~] = size(grppath);
                [~,j] = size(grppath_j);
                approach = {'LocalAnalysisI.xls' 'LocalAnalysisII.xls' 'GlobalAnalysis.xls'};
                [~,i] = size(approach);
                %                         [~,i1] = size(approach);
                %             xlswrite(fullfile(selpath,'l.xls'),A);
                %             app.y_hI(ii,jj).idx(1,i1) = abs(syn_time_mean);
                for ap = 1:i
                    if ap == 1
                        GDATA = [];
                        GDATA = app.y_hI;
                    elseif ap == 2
                        GDATA = [];
                        GDATA = app.y_hII_mean;
                    else
                        GDATA = [];
                        GDATA =  (app.GFS_m(:,:))';
                    end
                    
                    if ap < i
                        for h = 1:i
                            %                 mkdir(fullfile(selpath,grppath(h).name));
                            A1 =  [];
                            A1 =  [string('Sbj. No') string('R1-R2') string('R1-R4') string('R1-R5') string('R2-R4') string('R2-R5') string('R4-R5')];
                            %                 A = [1 2 3 4 5 6 7];
                            [~,i1] = size(A1);
                            for k = 3:j
                                % create the directory
                                
                                A1 = [A1; (k-2) GDATA(h,k-2).idx(1,:)];
                                
                                p =p+1;
                                app.prcs = p/((i-1)*(i*(j-2)+(i1-1)*(j-2)*(ii-3))+(i-2)*(j-2));
                                app.process;
                                
                            end
                            xlswrite(fullfile(selpath,approach{ap}),cellstr(A1),h);
                            
                            e = actxserver('Excel.Application'); % # open Activex server
                            ewb = e.Workbooks.Open(fullfile(selpath,approach{ap})); % # open file (enter full path!)
                            ewb.Worksheets.Item(h).Name = grppath(h+2).name; % # rename 1st sheet
                            ewb.Save % # save to the same file
                            ewb.Close(false)
                            e.Quit
                        end
                        
                        
                        for h = 2:i1
                            A2 =  [];
                            A2 =  [string('Sbj. No') string(grppath(3).name) string(grppath(4).name) string(grppath(5).name)];
                            for k = 3:j
                                B = GDATA(1,k-2).idx(1,h-1);
                                for kk = 4:ii
                                    B = [B, GDATA(kk-2,k-2).idx(1,h-1)];
                                    
                                    p =p+1;
                                    app.prcs = p/((i-1)*(i*(j-2)+(i1-1)*(j-2)*(ii-3))+(i-2)*(j-2));
                                    app.process;
                                end
                                
                                A2 = [A2; (k-2) B];
                            end
                            xlswrite(fullfile(selpath,approach{ap}),cellstr(A2),h+2);
                            
                            e = actxserver('Excel.Application'); % # open Activex server
                            ewb = e.Workbooks.Open(fullfile(selpath,approach{ap})); % # open file (enter full path!)
                            ewb.Worksheets.Item(h+2).Name = A1{1,h}; % # rename 1st sheet
                            ewb.Save % # save to the same file
                            ewb.Close(false)
                            e.Quit
                        end
                    else
                        
                        A2 =  [];
                        A2 =  [string('Sbj. No') string(grppath(3).name) string(grppath(4).name) string(grppath(5).name)];
                        for k = 3:j
                            
                            A2 = [A2; (k-2) GDATA(k-2,:)];
                            
                            p =p+1;
                            app.prcs = p/((i-1)*(i*(j-2)+(i1-1)*(j-2)*(ii-3))+(i-2)*(j-2));
                            
                        end
                        xlswrite(fullfile(selpath,approach{ap}),cellstr(A2));
                        
                        e = actxserver('Excel.Application'); % # open Activex server
                        ewb = e.Workbooks.Open(fullfile(selpath,approach{ap})); % # open file (enter full path!)
                        ewb.Worksheets.Item(1).Name = 'GFS'; % # rename 1st sheet
                        ewb.Save % # save to the same file
                        ewb.Close(false)
                        e.Quit
                        
                    end
                end
                app.process;
                msgbox('Data export completed', 'Success','help','modal');
            end
        end

        % Button pushed function: Button14_2
        function analyze(app, event)
            
            if app.ANOVAOneWayCheckBox.Value == 1
                app.UITable5.Data =  [];
                app.UITable5_2.Data = [];
                app.UITable5_3.Data = [];
                app.TabGroup6.SelectedTab = app.ParametricTestResultsTab;
                alpha = app.SignificanceLevelEditField_2.Value;
                x = app.UITable.Data(:,2:end);
                groups = app.UITable.ColumnName(2:end);
                app.UITable5.ColumnFormat(:,1) = ({'char'});
                
                
                %                 app.UITable5_2.ColumnName = {A1n(1); A1n(2); A1n(3); A1n(4); A1n(5); A1n(6); A1n(7)};
                [~, tbl,stats_an] = anova1(x, groups, 'off' );
                H = (alpha >= tbl{2,6});
                
                [ii,jj] = size(tbl);
                for i = 2:ii
                    for j = 2:jj
                        if ~isempty(tbl{i,j})
                            app.UITable5.Data{i-1,j} = tbl{i,j};
                        end
                    end
                end
                app.UITable5.Data{1,1} = 'Between Groups';
                app.UITable5.Data{2,1} = 'Within Groups';
                app.UITable5.Data{3,1} = 'Total';
                
                %                 app.UITable5.ColumnName = {'-'; 'Sum of Squares'; 'df'; 'Mean Square'; 'F'; 'Sig.'};
                %                 app.UITable5.ColumnWidth = {95, 110, 75, 85, 75, 75};
                if H == 1
                    app.UITable5.BackgroundColor(1,:) = [1 0 0];
                    app.UITable5.BackgroundColor(2,:) = [1 1 1];
                    app.UITable5.BackgroundColor(3,:) = [1 1 1];
                else
                    app.UITable5.BackgroundColor(1,:) = [0 1 0];
                    app.UITable5.BackgroundColor(2,:) = [1 1 1];
                    app.UITable5.BackgroundColor(3,:) = [1 1 1];
                end
                % data=[1+2.*randn(9,1);1+randn(14,1);-1+randn(11,1);randn(7,1)];
                % group=[ones(9,1);2.*ones(14,1);3.*ones(11,1);4.*ones(7,1)];
                % [h,p,stats]=games_howell(data, group);
                % multiple comp.
                if app.TukeyCheckBox.Value == 1
                    app.MultipleComparasionsTukeyPanel.Visible = 'on';
                    app.MultipleComparasionsGamesHowellPanel.Visible = 'off';
                    [c, m, ~, nms] = multcompare(stats_an, 'Display', 'off');
                    [ii, ~] = size(c);
                    [gn,~] = size(nms);
                    kk = 0;
                    be = 1;
                    bi = 0;
                    %                     ki = 1;
                    bb = ii+gn-1;
                    for b = 1:bb
                        if be == 0
                            ki = kk;
                            for k = 1:kk
                                cnew(bi+1,:) = cnew(ki,:);
                                cr = cnew(bi+1,1);
                                cs = cnew(bi+1,2);
                                cnew(bi+1,1) = cs;
                                cnew(bi+1,2) = cr;
                                ki = k*(gn-1)+kk;
                                bi = bi+1;
                            end
                        else
                            cnew(bi+1,:) = c(b-kk,:);
                            bi = bi+1;
                        end
                        be = mod(bi,gn-1);
                        if be == 0
                            kk = kk+1;
                            %         kc = kc+2;
                            %         kcc = kc;
                        end
                    end
                    app.UITable5_3.ColumnFormat(:,1) = ({'char'});
                    app.UITable5_3.ColumnFormat(:,2) = ({'char'});
                    [ii, ~] = size(cnew);
                    for i = 1:ii
                        app.UITable5_3.Data{i,1} = nms{cnew(i,1),1};
                        app.UITable5_3.Data{i,2} = nms{cnew(i,2),1};
                        app.UITable5_3.Data{i,3} = m(cnew(i,1),1)- m(cnew(i,2),1);
                        app.UITable5_3.Data{i,4} = m(cnew(i,1),2)+ m(cnew(i,2),2);
                        app.UITable5_3.Data{i,5} = cnew(i,6);
                        H = (alpha >= cnew(i,6));
                        if H == 1
                            app.UITable5_3.BackgroundColor(i,:) = [1 0 0];
                        else
                            app.UITable5_3.BackgroundColor(i,:) = [0 1 0];
                        end
                    end
                end
                
                if app.GamesHowellCheckBox.Value == 1
                    app.MultipleComparasionsTukeyPanel.Visible = 'off';
                    app.MultipleComparasionsGamesHowellPanel.Visible = 'on';
                    [ii,jj] = size(x);
                    data = [];
                    group = [];
                    for j = 1:jj
                        data = [data; x(:,j)];
                        for i = 1:ii
                            group = [group; app.UITable.ColumnName(j+1)];
                        end
                    end
                    [h,p,stats] = games_howell(app, data, group, alpha);
                    %                 stats=[];
                    app.UITable5_2.ColumnFormat(:,1) = ({'char'});
                    app.UITable5_2.ColumnFormat(:,2) = ({'char'});
                    %                 [gsiz, ~] = size(stats.gnames);
                    [ii, jj] = size(stats.md);
                    %                 kk = nchoosek(gsiz,2)*2;
                    k = 1;
                    
                    for i = 1:ii
                        for j = 1:jj
                            
                            if isnan(stats.md(i,j))
                                app.UITable5_2.Data{k-j+1,1} = stats.gnames{j,1};
                            else
                                if h(i,j) == 1
                                    app.UITable5_2.BackgroundColor(k,:) = [1 0 0];
                                else
                                    app.UITable5_2.BackgroundColor(k,:) = [0 1 0];
                                end
                                
                                app.UITable5_2.Data{k,2} = stats.gnames{j,1};
                                app.UITable5_2.Data{k,3} = stats.md(i,j);
                                app.UITable5_2.Data{k,4} = stats.se(i,j);
                                app.UITable5_2.Data{k,5} = p(i,j);
                                k =k+1;
                            end
                        end
                    end
                    
                    [ii, ~] = size(app.UITable5_2.Data);
                    for i = 1:ii
                        if isempty(app.UITable5_2.Data{i,1})
                            app.UITable5_2.Data{i,1} = app.UITable5_2.Data{i-1,1};
                        end
                    end
                    %                 app.UITable5_2.ColumnName = {'I'; 'J'; 'Mean Difference (I-J)'; 'Std. Error'; 'Sig.'};
                    %                 app.UITable5_2.ColumnWidth = {100, 100, 140, 80, 75};
                    %  - gnames, the labels for the different groups
                    %  - md, the differences between pairs of groups
                    %  - q, the test statistic for each pairwise comparison
                    %  - df, the degrees of freedom for each pairwise comparison
                end
            elseif app.KruskalWallisCheckBox.Value == 1
                alpha = app.SignificanceLevelEditField_3.Value;
                app.UITable5_4.Data =  [];
                app.UITable5_5.Data = [];
                app.WilcoxonRankSumPanel.Visible = 'off';
                app.KruskalWallisPanel.Visible = 'on';
                app.MultipleComparasionsDunnBonferroniApproachPanel.Visible = 'on';
                app.TabGroup6.SelectedTab = app.NonparametricTestResultsTab;
                
                x = app.UITable.Data(:,2:end);
                group = app.UITable.ColumnName(2:end);
                [~,tbl_non,stats] = kruskalwallis(x,group,'off');
                H = (alpha >= tbl_non{2,6});
                
                [ii,jj] = size(tbl_non);
                for i = 2:ii
                    for j = 2:jj
                        if ~isempty(tbl_non{i,j})
                            app.UITable5_4.Data{i-1,j} = tbl_non{i,j};
                        end
                    end
                end
                app.UITable5_4.Data{1,1} = 'Between Groups';
                app.UITable5_4.Data{2,1} = 'Within Groups';
                app.UITable5_4.Data{3,1} = 'Total';
                
                %                 app.UITable5.ColumnName = {'-'; 'Sum of Squares'; 'df'; 'Mean Square'; 'F'; 'Sig.'};
                %                 app.UITable5.ColumnWidth = {95, 110, 75, 85, 75, 75};
                if H == 1
                    app.UITable5_4.BackgroundColor(1,:) = [1 0 0];
                    app.UITable5_4.BackgroundColor(2,:) = [1 1 1];
                    app.UITable5_4.BackgroundColor(3,:) = [1 1 1];
                else
                    app.UITable5_4.BackgroundColor(1,:) = [0 1 0];
                    app.UITable5_4.BackgroundColor(2,:) = [1 1 1];
                    app.UITable5_4.BackgroundColor(3,:) = [1 1 1];
                end
                
                [c, m, ~, nms] = multcompare(stats, 'Display', 'off','CType','bonferroni'); %,'CType','bonferroni' 'dunn-sidak'
                %                 [c1, m1, ~, nms1] = multcompare(stats, 'Display', 'off','CType','dunn-sidak');
                %                 [c2, m2, ~, nms1] = multcompare(stats, 'Display', 'off');
                
                [trow,tot] = size(x);
                xt = [];
                for i = 1:tot
                    xt = [xt (x(:,i))'];
                end
                [~,t] = tiedrank(xt); %ranks and ties
                F = (tot*trow*(tot*trow+1)/12)-(t/(6*(tot*trow-1))); %costant denominator factor with ties correction
                N = [];
                for i = 1:tot
                    N = [N trow];
                end
                [ii, ~] = size(c);
                [gn,~] = size(nms);
                kk = 0;
                be = 1;
                bi = 0;
                
                %                     ki = 1;
                bb = ii+gn-1;
                for b = 1:bb
                    if be == 0
                        ki = kk;
                        for k = 1:kk
                            cnew(bi+1,:) = cnew(ki,:);
                            cr = cnew(bi+1,1);
                            cs = cnew(bi+1,2);
                            cnew(bi+1,1) = cs;
                            cnew(bi+1,2) = cr;
                            ki = k*(gn-1)+kk;
                            bi = bi+1;
                        end
                    else
                        cnew(bi+1,:) = c(b-kk,:);
                        bi = bi+1;
                    end
                    be = mod(bi,gn-1);
                    if be == 0
                        kk = kk+1;
                        %         kc = kc+2;
                        %         kcc = kc;
                    end
                end
                app.UITable5_5.ColumnFormat(:,1) = ({'char'});
                app.UITable5_5.ColumnFormat(:,2) = ({'char'});
                [ii, ~] = size(cnew);
                for i = 1:ii
                    app.UITable5_5.Data{i,1} = nms{cnew(i,1),1};
                    app.UITable5_5.Data{i,2} = nms{cnew(i,2),1};
                    app.UITable5_5.Data{i,3} = m(cnew(i,1),1)- m(cnew(i,2),1);
                    denom = sqrt(F*sum(1./N([cnew(i,1) cnew(i,2)]))); %Complete Denominator with ties correction
                    app.UITable5_5.Data{i,4} = denom;
                    app.UITable5_5.Data{i,5} = app.UITable5_5.Data{i,3}/app.UITable5_5.Data{i,4};
                    app.UITable5_5.Data{i,6} = cnew(i,6)/tot;
                    app.UITable5_5.Data{i,7} = cnew(i,6);
                    H = (alpha >= cnew(i,6));
                    if H == 1
                        app.UITable5_5.BackgroundColor(i,:) = [1 0 0];
                    else
                        app.UITable5_5.BackgroundColor(i,:) = [0 1 0];
                    end
                end
            elseif app.WilcoxonRankSumCheckBox.Value == 1
                alpha = app.SignificanceLevelEditField_3.Value;
                app.WilcoxonRankSumPanel.Visible = 'on';
                app.KruskalWallisPanel.Visible = 'off';
                app.MultipleComparasionsDunnBonferroniApproachPanel.Visible = 'off';
                app.TabGroup6.SelectedTab = app.NonparametricTestResultsTab;
                app.UITable6.Data = [];
                x = app.UITable.Data(:,2:end);
                group = app.UITable.ColumnName(2:end);
                [~, jj] = size(x);
                ii = nchoosek(jj,2);
                k1 = 1;
                k2 = 2;
                comp_idx = 1:jj;
                for i = 1:ii
                    [p, h, stats] = ranksum(x(:,k1), x(:,k2),'alpha' ,alpha);
                    
                    app.UITable6.Data{i,1} = group{comp_idx(k1)};
                    app.UITable6.Data{i,2} = group{comp_idx(k2)};
                    app.UITable6.Data{i,3} = stats.ranksum;
                    app.UITable6.Data{i,4} = stats.zval;
                    app.UITable6.Data{i,5} = p;
                    if h == 1
                        app.UITable6.BackgroundColor(i,:) = [1 0 0];
                    else
                        app.UITable6.BackgroundColor(i,:) = [0 1 0];
                    end
                    if k2 == jj
                        k2 = 2;
                        comp_idx(1) = [];
                        [~,jj] = size(comp_idx);
                    else
                        k2 = k2+1;
                    end
                end
                
                
            end
        end

        % Value changed function: DropDown9_2
        function approach_chg2(app, event)
            %             value = app.DropDown9_2.Value;
            sync_var_tab(app);
        end

        % Value changed function: DropDown10_2
        function sycn_res_chg(app, event)
            %             value = app.DropDown10_2.Value;
            sync_var_tab(app);
        end

        % Button pushed function: Button15_2
        function explore(app, event)
            app.TabGroup6.SelectedTab = app.TestofNormalityTab;
            alpha = app.SignificanceLevelEditField.Value;
            [df,ii] = size(app.UITable.Data);
            
            %Test of Normality
            app.UITable2.Data =  [];
            app.UITable2_2.Data = [];
            app.UITable3.Data = [];
            app.UITable2.ColumnFormat(:,1) = ({'char'});
            app.UITable2.ColumnFormat(:,2) = ({'numeric'});
            app.UITable2.ColumnFormat(:,3) = ({'numeric'});
            app.UITable2.ColumnFormat(:,4) = ({'numeric'});
            
            for i = 2:ii
                x = app.UITable.Data(:,i);
                [H, pValue, W, skw, krt] = swtest(app, x, alpha);
                [h,p,ksstat,~] = lillietest(x,'Alpha',alpha);
                if df < 30
                    if H == 1
                        switch app.trns
                            
                            case 1
                                x = log10(x);
                                [H, pValue, W, skw, krt] = swtest(app, x, alpha);
                            case 2
                                x = sqrt(x);
                                [H, pValue, W, skw, krt] = swtest(app, x, alpha);
                            case 3
                                x = x.^2;
                                [H, pValue, W, skw, krt] = swtest(app, x, alpha);
                            case 4
                                x = 1./x;
                                [H, pValue, W, skw, krt] = swtest(app, x, alpha);
                        end
                        if H == 1
                            app.UITable2_2.BackgroundColor(i-1,:) = [1 0 0];
                        else
                            app.UITable2_2.BackgroundColor(i-1,:) = [0 1 0];
                        end
                    else
                        app.UITable2_2.BackgroundColor(i-1,:) = [0 1 0];
                    end
                    
                else
                    if h == 1
                        switch app.trns
                            
                            case 1
                                x = log10(x);
                                [h,p,ksstat,~] = lillietest(x,'Alpha',alpha);
                            case 2
                                x = sqrt(x);
                                [h,p,ksstat,~] = lillietest(x,'Alpha',alpha);
                            case 3
                                x = x.^2;
                                [h,p,ksstat,~] = lillietest(x,'Alpha',alpha);
                            case 4
                                x = 1./x;
                                [h,p,ksstat,~] = lillietest(x,'Alpha',alpha);
                        end
                        if h == 1
                            app.UITable2.BackgroundColor(i-1,:) =  [1 0 0];
                        else
                            app.UITable2.BackgroundColor(i-1,:) =  [0 1 0];
                        end
                    else
                        app.UITable2.BackgroundColor(i-1,:) =  [0 1 0];
                    end
                end
                app.UITable2.Data{i-1,1} =  app.UITable.ColumnName{i};
                app.UITable2.Data{i-1,2} =  ksstat;
                app.UITable2.Data{i-1,3} =  df;
                app.UITable2.Data{i-1,4} =  p;
                app.UITable2_2.Data(i-1,1) = W;
                app.UITable2_2.Data(i-1,2) = df;
                app.UITable2_2.Data(i-1,3) = pValue;
                app.UITable3.Data(i-1,1) = skw;
                app.UITable3.Data(i-1,2) = krt;
            end
            
            %Test of Homogeneity of Variances
            X = [];
            X = [0 0];
            for i = 2:ii
                x = app.UITable.Data(:,i);
                [idx,~] = size(x);
                
                for i1 = 1:idx
                    X = [X; x(i1) i-1];
                end
            end
            X(1,:) = [];
            [H, F,df1,df2,P] = levenetest(app, X, alpha);
            app.UITable4.Data(1,1) = F;
            app.UITable4.Data(1,2) = df1;
            app.UITable4.Data(1,3) = df2;
            app.UITable4.Data(1,4) = P;
            if H == 1
                app.UITable4.BackgroundColor(1,:) =  [1 0 0];
            else
                app.UITable4.BackgroundColor(1,:) =  [0 1 0];
            end
            
            if app.trns_btn == 1
                app.UITable.Data(:,i) = x;
            end
            app.trns_btn = 0;
            %             app.trns = 0;
        end

        % Value changed function: log10xCheckBox
        function trns1(app, event)
            value = app.log10xCheckBox.Value;
            if value == 1
                app.sqrtxCheckBox.Value = 0;
                app.powx2CheckBox.Value = 0;
                app.xCheckBox.Value = 0;
                set(app.Button7_3,'Enable','on');
                app.trns = 1;
            else
                set(app.Button7_3,'Enable','off');
                app.trns = 0;
            end
        end

        % Value changed function: sqrtxCheckBox
        function trns2(app, event)
            value = app.sqrtxCheckBox.Value;
            if value == 1
                app.log10xCheckBox.Value = 0;
                app.powx2CheckBox.Value = 0;
                app.xCheckBox.Value = 0;
                set(app.Button7_3,'Enable','on');
                app.trns = 2;
            else
                set(app.Button7_3,'Enable','off');
                app.trns = 0;
            end
        end

        % Value changed function: powx2CheckBox
        function trns3(app, event)
            value = app.powx2CheckBox.Value;
            if value == 1
                app.sqrtxCheckBox.Value = 0;
                app.log10xCheckBox.Value = 0;
                app.xCheckBox.Value = 0;
                set(app.Button7_3,'Enable','on');
                app.trns = 3;
            else
                set(app.Button7_3,'Enable','off');
                app.trns = 0;
            end
        end

        % Value changed function: xCheckBox
        function trns4(app, event)
            value = app.xCheckBox.Value;
            if value == 1
                app.sqrtxCheckBox.Value = 0;
                app.powx2CheckBox.Value = 0;
                app.log10xCheckBox.Value = 0;
                set(app.Button7_3,'Enable','on');
                app.trns = 4;
            else
                set(app.Button7_3,'Enable','off');
                app.trns = 0;
            end
        end

        % Button pushed function: Button7_3
        function transform(app, event)
            app.trns_btn = 1;
            explore(app);
        end

        % Button pushed function: Button7_2
        function undo1(app, event)
            app.trns_btn = 0;
            sync_var_tab(app);
        end

        % Value changed function: ANOVAOneWayCheckBox
        function test_anova(app, event)
            value = app.ANOVAOneWayCheckBox.Value;
            if value == 1
                app.KruskalWallisCheckBox.Value = 0;
                app.WilcoxonRankSumCheckBox.Value = 0;
                set(app.TukeyCheckBox,'Enable','on');
                set(app.GamesHowellCheckBox,'Enable','on');
            else
                set(app.TukeyCheckBox,'Enable','off');
                set(app.GamesHowellCheckBox,'Enable','off');
            end
        end

        % Value changed function: KruskalWallisCheckBox
        function test_K_W(app, event)
            value = app.KruskalWallisCheckBox.Value;
            if value == 1
                app.ANOVAOneWayCheckBox.Value = 0;
                app.WilcoxonRankSumCheckBox.Value = 0;
                set(app.TukeyCheckBox,'Enable','off');
                set(app.GamesHowellCheckBox,'Enable','off');
            end
        end

        % Value changed function: WilcoxonRankSumCheckBox
        function test_RankSum(app, event)
            value = app.WilcoxonRankSumCheckBox.Value;
            if value == 1
                app.ANOVAOneWayCheckBox.Value = 0;
                app.KruskalWallisCheckBox.Value = 0;
                set(app.TukeyCheckBox,'Enable','off');
                set(app.GamesHowellCheckBox,'Enable','off');
            end
        end

        % Value changed function: TukeyCheckBox
        function test_Tukey(app, event)
            value = app.TukeyCheckBox.Value;
            if value == 1
                app.GamesHowellCheckBox.Value = 0;
            end
        end

        % Value changed function: GamesHowellCheckBox
        function test_G_H(app, event)
            value = app.GamesHowellCheckBox.Value;
            if value == 1
                app.TukeyCheckBox.Value = 0;
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.EEGSProc = uifigure;
            app.EEGSProc.Name = 'EEG S-Proc';
            setAutoResize(app, app.EEGSProc, true)
            app.TabGroup = uitabgroup(app.EEGSProc);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Home';
            app.Tab.Units = 'pixels';
            app.Panel = uipanel(app.Tab);
            app.Panel.Title = 'Tools';
            app.Button = uibutton(app.Panel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @browse, true);
            app.Button.Icon = 'browse_folder_icon.png';
            app.Button.Text = '';
            app.Label3 = uilabel(app.Panel);
            app.Label3.Text = 'Browse';
            app.Button2 = uibutton(app.Panel, 'push');
            app.Button2.ButtonPushedFcn = createCallbackFcn(app, @load, true);
            app.Button2.Icon = 'Load_icon.png';
            app.Button2.Text = '';
            app.Label4 = uilabel(app.Panel);
            app.Label4.Text = 'Load';
            app.Button3 = uibutton(app.Panel, 'push');
            app.Button3.ButtonPushedFcn = createCallbackFcn(app, @clear, true);
            app.Button3.Icon = 'clear_icon.png';
            app.Button3.Text = '';
            app.Label5 = uilabel(app.Panel);
            app.Label5.Text = 'Clear';
            app.Button4 = uibutton(app.Panel, 'push');
            app.Button4.ButtonPushedFcn = createCallbackFcn(app, @editepoch, true);
            app.Button4.Icon = 'Start-icon.png';
            app.Button4.Text = '';
            app.Label6 = uilabel(app.Panel);
            app.Label6.Text = 'Run';
            app.Button5 = uibutton(app.Panel, 'push');
            app.Button5.ButtonPushedFcn = createCallbackFcn(app, @nexttab0, true);
            app.Button5.Icon = 'next_tab.png';
            app.Button5.Text = '';
            app.Label7 = uilabel(app.Panel);
            app.Label7.Text = 'Next';
            app.Label8 = uilabel(app.Panel);
            app.Label8.Text = 'Help';
            app.Button6 = uibutton(app.Panel, 'push');
            app.Button6.Enable = 'off';
            app.Button6.Icon = 'sign-question-icon.png';
            app.Button6.Text = '';
            app.Button7 = uibutton(app.Panel, 'push');
            app.Button7.ButtonPushedFcn = createCallbackFcn(app, @undo, true);
            app.Button7.Icon = 'undo_icon.png';
            app.Button7.Text = '';
            app.Label12 = uilabel(app.Panel);
            app.Label12.Text = 'Undo';
            app.Panel2 = uipanel(app.Tab);
            app.Panel2.Title = 'Parameters';
            app.LabelDropDown2 = uilabel(app.Panel2);
            app.LabelDropDown2.Text = 'Group:';
            app.DropDown2 = uidropdown(app.Panel2);
            app.DropDown2.ValueChangedFcn = createCallbackFcn(app, @editsubj, true);
            app.LabelDropDown3 = uilabel(app.Panel2);
            app.LabelDropDown3.Text = 'Subject:';
            app.DropDown3 = uidropdown(app.Panel2);
            app.DropDown3.ValueChangedFcn = createCallbackFcn(app, @graphsubj, true);
            app.LabelDropDown4 = uilabel(app.Panel2);
            app.LabelDropDown4.Text = 'Channel:';
            app.DropDown4 = uidropdown(app.Panel2);
            app.DropDown4.ValueChangedFcn = createCallbackFcn(app, @graphch, true);
            app.Label9 = uilabel(app.Panel2);
            app.Label9.Text = '.sec';
            app.Label2 = uilabel(app.Panel2);
            app.Label2.Text = 'Recording Len.:';
            app.NumericEditField3 = uieditfield(app.Panel2, 'numeric');
            app.NumericEditField3.ValueDisplayFormat = '%.0f';
            app.NumericEditField3.Editable = 'off';
            app.Label10 = uilabel(app.Panel2);
            app.Label10.Text = '.sec';
            app.LabelNumericEditField = uilabel(app.Panel2);
            app.LabelNumericEditField.Text = 'Start Time:';
            app.NumericEditField = uieditfield(app.Panel2, 'numeric');
            app.NumericEditField.ValueChangedFcn = createCallbackFcn(app, @stchang, true);
            app.NumericEditField.Limits = [0 Inf];
            app.NumericEditField.ValueDisplayFormat = '%.0f';
            app.Label11 = uilabel(app.Panel2);
            app.Label11.Text = '.sec';
            app.Label = uilabel(app.Panel2);
            app.Label.Text = 'End Time:';
            app.NumericEditField2 = uieditfield(app.Panel2, 'numeric');
            app.NumericEditField2.ValueChangedFcn = createCallbackFcn(app, @etchang, true);
            app.NumericEditField2.Limits = [1 Inf];
            app.NumericEditField2.ValueDisplayFormat = '%.0f';
            app.NumericEditField2.Value = 180;
            app.UIAxes = uiaxes(app.Tab);
            title(app.UIAxes, 'Title');
            xlabel(app.UIAxes, 'X');
            ylabel(app.UIAxes, 'Y');
            app.Panel3 = uipanel(app.Tab);
            app.Panel3.Title = 'Data Set Information';
            app.TextArea = uitextarea(app.Panel3);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {' '; ''};
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Filter';
            app.Tab2.Units = 'pixels';
            app.Panel4 = uipanel(app.Tab2);
            app.Panel4.Title = 'Tools';
            app.Button11 = uibutton(app.Panel4, 'push');
            app.Button11.ButtonPushedFcn = createCallbackFcn(app, @filtersignals, true);
            app.Button11.Icon = 'Start-icon.png';
            app.Button11.Text = '';
            app.Label16 = uilabel(app.Panel4);
            app.Label16.Text = 'Run';
            app.Button12 = uibutton(app.Panel4, 'push');
            app.Button12.ButtonPushedFcn = createCallbackFcn(app, @nexttab1, true);
            app.Button12.Icon = 'next_tab.png';
            app.Button12.Text = '';
            app.Label17 = uilabel(app.Panel4);
            app.Label17.Text = 'Next';
            app.Label18 = uilabel(app.Panel4);
            app.Label18.Text = 'Help';
            app.Button13 = uibutton(app.Panel4, 'push');
            app.Button13.Enable = 'off';
            app.Button13.Icon = 'sign-question-icon.png';
            app.Button13.Text = '';
            app.ExportLabel = uilabel(app.Panel4);
            app.ExportLabel.Text = 'Export';
            app.Button13_2 = uibutton(app.Panel4, 'push');
            app.Button13_2.ButtonPushedFcn = createCallbackFcn(app, @export, true);
            app.Button13_2.Icon = 'export_icon.png';
            app.Button13_2.Text = '';
            app.TabGroup2 = uitabgroup(app.Tab2);
            app.Tab5 = uitab(app.TabGroup2);
            app.Tab5.Title = 'Data Set';
            app.Tab5.Units = 'pixels';
            app.Label20 = uilabel(app.Tab5);
            app.Label20.Text = 'Group:';
            app.DropDown5 = uidropdown(app.Tab5);
            app.DropDown5.ValueChangedFcn = createCallbackFcn(app, @tab2grp, true);
            app.Label21 = uilabel(app.Tab5);
            app.Label21.Text = 'Subject:';
            app.DropDown6 = uidropdown(app.Tab5);
            app.DropDown6.ValueChangedFcn = createCallbackFcn(app, @tab2sbj, true);
            app.Label22 = uilabel(app.Tab5);
            app.Label22.Text = 'Channel:';
            app.DropDown7 = uidropdown(app.Tab5);
            app.DropDown7.ValueChangedFcn = createCallbackFcn(app, @tab2ch, true);
            app.Tab6 = uitab(app.TabGroup2);
            app.Tab6.Title = 'Intervals';
            app.Tab6.Units = 'pixels';
            app.Panel8 = uipanel(app.Tab6);
            app.Label31 = uilabel(app.Panel8);
            app.Label31.Text = 'Graph Between';
            app.Label32 = uilabel(app.Panel8);
            app.Label32.Text = 'sec.';
            app.Label33 = uilabel(app.Panel8);
            app.Label33.Text = 'Upper Limit:';
            app.NumericEditField10 = uieditfield(app.Panel8, 'numeric');
            app.NumericEditField10.ValueChangedFcn = createCallbackFcn(app, @uppertime_chg, true);
            app.NumericEditField10.Limits = [1 Inf];
            app.NumericEditField10.Value = 1;
            app.Label34 = uilabel(app.Panel8);
            app.Label34.Text = 'sec.';
            app.Label35 = uilabel(app.Panel8);
            app.Label35.Text = 'Lower Limit:';
            app.NumericEditField11 = uieditfield(app.Panel8, 'numeric');
            app.NumericEditField11.ValueChangedFcn = createCallbackFcn(app, @lowertime_chg, true);
            app.NumericEditField11.Limits = [0 Inf];
            app.Panel7 = uipanel(app.Tab6);
            app.Label27 = uilabel(app.Panel7);
            app.Label27.Text = 'Graph Between';
            app.Label28 = uilabel(app.Panel7);
            app.Label28.Text = 'Hz';
            app.LabelNumericEditField7 = uilabel(app.Panel7);
            app.LabelNumericEditField7.Text = 'Upper Limit:';
            app.NumericEditField7 = uieditfield(app.Panel7, 'numeric');
            app.NumericEditField7.ValueChangedFcn = createCallbackFcn(app, @upperfreq_chg, true);
            app.NumericEditField7.Value = 1;
            app.Label29 = uilabel(app.Panel7);
            app.Label29.Text = 'Hz';
            app.Label26 = uilabel(app.Panel7);
            app.Label26.Text = 'Lower Limit:';
            app.NumericEditField8 = uieditfield(app.Panel7, 'numeric');
            app.NumericEditField8.ValueChangedFcn = createCallbackFcn(app, @lowerfreq_chg, true);
            app.TabGroup3 = uitabgroup(app.Tab2);
            app.TabGroup3.SelectionChangedFcn = createCallbackFcn(app, @filtertabs, true);
            app.Tab7 = uitab(app.TabGroup3);
            app.Tab7.Title = 'Butterworth Band Pass';
            app.Tab7.Units = 'pixels';
            app.Panel_7 = uipanel(app.Tab7);
            app.Label23 = uilabel(app.Panel_7);
            app.Label23.Text = 'Hz';
            app.LabelNumericEditField4 = uilabel(app.Panel_7);
            app.LabelNumericEditField4.Text = 'Cut On Freq.:';
            app.NumericEditField4 = uieditfield(app.Panel_7, 'numeric');
            app.NumericEditField4.ValueChangedFcn = createCallbackFcn(app, @cuton_frq_chg, true);
            app.NumericEditField4.Value = 0.5;
            app.Label24 = uilabel(app.Panel_7);
            app.Label24.Text = 'Hz';
            app.LabelNumericEditField5 = uilabel(app.Panel_7);
            app.LabelNumericEditField5.Text = 'Cut Off Freq.:';
            app.NumericEditField5 = uieditfield(app.Panel_7, 'numeric');
            app.NumericEditField5.ValueChangedFcn = createCallbackFcn(app, @cutoff_frq_chg, true);
            app.NumericEditField5.Value = 45;
            app.Label25 = uilabel(app.Panel_7);
            app.Label25.Text = 'Filter Order:';
            app.NumericEditField6 = uieditfield(app.Panel_7, 'numeric');
            app.NumericEditField6.ValueChangedFcn = createCallbackFcn(app, @bp_order, true);
            app.NumericEditField6.Value = 3;
            app.Panel_8 = uipanel(app.Tab7);
            app.CheckBox2 = uicheckbox(app.Panel_8);
            app.CheckBox2.ValueChangedFcn = createCallbackFcn(app, @shw_bpf, true);
            app.CheckBox2.Text = 'Graph Filter';
            app.CheckBox2.Value = true;
            app.CheckBox3 = uicheckbox(app.Panel_8);
            app.CheckBox3.ValueChangedFcn = createCallbackFcn(app, @bp_filt_view, true);
            app.CheckBox3.Text = '|Amplitude| (dB)';
            app.CheckBox3.Value = true;
            app.CheckBox4 = uicheckbox(app.Panel_8);
            app.CheckBox4.ValueChangedFcn = createCallbackFcn(app, @bp_filt_view1, true);
            app.CheckBox4.Text = '|Amplitude| (Normalized)';
            app.Panel_9 = uipanel(app.Tab7);
            app.CheckBox = uicheckbox(app.Panel_9);
            app.CheckBox.Text = 'Apply';
            app.CheckBox.Value = true;
            app.Tab8 = uitab(app.TabGroup3);
            app.Tab8.Title = 'Stationary Wavelet';
            app.Tab8.Units = 'pixels';
            app.Panel_10 = uipanel(app.Tab8);
            app.LabelNumericEditField12 = uilabel(app.Panel_10);
            app.LabelNumericEditField12.Text = 'Decomposition Level:';
            app.NumericEditField12 = uieditfield(app.Panel_10, 'numeric');
            app.NumericEditField12.ValueChangedFcn = createCallbackFcn(app, @declev, true);
            app.NumericEditField12.Limits = [1 Inf];
            app.NumericEditField12.Value = 5;
            app.LabelEditField = uilabel(app.Panel_10);
            app.LabelEditField.Text = 'Mother Wavelet:';
            app.EditField = uieditfield(app.Panel_10, 'text');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @motwave, true);
            app.EditField.Value = 'db3';
            app.LabelDropDown8 = uilabel(app.Panel_10);
            app.LabelDropDown8.Text = 'Show:';
            app.DropDown8 = uidropdown(app.Panel_10);
            app.DropDown8.ValueChangedFcn = createCallbackFcn(app, @graphswt_param, true);
            app.Panel_11 = uipanel(app.Tab8);
            app.CheckBox7 = uicheckbox(app.Panel_11);
            app.CheckBox7.ValueChangedFcn = createCallbackFcn(app, @thr_mets, true);
            app.CheckBox7.Text = 'Soft Treshold';
            app.CheckBox7.Value = true;
            app.CheckBox8 = uicheckbox(app.Panel_11);
            app.CheckBox8.ValueChangedFcn = createCallbackFcn(app, @thr_meth, true);
            app.CheckBox8.Text = 'Hard Treshold';
            app.CheckBox6 = uicheckbox(app.Panel_11);
            app.CheckBox6.Text = 'Apply';
            app.CheckBox6.Value = true;
            app.Panel_12 = uipanel(app.Tab8);
            app.LabelListBox = uilabel(app.Panel_12);
            app.LabelListBox.Text = 'Remove Details';
            app.ListBox = uilistbox(app.Panel_12);
            app.ListBox.Multiselect = 'on';
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @removedetails, true);
            app.ListBox.Value = {'Item 1'};
            app.Panel5 = uipanel(app.Tab2);
            app.UIAxes2 = uiaxes(app.Panel5);
            title(app.UIAxes2, 'Title');
            xlabel(app.UIAxes2, 'X');
            ylabel(app.UIAxes2, 'Y');
            app.Panel6 = uipanel(app.Tab2);
            app.UIAxes3 = uiaxes(app.Panel6);
            title(app.UIAxes3, 'Title');
            xlabel(app.UIAxes3, 'X');
            ylabel(app.UIAxes3, 'Y');
            app.UIAxes4 = uiaxes(app.Panel6);
            title(app.UIAxes4, 'Title');
            xlabel(app.UIAxes4, 'X');
            ylabel(app.UIAxes4, 'Y');
            app.Tab3 = uitab(app.TabGroup);
            app.Tab3.Title = 'Sync. Analysis';
            app.Tab3.Units = 'pixels';
            app.Panel9 = uipanel(app.Tab3);
            app.Panel9.Title = 'Tools';
            app.Button14 = uibutton(app.Panel9, 'push');
            app.Button14.ButtonPushedFcn = createCallbackFcn(app, @export1, true);
            app.Button14.Icon = 'export_icon.png';
            app.Button14.Text = '';
            app.ExportLabel_2 = uilabel(app.Panel9);
            app.ExportLabel_2.Text = 'Export';
            app.Button15 = uibutton(app.Panel9, 'push');
            app.Button15.ButtonPushedFcn = createCallbackFcn(app, @nexttab2, true);
            app.Button15.Icon = 'next_tab.png';
            app.Button15.Text = '';
            app.Label37 = uilabel(app.Panel9);
            app.Label37.Text = 'Next';
            app.Label38 = uilabel(app.Panel9);
            app.Label38.Text = 'Help';
            app.Button16 = uibutton(app.Panel9, 'push');
            app.Button16.Enable = 'off';
            app.Button16.Icon = 'sign-question-icon.png';
            app.Button16.Text = '';
            app.TabGroup4 = uitabgroup(app.Tab3);
            app.Tab9 = uitab(app.TabGroup4);
            app.Tab9.Title = 'Data Set';
            app.Tab9.Units = 'pixels';
            app.Label39 = uilabel(app.Tab9);
            app.Label39.Text = 'Group:';
            app.DropDown9 = uidropdown(app.Tab9);
            app.DropDown9.ValueChangedFcn = createCallbackFcn(app, @tab3grp, true);
            app.Label40 = uilabel(app.Tab9);
            app.Label40.Text = 'Subject:';
            app.DropDown10 = uidropdown(app.Tab9);
            app.DropDown10.ValueChangedFcn = createCallbackFcn(app, @tab3sbj, true);
            app.TabGroup5 = uitabgroup(app.Tab3);
            app.Tab11 = uitab(app.TabGroup5);
            app.Tab11.Title = 'Phase Synchronization';
            app.Tab11.Units = 'pixels';
            app.LabelDropDown11 = uilabel(app.Tab11);
            app.LabelDropDown11.Text = 'Approach:';
            app.DropDown11 = uidropdown(app.Tab11);
            app.DropDown11.Items = {'Local Analysis I', 'Local Analysis II', 'Global Analysis'};
            app.DropDown11.ValueChangedFcn = createCallbackFcn(app, @approach_chg, true);
            app.DropDown11.Value = 'Local Analysis I';
            app.Panel_3 = uipanel(app.Tab11);
            app.Label27_2 = uilabel(app.Panel_3);
            app.Label27_2.Text = 'Graph Between';
            app.Label28_2 = uilabel(app.Panel_3);
            app.Label28_2.Text = 'Hz';
            app.NumericEditField7_2Label = uilabel(app.Panel_3);
            app.NumericEditField7_2Label.Text = 'Upper Limit:';
            app.NumericEditField7_2 = uieditfield(app.Panel_3, 'numeric');
            app.NumericEditField7_2.ValueChangedFcn = createCallbackFcn(app, @upperfrq_chg_2, true);
            app.NumericEditField7_2.Limits = [1 Inf];
            app.NumericEditField7_2.Value = 1;
            app.Label29_2 = uilabel(app.Panel_3);
            app.Label29_2.Text = 'Hz';
            app.NumericEditField8_2Label = uilabel(app.Panel_3);
            app.NumericEditField8_2Label.Text = 'Lower Limit:';
            app.NumericEditField8_2 = uieditfield(app.Panel_3, 'numeric');
            app.NumericEditField8_2.ValueChangedFcn = createCallbackFcn(app, @lowerfrq_chg_2, true);
            app.NumericEditField8_2.Limits = [0 Inf];
            app.Panel_2 = uipanel(app.Tab11);
            app.LabelListBox3 = uilabel(app.Panel_2);
            app.LabelListBox3.Text = 'Btw. Channels';
            app.ListBox3 = uilistbox(app.Panel_2);
            app.ListBox3.Multiselect = 'on';
            app.ListBox3.ValueChangedFcn = createCallbackFcn(app, @ch_chg, true);
            app.ListBox3.Value = {'Item 1'};
            app.CheckBox_3 = uicheckbox(app.Panel_2);
            app.CheckBox_3.ValueChangedFcn = createCallbackFcn(app, @selectall_ch, true);
            app.CheckBox_3.Text = '';
            app.ListBox2 = uilistbox(app.Panel_2);
            app.ListBox2.Items = {'Region 1', 'Region 2', 'Region 4', 'Region 5'};
            app.ListBox2.Multiselect = 'on';
            app.ListBox2.ValueChangedFcn = createCallbackFcn(app, @rgn_chg, true);
            app.ListBox2.Value = {'Region 1'};
            app.LabelListBox2 = uilabel(app.Panel_2);
            app.LabelListBox2.Text = 'Btw. Regions';
            app.CheckBox_2 = uicheckbox(app.Panel_2);
            app.CheckBox_2.ValueChangedFcn = createCallbackFcn(app, @graph_regions, true);
            app.CheckBox_2.Text = '';
            app.Panel12 = uipanel(app.Tab3);
            app.UIAxes5 = uiaxes(app.Panel12);
            title(app.UIAxes5, 'Title');
            xlabel(app.UIAxes5, 'X');
            ylabel(app.UIAxes5, 'Y');
            app.Tab4 = uitab(app.TabGroup);
            app.Tab4.Title = 'Statics';
            app.Tab4.Units = 'pixels';
            app.Panel9_2 = uipanel(app.Tab4);
            app.Panel9_2.Title = 'Tools';
            app.Button14_2 = uibutton(app.Panel9_2, 'push');
            app.Button14_2.ButtonPushedFcn = createCallbackFcn(app, @analyze, true);
            app.Button14_2.Icon = 'analysis_icon.png';
            app.Button14_2.Text = '';
            app.AnalyzeLabel = uilabel(app.Panel9_2);
            app.AnalyzeLabel.Text = 'Analyze';
            app.Button15_2 = uibutton(app.Panel9_2, 'push');
            app.Button15_2.ButtonPushedFcn = createCallbackFcn(app, @explore, true);
            app.Button15_2.Icon = 'explore_icon.png';
            app.Button15_2.Text = '';
            app.ExploreLabel = uilabel(app.Panel9_2);
            app.ExploreLabel.Text = 'Explore';
            app.Button16_2Label = uilabel(app.Panel9_2);
            app.Button16_2Label.Enable = 'off';
            app.Button16_2Label.Text = 'Help';
            app.Button16_2 = uibutton(app.Panel9_2, 'push');
            app.Button16_2.Enable = 'off';
            app.Button16_2.Icon = 'sign-question-icon.png';
            app.Button16_2.Text = '';
            app.Button7_2 = uibutton(app.Panel9_2, 'push');
            app.Button7_2.ButtonPushedFcn = createCallbackFcn(app, @undo1, true);
            app.Button7_2.Icon = 'undo_icon.png';
            app.Button7_2.Text = '';
            app.Button7_2Label = uilabel(app.Panel9_2);
            app.Button7_2Label.Text = 'Undo';
            app.Button7_3 = uibutton(app.Panel9_2, 'push');
            app.Button7_3.ButtonPushedFcn = createCallbackFcn(app, @transform, true);
            app.Button7_3.Icon = 'compute_icon.png';
            app.Button7_3.Text = '';
            app.TransformLabel_2 = uilabel(app.Panel9_2);
            app.TransformLabel_2.Text = 'Transform';
            app.TabGroup4_2 = uitabgroup(app.Tab4);
            app.Tab9_2 = uitab(app.TabGroup4_2);
            app.Tab9_2.Title = 'Sync. Results';
            app.Tab9_2.Units = 'pixels';
            app.ApproachLabel = uilabel(app.Tab9_2);
            app.ApproachLabel.Text = 'Approach:';
            app.DropDown9_2 = uidropdown(app.Tab9_2);
            app.DropDown9_2.Items = {'Local Analysis I', 'Local Analysis II', 'Global Analysis'};
            app.DropDown9_2.ValueChangedFcn = createCallbackFcn(app, @approach_chg2, true);
            app.DropDown9_2.Value = 'Local Analysis I';
            app.ResutlsLabel = uilabel(app.Tab9_2);
            app.ResutlsLabel.Text = 'Resutls:';
            app.DropDown10_2 = uidropdown(app.Tab9_2);
            app.DropDown10_2.ValueChangedFcn = createCallbackFcn(app, @sycn_res_chg, true);
            app.TabGroup6 = uitabgroup(app.Tab4);
            app.VariablesTab = uitab(app.TabGroup6);
            app.VariablesTab.Title = 'Variables';
            app.VariablesTab.Units = 'pixels';
            app.UITable = uitable(app.VariablesTab);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'; 'Column 5'; 'Column 6'; 'Column 7'};
            app.UITable.RowName = {};
            app.TestofNormalityTab = uitab(app.TabGroup6);
            app.TestofNormalityTab.Title = 'Test of Normality';
            app.TestofNormalityTab.Units = 'pixels';
            app.LillieforsTestPanel = uipanel(app.TestofNormalityTab);
            app.LillieforsTestPanel.Title = 'Lilliefors Test';
            app.UITable2 = uitable(app.LillieforsTestPanel);
            app.UITable2.ColumnName = {'Var. Name'; 'Statistics'; 'df'; 'Sig.'};
            app.UITable2.RowName = {};
            app.ShapiroWilkShapiroFranciaPanel = uipanel(app.TestofNormalityTab);
            app.ShapiroWilkShapiroFranciaPanel.Title = 'Shapiro-Wilk / Shapiro-Francia';
            app.UITable2_2 = uitable(app.ShapiroWilkShapiroFranciaPanel);
            app.UITable2_2.ColumnName = {'Statistics'; 'df'; 'Sig.'};
            app.UITable2_2.RowName = {};
            app.UITable2_2.ColumnEditable = [true false false];
            app.DescriptivesPanel = uipanel(app.TestofNormalityTab);
            app.DescriptivesPanel.Title = 'Descriptives';
            app.UITable3 = uitable(app.DescriptivesPanel);
            app.UITable3.ColumnName = {'Skewness'; 'Kurtosis'};
            app.UITable3.RowName = {};
            app.TestofHomogeneityofVariancesTab = uitab(app.TabGroup6);
            app.TestofHomogeneityofVariancesTab.Title = 'Test of Homogeneity of Variances';
            app.TestofHomogeneityofVariancesTab.Units = 'pixels';
            app.LeveneTestPanel = uipanel(app.TestofHomogeneityofVariancesTab);
            app.LeveneTestPanel.Title = 'Levene Test';
            app.UITable4 = uitable(app.LeveneTestPanel);
            app.UITable4.ColumnName = {'Levene Statistic'; 'df1'; 'df2'; 'Sig'};
            app.UITable4.RowName = {};
            app.ParametricTestResultsTab = uitab(app.TabGroup6);
            app.ParametricTestResultsTab.Title = 'Parametric Test Results';
            app.ParametricTestResultsTab.Units = 'pixels';
            app.ANOVAPanel = uipanel(app.ParametricTestResultsTab);
            app.ANOVAPanel.Title = 'ANOVA';
            app.UITable5 = uitable(app.ANOVAPanel);
            app.UITable5.ColumnName = {'-'; 'Sum of Squares'; 'df'; ' Mean Square '; 'F'; 'Sig.'};
            app.UITable5.RowName = {};
            app.MultipleComparasionsGamesHowellPanel = uipanel(app.ParametricTestResultsTab);
            app.MultipleComparasionsGamesHowellPanel.Title = 'Multiple Comparasions (Games-Howell)';
            app.UITable5_2 = uitable(app.MultipleComparasionsGamesHowellPanel);
            app.UITable5_2.ColumnName = {'I'; 'J'; '  Mean Difference (I-J)  '; 'Std. Error'; 'Sig.'};
            app.UITable5_2.RowName = {};
            app.MultipleComparasionsTukeyPanel = uipanel(app.ParametricTestResultsTab);
            app.MultipleComparasionsTukeyPanel.Title = 'Multiple Comparasions (Tukey)';
            app.UITable5_3 = uitable(app.MultipleComparasionsTukeyPanel);
            app.UITable5_3.ColumnName = {'I'; 'J'; '  Mean Difference (I-J)  '; 'Std. Error'; 'Sig.'};
            app.UITable5_3.RowName = {};
            app.NonparametricTestResultsTab = uitab(app.TabGroup6);
            app.NonparametricTestResultsTab.Title = 'Nonparametric Test Results';
            app.NonparametricTestResultsTab.Units = 'pixels';
            app.KruskalWallisPanel = uipanel(app.NonparametricTestResultsTab);
            app.KruskalWallisPanel.Title = 'Kruskal-Wallis';
            app.UITable5_4 = uitable(app.KruskalWallisPanel);
            app.UITable5_4.ColumnName = {'-'; 'Sum of Squares'; 'df'; ' Mean Square '; 'F'; 'Sig.'};
            app.UITable5_4.RowName = {};
            app.MultipleComparasionsDunnBonferroniApproachPanel = uipanel(app.NonparametricTestResultsTab);
            app.MultipleComparasionsDunnBonferroniApproachPanel.Title = 'Multiple Comparasions (Dunn-Bonferroni Approach)';
            app.UITable5_5 = uitable(app.MultipleComparasionsDunnBonferroniApproachPanel);
            app.UITable5_5.ColumnName = {'I'; 'J'; '  Mean Difference (I-J)  '; 'Std. Error'; 'Std. Test Statistic'; 'Sig.'; 'Adj. Sig.'};
            app.UITable5_5.RowName = {};
            app.WilcoxonRankSumPanel = uipanel(app.NonparametricTestResultsTab);
            app.WilcoxonRankSumPanel.Title = 'Wilcoxon Rank Sum';
            app.UITable6 = uitable(app.WilcoxonRankSumPanel);
            app.UITable6.ColumnName = {'I'; 'J'; 'Rank Sum'; 'Z'; 'Sig.'};
            app.UITable6.RowName = {};
            app.TabGroup7 = uitabgroup(app.Tab4);
            app.DescriptiveStatisticsTab = uitab(app.TabGroup7);
            app.DescriptiveStatisticsTab.Title = 'Descriptive Statistics';
            app.DescriptiveStatisticsTab.Units = 'pixels';
            app.Panel_4 = uipanel(app.DescriptiveStatisticsTab);
            app.log10xCheckBox = uicheckbox(app.Panel_4);
            app.log10xCheckBox.ValueChangedFcn = createCallbackFcn(app, @trns1, true);
            app.log10xCheckBox.Text = 'log10(x)';
            app.sqrtxCheckBox = uicheckbox(app.Panel_4);
            app.sqrtxCheckBox.ValueChangedFcn = createCallbackFcn(app, @trns2, true);
            app.sqrtxCheckBox.Text = 'sqrt(x)';
            app.powx2CheckBox = uicheckbox(app.Panel_4);
            app.powx2CheckBox.ValueChangedFcn = createCallbackFcn(app, @trns3, true);
            app.powx2CheckBox.Text = 'pow(x,2)';
            app.xCheckBox = uicheckbox(app.Panel_4);
            app.xCheckBox.ValueChangedFcn = createCallbackFcn(app, @trns4, true);
            app.xCheckBox.Text = '1/x';
            app.TransformLabel_3 = uilabel(app.Panel_4);
            app.TransformLabel_3.Text = 'Transform:';
            app.Panel_15 = uipanel(app.DescriptiveStatisticsTab);
            app.SignificanceLevelLabel = uilabel(app.Panel_15);
            app.SignificanceLevelLabel.Text = 'Significance Level:';
            app.SignificanceLevelEditField = uieditfield(app.Panel_15, 'numeric');
            app.SignificanceLevelEditField.Limits = [0.01 0.99];
            app.SignificanceLevelEditField.Value = 0.05;
            app.ParametricTestsTab = uitab(app.TabGroup7);
            app.ParametricTestsTab.Title = 'Parametric Tests';
            app.ParametricTestsTab.Units = 'pixels';
            app.Panel_5 = uipanel(app.ParametricTestsTab);
            app.tTestCheckBox = uicheckbox(app.Panel_5);
            app.tTestCheckBox.Enable = 'off';
            app.tTestCheckBox.Text = 't-Test';
            app.ANOVAOneWayCheckBox = uicheckbox(app.Panel_5);
            app.ANOVAOneWayCheckBox.ValueChangedFcn = createCallbackFcn(app, @test_anova, true);
            app.ANOVAOneWayCheckBox.Text = 'ANOVA (One-Way)';
            app.ANOVAOneWayCheckBox.Value = true;
            app.RegressionAnalysisCheckBox = uicheckbox(app.Panel_5);
            app.RegressionAnalysisCheckBox.Enable = 'off';
            app.RegressionAnalysisCheckBox.Text = 'Regression Analysis';
            app.Panel_13 = uipanel(app.ParametricTestsTab);
            app.TukeyCheckBox = uicheckbox(app.Panel_13);
            app.TukeyCheckBox.ValueChangedFcn = createCallbackFcn(app, @test_Tukey, true);
            app.TukeyCheckBox.Text = 'Tukey';
            app.TukeyCheckBox.Value = true;
            app.GamesHowellCheckBox = uicheckbox(app.Panel_13);
            app.GamesHowellCheckBox.ValueChangedFcn = createCallbackFcn(app, @test_G_H, true);
            app.GamesHowellCheckBox.Text = 'Games-Howell';
            app.PostHocLabel = uilabel(app.Panel_13);
            app.PostHocLabel.Text = 'Post Hoc';
            app.Button_2 = uibutton(app.ParametricTestsTab, 'push');
            app.Button_2.Enable = 'off';
            app.Button_2.Icon = 'next.png';
            app.Button_2.Text = '';
            app.Button_3 = uibutton(app.ParametricTestsTab, 'push');
            app.Button_3.Enable = 'off';
            app.Button_3.Icon = 'back.png';
            app.Button_3.Text = '';
            app.Button_4 = uibutton(app.ParametricTestsTab, 'push');
            app.Button_4.Enable = 'off';
            app.Button_4.Icon = 'back.png';
            app.Button_4.Text = '';
            app.Button_5 = uibutton(app.ParametricTestsTab, 'push');
            app.Button_5.Enable = 'off';
            app.Button_5.Icon = 'next.png';
            app.Button_5.Text = '';
            app.Panel_16 = uipanel(app.ParametricTestsTab);
            app.SignificanceLevelEditField_2Label = uilabel(app.Panel_16);
            app.SignificanceLevelEditField_2Label.Text = 'Significance Level:';
            app.SignificanceLevelEditField_2 = uieditfield(app.Panel_16, 'numeric');
            app.SignificanceLevelEditField_2.Limits = [0.01 0.99];
            app.SignificanceLevelEditField_2.Value = 0.05;
            app.NonparametricTestsTab = uitab(app.TabGroup7);
            app.NonparametricTestsTab.Title = 'Nonparametric Tests';
            app.NonparametricTestsTab.Units = 'pixels';
            app.Panel_6 = uipanel(app.NonparametricTestsTab);
            app.FriedmanCheckBox = uicheckbox(app.Panel_6);
            app.FriedmanCheckBox.Enable = 'off';
            app.FriedmanCheckBox.Text = 'Friedman';
            app.KruskalWallisCheckBox = uicheckbox(app.Panel_6);
            app.KruskalWallisCheckBox.ValueChangedFcn = createCallbackFcn(app, @test_K_W, true);
            app.KruskalWallisCheckBox.Text = 'Kruskal-Wallis';
            app.WilcoxonRankSumCheckBox = uicheckbox(app.Panel_6);
            app.WilcoxonRankSumCheckBox.ValueChangedFcn = createCallbackFcn(app, @test_RankSum, true);
            app.WilcoxonRankSumCheckBox.Text = 'Wilcoxon Rank Sum';
            app.Panel_17 = uipanel(app.NonparametricTestsTab);
            app.SignificanceLevelEditField_3Label = uilabel(app.Panel_17);
            app.SignificanceLevelEditField_3Label.Text = 'Significance Level:';
            app.SignificanceLevelEditField_3 = uieditfield(app.Panel_17, 'numeric');
            app.SignificanceLevelEditField_3.Limits = [0.01 0.99];
            app.SignificanceLevelEditField_3.Value = 0.05;
            app.Button_6 = uibutton(app.NonparametricTestsTab, 'push');
            app.Button_6.Enable = 'off';
            app.Button_6.Icon = 'back.png';
            app.Button_6.Text = '';
            app.Button_7 = uibutton(app.NonparametricTestsTab, 'push');
            app.Button_7.Enable = 'off';
            app.Button_7.Icon = 'next.png';
            app.Button_7.Text = '';
        end
    end

    methods (Access = public)

        % Construct app
        function app = App1_v0()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.EEGSProc)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.EEGSProc)
        end
    end
end